classdef PhasePlane < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhasePlaneUIFigure              matlab.ui.Figure
        AnalysisMenu                    matlab.ui.container.Menu
        FindnearbyequilibriumMenu       matlab.ui.container.Menu
        ScanforequilibriaMenu           matlab.ui.container.Menu
        ClearequilibriaMenu             matlab.ui.container.Menu
        ShowrealeigenvectorsMenu        matlab.ui.container.Menu
        SolveforsaddleseparatricesMenu  matlab.ui.container.Menu
        ClearseparatricesMenu           matlab.ui.container.Menu
        ShownullclinesMenu              matlab.ui.container.Menu
        AutogenerateisoclinesMenu       matlab.ui.container.Menu
        DrawisoclinethroughapointMenu   matlab.ui.container.Menu
        DrawseveralisoclinesMenu        matlab.ui.container.Menu
        ClearisoclinesMenu              matlab.ui.container.Menu
        SolveMenu                       matlab.ui.container.Menu
        SolvefromregionMenu             matlab.ui.container.Menu
        DrawMenu                        matlab.ui.container.Menu
        DrawsolutionMenu                matlab.ui.container.Menu
        DrawandcomparesolutionMenu      matlab.ui.container.Menu
        AppearanceMenu                  matlab.ui.container.Menu
        AnimatesolutionMenu             matlab.ui.container.Menu
        PointlabelsMenu                 matlab.ui.container.Menu
        AxisthroughoriginMenu           matlab.ui.container.Menu
        DarkmodeMenu                    matlab.ui.container.Menu
        FieldorientationonlyMenu        matlab.ui.container.Menu
        LibraryMenu                     matlab.ui.container.Menu
        CustomlibraryMenu               matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        AddcurrentsystemMenu            matlab.ui.container.Menu
        ExportgraphicsMenu              matlab.ui.container.Menu
        ExporttoPNGMenu                 matlab.ui.container.Menu
        ExporttoSVGMenu                 matlab.ui.container.Menu
        ExporttoPDFMenu                 matlab.ui.container.Menu
        ExportportraitonlytoPNGMenu     matlab.ui.container.Menu
        ExportportraitonlytoSVGMenu     matlab.ui.container.Menu
        ExportportraitonlytoPDFMenu     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        VideotutorialMenu               matlab.ui.container.Menu
        QuickstartguideMenu             matlab.ui.container.Menu
        GridLayout2                     matlab.ui.container.GridLayout
        StartPanel                      matlab.ui.container.Panel
        Hyperlink3                      matlab.ui.control.Hyperlink
        NewtothePhasePlaneappLabel      matlab.ui.control.Label
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink1                      matlab.ui.control.Hyperlink
        MessageArea                     matlab.ui.control.TextArea
        DifferentialequationsystemPanel  matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        EditField_6Label                matlab.ui.control.Label
        EditField_5Label                matlab.ui.control.Label
        EditField_4Label                matlab.ui.control.Label
        EditField_3Label                matlab.ui.control.Label
        EditField_2Label                matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.StateButton
        ClearButton                     matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        p6Value                         matlab.ui.control.EditField
        p6Name                          matlab.ui.control.EditField
        p3Value                         matlab.ui.control.EditField
        p3Name                          matlab.ui.control.EditField
        p5Value                         matlab.ui.control.EditField
        p5Name                          matlab.ui.control.EditField
        p2Value                         matlab.ui.control.EditField
        p2Name                          matlab.ui.control.EditField
        p4Value                         matlab.ui.control.EditField
        p4Name                          matlab.ui.control.EditField
        p1Value                         matlab.ui.control.EditField
        p1Name                          matlab.ui.control.EditField
        EditField_dydt                  matlab.ui.control.EditField
        Variable2EditField              matlab.ui.control.EditField
        EditField_dxdt                  matlab.ui.control.EditField
        Variable1EditField              matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        AppearanceTab                   matlab.ui.container.Tab
        AxislimitsLabel                 matlab.ui.control.Label
        AnimationspeedSlider            matlab.ui.control.Slider
        LinewidthLabel                  matlab.ui.control.Label
        FieldScaleUI                    matlab.ui.control.Spinner
        FieldscaleSpinnerLabel          matlab.ui.control.Label
        FielddensitySpinnerLabel        matlab.ui.control.Label
        FieldDensityUI                  matlab.ui.control.Spinner
        AnimationspeedLabel             matlab.ui.control.Label
        SpinnerLineWidth                matlab.ui.control.Spinner
        TimeseriesCheckBox              matlab.ui.control.CheckBox
        tmaxEditField                   matlab.ui.control.NumericEditField
        tmaxEditFieldLabel              matlab.ui.control.Label
        tminEditField                   matlab.ui.control.NumericEditField
        tminEditFieldLabel              matlab.ui.control.Label
        ymaxEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        xmaxEditField                   matlab.ui.control.NumericEditField
        xmaxEditFieldLabel              matlab.ui.control.Label
        xminEditField                   matlab.ui.control.NumericEditField
        xminEditFieldLabel              matlab.ui.control.Label
        SolutionTab                     matlab.ui.container.Tab
        SolvefromregiondensitySlider    matlab.ui.control.Slider
        SolvefromregiondensitySliderLabel  matlab.ui.control.Label
        TerminatesolutionsataxislimitsCheckBox  matlab.ui.control.CheckBox
        BackwardsolutiontminEditField   matlab.ui.control.NumericEditField
        BackwardsolutiontminEditFieldLabel  matlab.ui.control.Label
        ForwardsolutiontmaxEditField    matlab.ui.control.NumericEditField
        ForwardsolutiontmaxEditFieldLabel  matlab.ui.control.Label
        SolverTab                       matlab.ui.container.Tab
        MaxsolverwallclocksEditField    matlab.ui.control.NumericEditField
        MaxsolverwallclocksEditFieldLabel  matlab.ui.control.Label
        VariablesolveroptionsPanel      matlab.ui.container.Panel
        SolverDropDown                  matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        AbsolutetoleranceEditField      matlab.ui.control.NumericEditField
        AbsolutetoleranceLabel          matlab.ui.control.Label
        RelativetoleranceEditField      matlab.ui.control.NumericEditField
        RelativetoleranceLabel          matlab.ui.control.Label
        FixedsolveroptionsPanel         matlab.ui.container.Panel
        NoteimplicitmethodsareestimatedLabel  matlab.ui.control.Label
        StepsizeEditField               matlab.ui.control.NumericEditField
        StepsizeLabel                   matlab.ui.control.Label
        FixedSolverDropDown             matlab.ui.control.DropDown
        SolverLabel                     matlab.ui.control.Label
        SolvertypeButtonGroup           matlab.ui.container.ButtonGroup
        FixedstepButton                 matlab.ui.control.ToggleButton
        VariablestepButton              matlab.ui.control.ToggleButton
        TimeseriesPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes_x                        matlab.ui.control.UIAxes
        UIAxes_y                        matlab.ui.control.UIAxes
        PhaseplanePanel                 matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        PositionLabel                   matlab.ui.control.Label
        SkipanimationButton             matlab.ui.control.Button
        ClearsolutionsButton            matlab.ui.control.StateButton
        y0EditFieldLabel                matlab.ui.control.Label
        Solvefromx0y0Button             matlab.ui.control.Button
        y0EditField                     matlab.ui.control.NumericEditField
        x0Label                         matlab.ui.control.Label
        x0EditField                     matlab.ui.control.NumericEditField
        UIAxes_pp                       matlab.ui.control.UIAxes
    end

    % This is app has similar functionality to the PPlane app developed by 
    % John C. Polking at Rice University between 1995-2003.
    % 
    % This app does not contain any of the original code.
    %
    % Copyright 2021 The MathWorks, Inc. 


    properties (Access = public)
       
        %--------- SETTINGS --------%
        % Note that some of these are set to the default values of the edit
        % fields. 
        % Other settings, like "eqColor" are hardcoded here and can
        % be changed here

        % Minimum width/height after resizing
        minWidth = 750;
        minHeight = 600;
        verticalBuffer = 100; % Will try to use a buffer if the screen is small
        
        % Plot settings
        Ntick = 3; % Number of ticks to use in the graph window
        lw = 1.5 % Line width (set automatically to default spinner value)
        ms = 7 % Marker size
        colors = lines(500) % Colors to be plotted
        markerColor = [0 0 0]
        msIP = 3 % Size of markers for initial points
        fsIP = 12 % Size of font for initial points
        fsLabels = 13 % Axis labels font size [latex]
        fontColorIP = [0 0 0]
        ipMarkerColor = [1 1 1] % Initial point marker color
        quiverColor = [0.7 0.7 0.7] % Quiver color
        highlightEdgeAlpha = 0.15
        labelBGColor = "none" % Can also use "none"
        
        % Format spec for numbers
        formatSpec = "%.2f"
        
        % Video tutorial local file location
        videoFileLocation = "PhasePlaneIntro.mp4"
        quickStartLocation = "PhasePlaneQuickStart.pdf"
        tutorialsFolder = "Tutorials"
        
        % Other appearance settings
        textColorDM = [1 1 1]
        bgColorDM = [0 0 0]
        textColorSTD = [0 0 0]
        bgColorSTD = [1 1 1]
        highlightColor = [0.94 0.5 0.2]
        
        % Animation steps when the speed slider is the slowest (so this is
        % the maximum number of steps that will ever be animated)
        defaultStepsPerAnimation = 600;
        Nchecks = 20; % How many times to check to stop the animation

        % The animation will skip time steps smaller than this value
        animateTolerance = 1e-2
        
        % Equilibrium settings 
        zeroTolFun = 1e-10
        zeroTolX = 1e-10
        eqSigs = 3 % Decimal places to round to for classifying equilibria
        eqColor = [0.6 0.2 0.2] % Color of equilibria markers
        msEq = 9
        NEqScan = 10 % Number of points when scanning for equilibria
        eigenvectorColor = [0.6 0.2 0.2] % Color of eigenvector arrows
        evlw = 1.5 % eigenvector line width
        evHeadsize = 0.5 % Eigenvector max head size
        eigScaleFactor = 0.08 % Relative to the diagonal distance across the plot window
        maxEqDisplay = 5 % Maximum number of equilibria to display
        eqNewtonNmax = 200; % Max iterations for the Newton iteration solver (if used)
        
        % Separatrices settings
        separatrixInitialStep = 1e-3
        tmaxSeparatrix = 50
        separatrixColor = [0.6 0.3 0.3]
        separatrixlw = 1.25;
        
        % Nullclines settings
        Nnc = 400 % number of points to use when finding nullclines
        nullclineColors = [0.4 0.4 0.7; 0.7 0.5 0.5]
        nclw = 1.25 % width
        
        % Isoclines settings
        Niso = 400 % number of points to use when finding nullclines
        isoclineColors = [0.6 0.4 0.6]
        isolw = 1 % width
        isoMinPoints = 50;
        isoRemovePoints = 10; % must be less than half of min points
        isoTextColor = [0 0 0]; % Will get changed by dark mode
        isoFontsize = 12;
        isoNumSpec = "%0.2f";
        
        % Figure export settings
        figLatexFontsize = 12
        defaultFilename = "phaseportrait"

        % Newton iteration settings
        NMaxNewton = 5 % Maximum iterations
        FNormNewton = 1e-8 % Norm of F
        xNormNewton = 1e-8 % Norm of x
        dxNewton = 1e-6 % step for finite difference approximation of derivatives
        
        % Default library save name            
        defaultLibraryName = "PhasePlaneLibrary";
                    
        %--------- OBJECTS --------%
        % Quiver field object
        field
        
        % Switches
        skipAnimationFlag = false;
        animateSolnSwitch
        pointLabelsSwitch
        originAxisSwitch
        darkModeSwitch
        orientationOnlySwitch
        nullclinesSwitch
        showEigenvectorsSwitch
        
        % Equations
        dY_Y
        dY_xy
        dY_xy_cell
        invalidEquation = false
        firstStep = true
        solverMessage = ""
        
        % Parameter storage
        params
        
        % Plot storage
        plotHandlesPP
        plotMarkerHandles
        plotTextHandles
        plotHandlesY
        plotHandlesX
        highlightHandles 
        
        % Equilibria
        findEqMode = false
        equilibria
        equilibriaPlots
        equilibriaLabels
        eigenvectorPlots
        
        % Nullclines/isoclines
        nullclinesPlots
        isoclinesPlots
        isoclinesLabels
        singleIsoclineMode = false
        
        % Separatrices in one plot
        separatricesPlot
        
        % Container for ODE system expressions
        defaultOdeMenuHandles
        userOdeMenuHandles
        userODESystems
        
        % Solver type to use (either "fixed" or "variable")
        solverType = "variable"
        
        % User is drawing something right now
        userDrawing = false
        
        % Disable unused suggestions
        %#ok<*INUSL> 
        %#ok<*INUSD> 
    end
    
    methods (Access = private)
        
        %------------------ DERIVATIVE FIELD FUNCTIONS ------------------%      
        function updateArrows(app)
            %This function generates the phase plane quivers
            try
                % Delete the previous field
                delete(app.field)
                % Use shared function to generate quivers
                app.field = SharedFunctions.updateArrows(app.UIAxes_pp,app.dY_xy_cell,...
                app.xminEditField.Value,app.xmaxEditField.Value,app.yminEditField.Value,app.ymaxEditField.Value,...
                app.FieldDensityUI.Value,app.FieldScaleUI.Value,app.quiverColor,app.Ntick,app.orientationOnlySwitch);
            catch ME
                message = "Failed to update quiver arrows with error: " + ME.message;
                app.displayMessage(message,2)
            end
        end
        %---------------- END DERIVATIVE FIELD FUNCTIONS ----------------%      
        
        
        %------------------ ODE SOLUTION/PLOT FUNCTIONS ------------------%      
        
        function evalWithParams(app,expression___)
            % This evaluates an expression with the user-defined parameters present
            if(~isempty(app.p1Name.Value) && ~isempty(app.p1Value.Value))
                eval(app.p1Name.Value + "=" + app.p1Value.Value + ";")
            end
            if(~isempty(app.p2Name.Value) && ~isempty(app.p2Value.Value))
                eval(app.p2Name.Value + "=" + app.p2Value.Value + ";")
            end
            if(~isempty(app.p3Name.Value) && ~isempty(app.p3Value.Value))
                eval(app.p3Name.Value + "=" + app.p3Value.Value + ";")
            end
            if(~isempty(app.p4Name.Value) && ~isempty(app.p4Value.Value))
                eval(app.p4Name.Value + "=" + app.p4Value.Value + ";")
            end
            if(~isempty(app.p5Name.Value) && ~isempty(app.p5Value.Value))
                eval(app.p5Name.Value + "=" + app.p5Value.Value + ";")
            end
            if(~isempty(app.p6Name.Value) && ~isempty(app.p6Value.Value))
                eval(app.p6Name.Value + "=" + app.p6Value.Value + ";")
            end
 
            % Evaluate expression
            eval(expression___)
        end
        
        function setEquation(app)
            % This function formats the equation and checks for errors
            % when the user tries to update it
            
            % Get variable names and user expressions
            variables = [string(app.Variable1EditField.Value), string(app.Variable2EditField.Value)];
            dxdt_str = app.EditField_dxdt.Value;
            dydt_str = app.EditField_dydt.Value;   
               
            % Create the equation handles and test them
            try 
                % This one is formatted for making the quivers
                evFieldStr = "app.dY_xy_cell = @("+variables(1)+","+variables(2)+"){ ("+dxdt_str+");"+" ("+dydt_str+");};";
                evFieldStrEdit = replace(evFieldStr,["*","^","/"],[".*",".^","./"]);
                evFieldStrEdit = replace(evFieldStrEdit,["..*","..^","../"],[".*",".^","./"]);               
                app.evalWithParams(evFieldStrEdit)
                % This handle is step toward the next one
                evODEStr_xy = "app.dY_xy = @(t,"+ variables(1) + ","+ variables(2) +")[ (" + dxdt_str + "); (" + dydt_str + ");];";
                app.evalWithParams(evODEStr_xy)
                % This handle is formatted for the ODE solver
                evODEStr_Y = "app.dY_Y = @(t,Y) app.dY_xy(t,Y(1),Y(2));";
                app.evalWithParams(evODEStr_Y)
                
                % Test out the handles
                app.dY_Y([0 0 0],[0 0 0; 0 0 0]);
                app.dY_xy_cell( zeros(3,2), zeros(3,2) );
                
            catch exception
                % If it fails set "invalidEquation" to true so that the
                % user cannot generate solutions
                message = ['Invalid equation because: ', ...
                    newline, getReport(exception,'basic','hyperlinks','off'),newline,newline,...
                    'Update the equation.'];
                app.displayMessage(message,2)
                
                app.dY_xy_cell = @(x,y){x*0;y*0};
                app.dY_Y = @(t,Y) Y.*0;
                app.dY_xy = @(x,y){ x*0 ; y*0 };
                app.invalidEquation = true;
                return
            end
            
            % Set the axis labels/initial value labels
            app.setVariableLabels
        end
            
        function [tF,yF,tR,yR] = generateSolutionCurve(app,y0) 
            
            tF = [];
            yF = [];
            tR = [];
            yR = [];

            if(app.invalidEquation)
                message = "Cannot generate solution because the equation is invalid."...
                        + newline + "Please define a new equation.";
                app.displayMessage(message,2)
                return
            end
                    
            try        
                if(size(y0,1) == 2)
                    y0 = y0';
                end
                
                % Set the time spans to solve with the ODE solver                
                tspanF = [0,app.ForwardsolutiontmaxEditField.Value];
                tspanR = [0,app.BackwardsolutiontminEditField.Value];

                % Indicate to user that the ODE is being solved
                message = 'Solving...';
                app.displayMessage(message,0)
                pause(0)
                
                % Now solve the ODE
                lastwarn(''); % Clear last warning message
                app.solverMessage = "";
                
                % Forward solution
                if(tspanF(1) ~= tspanF(2))
                    [tF,yF] = solveODE(app,tspanF,y0);
                else
                    % If there's no forward time span, just include
                    % the initial point (so that it gets plotted)
                    tF = tspanF(1);
                    yF = y0;
                end

                % Backward solution
                if(tspanR(1) ~= tspanR(2))
                    [tR,yR] = solveODE(app,tspanR,y0);
                else
                    tR = [];
                    yR = [];
                end
                
                % Set warning message if needed
                [warnMsg, ~] = lastwarn;
                if( isempty(warnMsg) )
                    message = 'Solution completed successfully.';
                    app.displayMessage(message,0)
                else
                    app.displayMessage(warnMsg,1)
                    app.solverMessage = warnMsg;
                end

                app.PhasePlaneUIFigure.Pointer = "arrow"; % Regular pointer
             catch ME
                app.PhasePlaneUIFigure.Pointer = "arrow"; % Regular pointer
                message = "Solution failed with error: " + ME.message;
                app.displayMessage(message,2)
             end
        end
          
        function [t,y] = solveODE(app,tspan,y0)
            % Solve the ODE based on the solver selections    
            % Note: already wrapped in a try/catch statement from "generateSolnCurve" ^^
            t = [];
            y = [];
            
            % Star timer 
            tstartWallClock = tic;
            
            % This is required for the time checks       
            app.firstStep = 1; 
            
            if(app.VariablestepButton.Value)
                % Variable step solver solution
                solver = app.SolverDropDown.Value; % Pull variable solver name
                % Generate settings and call the ODE solver
                options = odeset('Events',@(t,y)app.eventFcn(t,y,tstartWallClock),...
                            "RelTol",app.RelativetoleranceEditField.Value,...
                            "AbsTol",app.AbsolutetoleranceEditField.Value); %#ok<NASGU> 
                sltnStrF = ['[t,y] = ',char(solver),'(app.dY_Y,[',num2str(tspan),']'',[',num2str(y0),']'',options);'];
                eval(sltnStrF);
                
            elseif(app.FixedstepButton.Value)
                % Fixed step solver solution
                % Set forward or reverse time step
                if(tspan(2) >= tspan(1))
                    dt = app.StepsizeEditField.Value;
                else
                    dt = -app.StepsizeEditField.Value;
                end
                               
                % Set up vectors     
                Nsteps = ceil(abs(tspan(2)/dt));
                t = (tspan(1):dt:dt*Nsteps)';
                y = NaN(length(t),2);
                y(1,:) = y0;
            
                % Function handle for the fixed step
                f = @(t,Y) app.dY_Y(t,Y);

                % Loop through time points until tspan(2) is surpassed
                for k = 2:numel(t)
                    
                    y(k,:) = SharedFunctions.fixedStep(t(k-1),y(k-1,:)',f,dt,app.FixedSolverDropDown.Value,...
                        app.dxNewton,app.NMaxNewton,app.xNormNewton,app.FNormNewton)';
                    [timeCheck,axisCheck] = solverChecks(app,y(k,:),tstartWallClock);
                    
                    if(~timeCheck)
                        msg = "Solution terminated early as simulation took longer than "+ ...
                            num2str(app.MaxsolverwallclocksEditField.Value) + "s." + newline + ....
                           "You can change this behavior in the Solver options pane →"; 
                        app.solverMessage = msg;
                        break
                    end
                    if( ~axisCheck )
                        msg = "Solution terminated early because a phase plane axis limit was reached." + newline +...
                        "You can change this behavior in the Solution options pane  →" + newline;
                        app.solverMessage = msg;
                        break
                    end
               
                end
                
            end
        end
        

        function [value,isterminal,direction] = eventFcn(app,t,y,tstartWallClock)
            % ODE solver event function to stop solution if needed
            isterminal = [1 1];
            direction = [0 0];
       
            % Check the axis/time limit
            [timeCheck,axisCheck] = app.solverChecks(y,tstartWallClock);
            value = [axisCheck,timeCheck];

            if( ~timeCheck )
                msg = "Solution terminated early as simulation took longer than "+ ...
                   num2str(app.MaxsolverwallclocksEditField.Value) + "s." + newline + ....
                   "You can change this behavior in the Solver options pane →"; 
                app.solverMessage = msg;
            end
            if( ~axisCheck )
                msg = "Solution terminated early because a phase plane axis limit was reached." + newline +...
                "You can change this behavior in the Solution options pane →" + newline;
                app.solverMessage = msg;
            end
        end  
        
        function [timeCheck,axisCheck] = solverChecks(app,y,tstartWallClock)
            % Check if the solution is taking forever
            timeCheck = toc(tstartWallClock) < app.MaxsolverwallclocksEditField.Value;
            
            % Check if the solution is outside the bounds
            axisCheck = true;
            if(app.TerminatesolutionsataxislimitsCheckBox.Value)
                if(y(1) < app.xminEditField.Value || y(1) > app.xmaxEditField.Value || ...
                   y(2) < app.yminEditField.Value || y(2) > app.ymaxEditField.Value )
                    axisCheck = false;
                end
            end
            
            % If its the first step, report true
            if( app.firstStep==1 )
                timeCheck = true;
                axisCheck = true;
            end
   
            % After first step, set to zero
            app.firstStep = 0;
        end
     
        
        function plotSolution(app,tF,yF,tR,yR,lineSpec,plotxy)
  
            % If the equation is invalid, simply skip the plotting    
            if(app.invalidEquation)
                message = "Cannot generate solution because the equation is invalid."...
                        + newline + "Please define a new equation.";
                app.displayMessage(message,2)
                return
            end
            
            try
                app.PhasePlaneUIFigure.Pointer = "watch"; % Indicate that work is being done
                message = 'Plotting solution...';
                app.displayMessage(message,0)
                pause(0.00001)
                
                % Time range
                trange = abs(app.tmaxEditField.Value - app.tminEditField.Value);
    
                % Get the index of the new plot
                numPlots = numel(app.plotHandlesPP);
                idxPlot = numPlots+1;            
                lineColor = app.colors(idxPlot,:);
                
                % Create a handle for the new plot (phase plane)
                hold(app.UIAxes_pp,"on")
                app.plotHandlesPP{idxPlot} = plot(app.UIAxes_pp,0,0,lineSpec,"Linewidth",app.lw,"Color",lineColor);
                markerHandle = plot(app.UIAxes_pp,yF(1,1),yF(1,2),'o',"Color",lineColor,...
                    "MarkerFaceColor",app.markerColor,"MarkerSize",app.ms,"LineWidth",app.lw);        
                app.plotMarkerHandles{idxPlot} = plot(app.UIAxes_pp,yF(1,1),yF(1,2),'o',"Color",lineColor,...
                    "MarkerFaceColor",app.ipMarkerColor,"MarkerSize",app.ms,"LineWidth",app.lw);
                % text label  
                textLabel = "  (" + num2str(yF(1,1),app.formatSpec) + ", " + num2str(yF(1,2),app.formatSpec) + ")";
                app.plotTextHandles{idxPlot} = text(app.UIAxes_pp,yF(1,1),yF(1,2),textLabel,"fontsize",app.fsIP,...
                    "Visible",app.pointLabelsSwitch,'Color',app.fontColorIP,"PickableParts","none",...
                    "BackgroundColor", app.labelBGColor);
                hold(app.UIAxes_pp,"off")
                
                % X and Y plot objects
                if(plotxy)
                    hold(app.UIAxes_y,"on")
                        app.plotHandlesY{idxPlot} = plot(app.UIAxes_y,0,0,lineSpec,"Linewidth",app.lw,"Color",lineColor);
                    hold(app.UIAxes_y,"off")
                    hold(app.UIAxes_x,"on")
                        app.plotHandlesX{idxPlot} = plot(app.UIAxes_x,0,0,lineSpec,"Linewidth",app.lw,"Color",lineColor);
                    hold(app.UIAxes_x,"off")
                else
                    app.plotHandlesX{idxPlot} = [];
                    app.plotHandlesY{idxPlot} = [];
                end
                
                app.skipAnimationFlag = false;
                app.SkipanimationButton.Visible = true;
                
                % 1. Forward plot animation (if turned on)
                if(app.animateSolnSwitch)                 
                    % Compute the times where the solution will be drawn
                    forwardTime = abs(tF(end)-tF(1));
                    Npoints = forwardTime/trange * app.defaultStepsPerAnimation/app.AnimationspeedSlider.Value;
                    tvec = linspace(tF(1),tF(end),Npoints);
                    % Indices where the solution should be plotted
                    [~,tIndices] = min(abs(tvec-tF));
    
                    j = 1;
                    for k = tIndices
                        % Phase plane
                        app.plotHandlesPP{idxPlot}.XData = yF(1:k,1);
                        app.plotHandlesPP{idxPlot}.YData = yF(1:k,2);
                        markerHandle.XData = yF(k,1);
                        markerHandle.YData = yF(k,2);
                        
                        % X,Y plots
                        if(plotxy)
                            app.plotHandlesY{idxPlot}.XData = tF(1:k);
                            app.plotHandlesY{idxPlot}.YData = yF(1:k,2);
                            app.plotHandlesX{idxPlot}.XData = tF(1:k);
                            app.plotHandlesX{idxPlot}.YData = yF(1:k,1);
                        end
                        pause(0)
                        
                        if(mod(j,round(app.defaultStepsPerAnimation/app.Nchecks)) == 0)
                            drawnow
                            if(app.skipAnimationFlag)
                                break
                            end
                        end
                        j = j+1;
                    end
                end
                
               % 2. Reverse plot animation (if turned on)
               if(app.animateSolnSwitch && ~isempty(tR) && ~app.skipAnimationFlag)
    
                    % Compute the times where the solution will be drawn
                    reverseTime = abs(tR(end)-tR(1));
                    Npoints = reverseTime/trange * app.defaultStepsPerAnimation/app.AnimationspeedSlider.Value;
                    tvec = linspace(tR(1),tR(end),Npoints);
                    % Indices where the solution should be plotted
                    [~,tIndices] = min(abs(tvec-tR));
    
                    j = 1;
                    for k = tIndices
                        % Phase plane
                        app.plotHandlesPP{idxPlot}.XData = [yR(k:-1:1,1); yF(:,1)];
                        app.plotHandlesPP{idxPlot}.YData = [yR(k:-1:1,2); yF(:,2)];
                        markerHandle.XData = yR(k,1);
                        markerHandle.YData = yR(k,2);
                        
                        % X,Y plots
                        if(plotxy)
                            app.plotHandlesY{idxPlot}.XData = [tR(k:-1:1); tF(:)]; 
                            app.plotHandlesY{idxPlot}.YData = [yR(k:-1:1,2); yF(:,2)];
                            app.plotHandlesX{idxPlot}.XData = [tR(k:-1:1); tF(:)]; 
                            app.plotHandlesX{idxPlot}.YData = [yR(k:-1:1,1); yF(:,1)];
                        end
                        pause(0)
                          
                       if(mod(j,round(app.defaultStepsPerAnimation/app.Nchecks)) == 0)
                            drawnow
                            if(app.skipAnimationFlag)
                                break
                            end
                        end
                        j = j+1;
                    end
         
               end
               drawnow
               app.SkipanimationButton.Visible = false;
       
                if(~isempty(yR))
                   Y = [yR(end:-1:1,:); yF(:,:)]; 
                   t = [tR(end:-1:1); tF(:)]; 
                else
                   Y = yF;
                   t = tF;
                end
               
                
                % Regular plot update
                app.plotHandlesPP{idxPlot}.XData = Y(:,1);
                app.plotHandlesPP{idxPlot}.YData = Y(:,2);
                markerHandle.XData = yF(1,1);
                markerHandle.YData = yF(1,2);
                
                % X,Y plots
                if(plotxy)
                    app.plotHandlesX{idxPlot}.XData = t;
                    app.plotHandlesX{idxPlot}.YData = Y(:,1);
                    app.plotHandlesY{idxPlot}.XData = t;
                    app.plotHandlesY{idxPlot}.YData = Y(:,2);
                end
    
                % Delete the marker
                delete(markerHandle)
                            
                % Set a click callback for highlighting the solution 
                set(app.plotHandlesPP{idxPlot},"ButtonDownFcn",{@app.highlightPlotCallback,idxPlot});
                set(app.plotMarkerHandles{idxPlot},"ButtonDownFcn",{@app.highlightPlotCallback,idxPlot});
                if(plotxy)
                    set(app.plotHandlesX{idxPlot},"ButtonDownFcn",{@app.highlightPlotCallback,idxPlot});
                    set(app.plotHandlesY{idxPlot},"ButtonDownFcn",{@app.highlightPlotCallback,idxPlot});
                end
                
                % Display completion message
                drawnow
                if(app.solverMessage ~= "")
                    message = "Finished plotting solution." + newline ...
                        + newline + "Note:" + newline + app.solverMessage;
                    app.displayMessage(message,0)
                else
                    message = "Finished plotting solution.";
                    app.displayMessage(message,0)
                end
                app.PhasePlaneUIFigure.Pointer = "arrow"; % Regular pointer
            catch ME
               % Otherwise just error out
               message = "Plotting failed due to: "...
                    + newline + ME.message;
                
               app.displayMessage(message,2) 
            end
        end
        
        function solveAndPlotFromInitialValue(app)
            % Solves/plots from a single value defined in the edit fields:
            y0 = [app.x0EditField.Value, app.y0EditField.Value];
            % Solve
            [tF,yF,tR,yR] = app.generateSolutionCurve(y0);
            % Plot the solution             
            app.plotSolution(tF,yF,tR,yR,'-',true)
        end
        %---------------- END ODE SOLUTION/PLOT FUNCTIONS ----------------%          

    
        %----------------------- ANALYSIS FUNCTIONS ----------------------%
        %----> Functions used by the "analysis" menu
        
        function idx = findPlotEquilibrium(app,Y0)
            % Finds and plot an equilibrium
            % Already wrapped by try/catch statements from callers
            app.PhasePlaneUIFigure.Pointer = "watch";
     
            % Find a nearby equilibrium point given initial guess Y0
            zeroFunc = @(Y) app.dY_Y(0,Y);

            % If it fails (probably because the toolbox is not installed) 
            % % use Newton iteration instead
            [Xeq,exitFlag] = SharedFunctions.newtonIteration(Y0,zeroFunc,app.dxNewton,...
                app.eqNewtonNmax,app.xNormNewton,app.FNormNewton);
            Feq = zeroFunc(Xeq);
            J = SharedFunctions.approximateJacobian(zeroFunc,Feq,Xeq,app.dxNewton);
            output.message = "Newton's method failed to converge to required tolerance.";

            idx = [];
            if(exitFlag >= 1)
                Xeq = round(Xeq,app.eqSigs);
                eqSkipFlag = false;
               
                % If it's outside the domain, skip it.
                if(Xeq(1) > app.xmaxEditField.Value || Xeq(1) < app.xminEditField.Value ...
                   || Xeq(2) > app.ymaxEditField.Value || Xeq(2) < app.yminEditField.Value)
                   idx = [];
                   eqSkipFlag = true;
                end
                        
                % Check if it already exists
                Neq = numel(app.equilibria);
                for k = 1:Neq
                    % Be sure to round first in case the equilibria have
                    % some numerical differences
                    if( round(Xeq,app.eqSigs) == round(app.equilibria(k).Xeq,app.eqSigs))
                        eqSkipFlag = true;
                        idx = k;
                    end
                end

                % If it wasn't already found, store and plot it
                if(~eqSkipFlag)
                    [V,D] = eig(J); 
                    lambda = diag(D)';
                    [eqType,stability] = classifyEquilibrium(app,lambda);
                    
                    % In the degenerate case, get rid of the eigenvectors.
                    for j = 1:2
                        if( round(lambda(j),app.eqSigs) == 0)
                            V(:,j) = V(:,j)*0;
                        end
                    end
                    
                    % Store value
                    idx = Neq+1;
                    app.equilibria(idx).Xeq = Xeq;
                    app.equilibria(idx).Feq = Feq;
                    app.equilibria(idx).type = eqType;
                    app.equilibria(idx).stability = stability;
                    app.equilibria(idx).J = J;
                    app.equilibria(idx).lambda = lambda;
                    app.equilibria(idx).V = V;
                    app.equilibria(idx).D = D;

                    % Generate plot
                    hold(app.UIAxes_pp,"on")
                    % Display eigenvectors first so they are underneath the
                    % equilibria
                    app.displayEigenvector(idx)
                    % Plot equilibria
                    app.equilibriaPlots{idx} = plot(app.UIAxes_pp,Xeq(1),Xeq(2),...
                         'o','MarkerFaceColor',app.eqColor,"MarkerEdgeColor","none",...
                         'markersize',app.msEq,"ButtonDownFcn",{@app.equilibriumCallback,idx});
                    % text label  
                    textLabel = "  (" + num2str(Xeq(1),app.formatSpec) + ", " + num2str(Xeq(2),app.formatSpec) + ")";
                    app.equilibriaLabels{idx} = text(app.UIAxes_pp,Xeq(1),Xeq(2),textLabel,"fontsize",app.fsIP,...
                        "Visible",app.pointLabelsSwitch,'Color',app.fontColorIP,"PickableParts","none",...
                        "BackgroundColor",app.labelBGColor);
                    hold(app.UIAxes_pp,"off")
                    drawnow
                    
                end
            else
                % Otherwise just error out
                message = "Could not find an equilibrium near the given point. Output: "...
                    + newline + output.message;
                
                app.displayMessage(message,0)
            end           
            app.PhasePlaneUIFigure.Pointer = "arrow"; % Regular pointer
            
            
        end

        function scanForEquilibria(app)
            % Scan for equilibria on a regular grid
            % This throws out equilbria that are too close together
            message = "Scanning for equilibria...";
            app.displayMessage(message,0)
            try
                xbnd = linspace(app.xminEditField.Value,app.xmaxEditField.Value,app.NEqScan);
                ybnd = linspace(app.yminEditField.Value,app.ymaxEditField.Value,app.NEqScan);
                [X,Y] = meshgrid(xbnd,ybnd);
                x = X(:);
                y = Y(:);
                
                % Search for equilibria
                idxFound = [];
                for k = 1:numel(x)
                  Y0 = [x(k);y(k)];
                  idx = findPlotEquilibrium(app,Y0);
                  idxFound = [idxFound,idx];
                end
                idxFound = unique(idxFound);
                
                % If there are only a few equilibria, then display them
                if (numel(idxFound) <= app.maxEqDisplay)
                    for idx = idxFound
                        app.displayEquilibrium(idx)
                    end
                end
                
                % Show message
                message = "Found " + numel(idxFound) + " possible equilibria. Click on any equilibrium point to view its analysis.";
                app.displayMessage(message,0)
            catch ME
                message = "Equilibria scanning failed with error: "...
                        + newline + ME.message;
                    app.displayMessage(message,2)
            end
        end
        
        function findSingleEquilibrium(app,location)
            % Searches for a nearby equilibrium given a user-defined location
            idx = [];
            try
                idx = app.findPlotEquilibrium(location');
            catch ME
                message = "Equilibrium search failed with error: "...
                        + newline + ME.message;
                    app.displayMessage(message,2)
            end
            % Just display error if nothing was found
            if(isempty(idx))
                message = "No equilibrium was found near the given point with the required tolerance.";
                    app.displayMessage(message,2)
            else
                app.displayEquilibrium(idx)
                app.findEqMode = false; % Turn off eq mode after click
            end
        end
 
        function displayEquilibrium(app,idx)
            % Highlights and offers information on an equilibrium
            try
          
                J = round(app.equilibria(idx).J,app.eqSigs);
                Xeq = app.equilibria(idx).Xeq;
                stability = app.equilibria(idx).stability;
                eqType = app.equilibria(idx).type;
                lambda = app.equilibria(idx).lambda;
                V = round(app.equilibria(idx).V,app.eqSigs);
                Feq = app.equilibria(idx).Feq;
                Jstr = num2str(J,3);
                V1str = num2str(V(:,1),3);
                V2str = num2str(V(:,2),3);
                
                % Show the Jacobian, eigenvalues, and eigenvectors
                message = "Possible equilibrum at (" + num2str(Xeq(1),app.formatSpec)...
                   + "," + num2str(Xeq(2),app.formatSpec) + ") where " + newline + "[dxdt,dydt] ~= " + mat2str(Feq,3) + newline ...
                   + "Looks like a " + stability  + eqType + " to me. " ...
                   + newline + newline  ...
                   + "J ~=  |" + Jstr(1,:) + "|" + newline ...
                   + "      |" + Jstr(2,:) + "|" + newline + "with eigenvalues: " + newline ...
                   + " lambda1 = " +num2str(lambda(1),app.formatSpec) +newline ...
                   +" lambda2 = " + num2str(lambda(2),app.formatSpec) + newline ...
                   + "and corresponding eigenvectors " + newline ...
                   + " v1 = |" + V1str(1,:) + "|" + newline ...
                   + "      |" + V1str(2,:) + "|" + newline ...
                   + " v2 = |" + V2str(1,:) + "|" + newline ...
                   + "      |" + V2str(2,:) + "|";
                app.displayMessage(message,-1)
          
                % Highlight it
                for k = linspace(0,pi,25)
                    variation1 = sin(k).^2;
                    variation2 = 1+variation1/2;
                    
                    set(app.equilibriaPlots{idx},"MarkerSize",app.msEq + 1*app.msEq*variation1);
                    set(app.equilibriaPlots{idx},"MarkerEdgeColor",[0.9 0.6 0],'linewidth',2);
                    
                    for j = 1:size(app.eigenvectorPlots,2)
                        if(~isempty(app.eigenvectorPlots{idx,j}))
                            h = app.eigenvectorPlots{idx,j};                        
                            if(k == 0)
                                u0(j) = get(h,"UData");
                                v0(j) = get(h,"VData");
                            end
                            set(h,"UData",u0(j)*variation2,"VData",v0(j)*variation2);
                        end
                    end
                    pause(0.01)
                end
                set(app.equilibriaPlots{idx},"MarkerEdgeColor","None");
              
            catch ME
                message = "Equilibrium display failed with error: " + ME.message;
                app.displayMessage(message,1)
            end
        end       
             
        function [eqType,stability] = classifyEquilibrium(app,lambda)
            % Classifies an equilibrium based on its eigenvalues
            p = round(lambda(1) + lambda(2),app.eqSigs);
            q = round(lambda(1)*lambda(2),app.eqSigs);
            delta = round((lambda(1) - lambda(2))^2,app.eqSigs);
            
            % Equilibrium type
            if( round(lambda(1),app.eqSigs) == 0 || round(lambda(2),app.eqSigs) == 0)
                eqType = "degenerate equilibrium";
            elseif(q>0 && delta >=0)
                eqType = "node";
            elseif( q<0 )
                eqType = "saddle";
            elseif( p==0 && q>0 )
                eqType = "center";
            else
                eqType = "spiral";
            end
            
            % Stability
            if( round(lambda(1),app.eqSigs) == 0 || round(lambda(2),app.eqSigs) == 0)
                stability = "";
            elseif( round(real(lambda(1)),app.eqSigs) == 0 || round(real(lambda(2)),app.eqSigs) == 0)
                stability = "";
            elseif(p < 0 && q > 0)
                stability = "stable and attractive ";
            elseif(p<=0 && q > 0) 
                stability = "stable ";
            else
                stability = "unstable ";
            end
        end

        function displayEigenvector(app,idx)
            % Generates a quiver plot for an eigenvector
            for j = 1:2
                if( all(abs(imag(app.equilibria(idx).V(:,j)))<1e-12))
                    
                    scale = app.eigScaleFactor*sqrt( (app.xmaxEditField.Value - app.xminEditField.Value).^2 ...
                                   + (app.ymaxEditField.Value - app.yminEditField.Value).^2 );
                    u = scale*real(app.equilibria(idx).V(1,j));
                    v = scale*real(app.equilibria(idx).V(2,j));
                    app.eigenvectorPlots{idx,j} = quiver(app.UIAxes_pp,...
                        app.equilibria(idx).Xeq(1),app.equilibria(idx).Xeq(2),u,v,...
                        "Color",app.eigenvectorColor,"AutoScale","off",...
                        "Visible",app.showEigenvectorsSwitch,'linewidth',app.evlw,...
                        "MaxHeadSize",app.evHeadsize);
                else
                    app.eigenvectorPlots{idx,j} = [];
                end
            end
        end
        
        function toggleEigenvectors(app)
            % Sets the visibility of the eigenvector quivers
            for k = 1:size(app.eigenvectorPlots,1)
                for j = 1:size(app.eigenvectorPlots,2)
                    if(~isempty(app.eigenvectorPlots{k,j}))
                        app.eigenvectorPlots{k,j}.Visible = app.showEigenvectorsSwitch;
                    end
                end
            end
        end
   
        function solveForSaddleSeparatrices(app)
            % This function generates separatrices
            app.PhasePlaneUIFigure.Pointer = "watch";
            noSaddlesFlag = true;
            if(~isempty(app.equilibria))
                % Check if there are any saddles
                saddleIndices = find([app.equilibria.type] == "saddle");
                noSaddlesFlag = ~any(saddleIndices);
            end
            
            if(~noSaddlesFlag)
                % If there are saddles, solve for separatrices
                message = "Saddles found. Solving for the separatrices...";
                app.displayMessage(message,0)
                
                % Find separatrices
                app.generateSaddleSeparatrices(saddleIndices)
                
                message = "Finished solving for and plotting separatrices.";
                app.displayMessage(message,-1)                
            else
                % Otherwise just complain
                message = "No saddle equilibria found." + newline + newline ...
                    + "Solve for equilibria before attemping to solve for the separatrices.";
                app.displayMessage(message,1)
            end

            app.PhasePlaneUIFigure.Pointer = "arrow";
        end
        
        function generateSaddleSeparatrices(app,saddleIndices)
            % Store user settings
            tmaxUser = app.ForwardsolutiontmaxEditField.Value;
            tminUser = app.BackwardsolutiontminEditField.Value;
            terminateAtBoundary = app.TerminatesolutionsataxislimitsCheckBox.Value;
            animateSolnUser = app.animateSolnSwitch;            
            
            try
                % Adjust settings as needed
                app.TerminatesolutionsataxislimitsCheckBox.Value = true;
                app.animateSolnSwitch = false;
                app.ForwardsolutiontmaxEditField.Value = app.tmaxSeparatrix;
                app.BackwardsolutiontminEditField.Value = -app.tmaxSeparatrix;
                t = [];
                Y = [];
                for idx = saddleIndices
                    for j = 1:2
                        % The equilibrium and associated step in the
                        % direction of the eigenvector
                        Yeq = app.equilibria(idx).Xeq ;
                        deltaY0 = app.equilibria(idx).V(:,j)*app.separatrixInitialStep;
                        
                        % start upstream
                        Y0 = Yeq + deltaY0;
                        [tF,yF,tR,yR] = app.generateSolutionCurve(Y0);
                        t = [t;NaN;tR(end:-1:1);tF];
                        Y = [Y;[NaN,NaN];yR(end:-1:1,:);yF];
                        % start downstream 
                        Y0 = Yeq - deltaY0;
                        [tF,yF,tR,yR] = app.generateSolutionCurve(Y0);
                        t = [t;NaN;tR(end:-1:1);tF];
                        Y = [Y;[NaN,NaN];yR(end:-1:1,:);yF];
                    end
                end
                % Plot the result
                hold(app.UIAxes_pp,"on")
                app.separatricesPlot = plot(app.UIAxes_pp,Y(:,1),Y(:,2),"Color",app.separatrixColor,...
                    "LineWidth",app.separatrixlw,"PickableParts","none");
                hold(app.UIAxes_pp,"off")
                
            catch ME
                message = "Error while solving for separatrices due to: " + ME.message;
                app.displayMessage(message,1)
            end
            
            % Return user settings
            app.ForwardsolutiontmaxEditField.Value = tmaxUser;
            app.BackwardsolutiontminEditField.Value = tminUser;
            app.TerminatesolutionsataxislimitsCheckBox.Value = terminateAtBoundary;
            app.animateSolnSwitch = animateSolnUser;
            
        end
        
        
        function updateNullclines(app)
            
            if(app.nullclinesSwitch)
                try
                    % set up grid
                    xlims = [app.xminEditField.Value, app.xmaxEditField.Value];
                    ylims = [app.yminEditField.Value, app.ymaxEditField.Value];
                    x = linspace(xlims(1),xlims(2),app.Nnc);
                    y = linspace(ylims(1),ylims(2),app.Nnc);
                    [X,Y] = meshgrid(x,y);
            
                    % Pull ODE func
                    dYplane = app.dY_xy_cell(X,Y);
                    dxdt = dYplane{1};
                    dydt = dYplane{2};
            
                    % Generate contours
                    hold(app.UIAxes_pp,"on")
                    [~,app.nullclinesPlots{1}] = contour(app.UIAxes_pp,X,Y,dxdt,...
                        [0,0],'Color',app.nullclineColors(1,:),'linewidth',app.nclw);
                    [~,app.nullclinesPlots{2}] = contour(app.UIAxes_pp,X,Y,dydt,...
                        [0,0],'Color',app.nullclineColors(2,:),'linewidth',app.nclw);
                    hold(app.UIAxes_pp,"off")
                catch ME
                    app.displayMessage("Error displaying nullclines: " + ME.message,2)
                end
            else
                app.clearNullclines
            end
        end
        
        
         % Isoclines
        function autoGenerateIsoclines(app)
             [isoPlotHandles,isoLabelHandles] = SharedFunctions.autoGenerateIsoclines(app.xminEditField.Value,...
                app.xmaxEditField.Value,app.yminEditField.Value,app.ymaxEditField.Value,...
                app.Niso,app.dY_xy_cell,app.UIAxes_pp,app.isoclineColors,app.isolw,app.MessageArea, ...
                app.isoMinPoints,app.isoRemovePoints,app.isoTextColor,app.isoFontsize,app.isoNumSpec);
             
             app.storeIsoclinePlots(isoPlotHandles,isoLabelHandles)
        end
        
        function generateIsoclineThroughPoint(app,location)
             [isoPlotHandles,isoLabelHandles] = SharedFunctions.generateIsoclineThroughPoint(location,app.xminEditField.Value,...
                app.xmaxEditField.Value,app.yminEditField.Value,app.ymaxEditField.Value,...
                app.Niso,app.dY_xy_cell,app.UIAxes_pp,app.isoclineColors,app.isolw,app.MessageArea, ...
                app.isoMinPoints,app.isoRemovePoints,app.isoTextColor,app.isoFontsize,app.isoNumSpec);
            
             app.storeIsoclinePlots(isoPlotHandles,isoLabelHandles)
        end

       function generateIsoclinesFromVector(app)
             [isoPlotHandles,isoLabelHandles] = SharedFunctions.generateIsoclinesFromVector(app.xminEditField.Value,...
                app.xmaxEditField.Value,app.yminEditField.Value,app.ymaxEditField.Value,...
                app.Niso,app.dY_xy_cell,app.UIAxes_pp,app.isoclineColors,app.isolw,app.MessageArea, ...
                app.isoMinPoints,app.isoRemovePoints,app.isoTextColor,app.isoFontsize,app.isoNumSpec);
             
             app.storeIsoclinePlots(isoPlotHandles,isoLabelHandles)
       end
       
       function storeIsoclinePlots(app,isoPlotHandles,isoLabelHandles)
           % Saves the iscocline plot handles
           N = numel(app.isoclinesPlots);
           indices = N + (1:numel(isoPlotHandles));
           for k = 1:numel(indices)
               app.isoclinesPlots{indices(k)} = isoPlotHandles{k};
               app.isoclinesLabels{indices(k)} = isoLabelHandles{k};
           end
       end
    
        %--------------------- END ANALYSIS FUNCTIONS ---------------------%


        %---------------------- SOLVE MENU FUNCTIONS ---------------------%        
        function solveFromRegion(app)
            try
                % Drawing region
                app.userDrawing = true;
                % Pull solution density
                Nx = app.SolvefromregiondensitySlider.Value;
                Ny = app.SolvefromregiondensitySlider.Value;
  
                  % Start the freehand
                message = "Draw a region on the phase plane: "  ...
                    +newline+"1. Click once to start drawing. "...
                    +newline+"2. Click again to finish drawing."...
                    +newline+newline+"When complete, solutions will be computed at regular intervals starting inside the region.";
                app.displayMessage(message,-1)
             
                closed = true;
                 [xr,yr,pHandle] = SharedFunctions.freedraw(app.PhasePlaneUIFigure,app.PhaseplanePanel,...
                    app.UIAxes_pp,app.xminEditField.Value,app.xmaxEditField.Value,...
                    app.yminEditField.Value,app.ymaxEditField.Value,closed);
                delete(pHandle)
                
                % Plot the polygon region
                pgon = polyshape(xr,yr,"Simplify",false);
                hold(app.UIAxes_pp,"on")
                areaPlot = plot(app.UIAxes_pp,pgon,"FaceColor","Blue","Edgealpha",0);
                hold(app.UIAxes_pp,"off")

                % Regularly spaced grid of points to select from
                x = linspace(app.xminEditField.Value,app.xmaxEditField.Value,Nx);
                y = linspace(app.yminEditField.Value,app.ymaxEditField.Value,Ny);
                [X,Y] = meshgrid(x,y);
                xp = X(:);
                yp = Y(:);
                
                % Get points in the region
                hit = inpolygon(xp,yp,xr,yr);
                idx = find(hit == 1);
    
                % Loop through points and solve/plot
                for k = 1:numel(idx)
                    y0 = [xp(idx(k)),yp(idx(k))];
                    %Solve
                    [tF,yF,tR,yR] = app.generateSolutionCurve(y0);
                    %Plot the solution             
                    app.plotSolution(tF,yF,tR,yR,'-',true)
                end  
                
                % Done drawing region
                app.userDrawing = false;
                % Delete the region
                delete(areaPlot)           
            catch ME
                 app.displayMessage("Error solving from region: " + ME.message,2)
            end
                
            % reset the motion callback (in case there are any issues)
            set(app.PhasePlaneUIFigure,"WindowButtonMotionFcn",@app.windowMotion)
            set(app.PhasePlaneUIFigure,"WindowButtonDownFcn",@app.windowButtonDown)
        end
        %-------------------- END SOLVE MENU FUNCTIONS -------------------%     
        
        
        %---------------------- DRAW MENU FUNCTIONS ---------------------%     
        function drawSolution(app,compareFlag)
            try
                % Allows user to draw a solution by hand and then compares
                % their solution to an actual solution
                message ="1. Click once on the phase plane to start drawing. "...
                   +newline+"2. Click again to finish drawing.";
                app.displayMessage(message,-1)
                app.userDrawing = true;
                
                closed = false;
                [xr,yr,pHandle]  = SharedFunctions.freedraw(app.PhasePlaneUIFigure,app.PhaseplanePanel,...
                    app.UIAxes_pp,app.xminEditField.Value,app.xmaxEditField.Value,...
                    app.yminEditField.Value,app.ymaxEditField.Value,closed);
                delete(pHandle)
                
                % Plot the drawing as a solution
                N = 1e3;
                tr = linspace(0,1,length(xr))';
                
                tF = linspace(0,1,N)';
                Yr = [xr,yr];
                yF = interp1(tr,Yr,tF,"linear");
               
                app.plotSolution(tF,yF,[],[],'--',false)

                if(compareFlag)
                   
                    % Range of the solution
                    trange = [0,app.tmaxEditField.Value];

                    % Solve
                    y0 = yF(1,:);
                    [t,y] = solveODE(app,trange,y0);
                                  
                    % Plot the solution             
                    app.plotSolution(t,y,[],[],'-',true)
                else
                    
                    message = "Done drawing." ;
                    app.displayMessage(message,0)
                end
                
            
            catch ME
                 app.displayMessage("Error drawing solution: " + ME.message,2)
            end
            app.userDrawing = false;
            % reset the motion callback (in case there are any issues)
            set(app.PhasePlaneUIFigure,"WindowButtonMotionFcn",@app.windowMotion)
            set(app.PhasePlaneUIFigure,"WindowButtonDownFcn",@app.windowButtonDown)
            
        end
        
        
        %---- CLICK CALLBACKS AND CLICK CALLBACK SUPPORTING FUNCTIONS ----%
         function windowMotion(app,obj,event)
            try
                % Write the current point if in the PP axis
                textLabel = "-";
                location = SharedFunctions.checkIfInFieldAxis(event,app.PhaseplanePanel,...
                    app.UIAxes_pp,app.xminEditField.Value,app.xmaxEditField.Value,...
                    app.yminEditField.Value,app.ymaxEditField.Value);
            
                if(~isnan(location))
                    textLabel = "(" + num2str(location(1),app.formatSpec) + ", " + num2str(location(2),app.formatSpec) + ")"; 
                    if( app.findEqMode || app.singleIsoclineMode)
                        obj.Pointer = "crosshair";
                    elseif (app.userDrawing)
                        % If drawing an ROI, don't do anything here
                    else
                        obj.Pointer = "hand";
                    end
                else
                    obj.Pointer = "arrow";
                end
                app.PositionLabel.Text = textLabel;
                pause(0)
            catch ME
                 app.displayMessage("Error in windowMotion function: "...
                    + ME.message + newline + "If this persists try restarting the app.",3)
                
            end
        end
        
        function windowButtonDown(app,obj,event)   

            % Check if the current point is on the PP axis
            location = SharedFunctions.checkIfInFieldAxis(event,app.PhaseplanePanel,...
                app.UIAxes_pp,app.xminEditField.Value,app.xmaxEditField.Value,...
                app.yminEditField.Value,app.ymaxEditField.Value);
            
            % If you're creating an ROI, skip this
            if(app.userDrawing)
                return
            end
            % If you're looking for an equilibrium, just do that if you're
            % on the axis
            if(app.findEqMode && all(~isnan(location)))
                app.findSingleEquilibrium(location)
                return
            end
            
            if(app.singleIsoclineMode && all(~isnan(location)))
                  app.generateIsoclineThroughPoint(location)
                  app.singleIsoclineMode = false;
                  obj.Pointer = "hand";
                return
            end      
            % If you don't click on a line, try to find a point
            % Click must be normal
            if( class(event.HitObject) ~= "matlab.graphics.chart.primitive.Line" ...
                && all(~isnan(location))...
                && event.Source.SelectionType == "normal")
                
                % Solve for the solution
                [tF,yF,tR,yR] = app.generateSolutionCurve(location);
                % Plot the solution             
                app.plotSolution(tF,yF,tR,yR,'-',true)
            end         

        end
        
        function deletePlotCallback(app,src,event,idx)
            
            % Delete the objects
            delete(app.plotHandlesPP{idx})
            delete(app.plotHandlesX{idx})
            delete(app.plotHandlesY{idx})
            delete(app.plotTextHandles{idx})
            delete(app.plotMarkerHandles{idx})
            
            % Just store an empty value in the array
            app.plotHandlesPP{idx} = [];
            app.plotHandlesX{idx} = [];
            app.plotHandlesY{idx} = [];
            app.plotTextHandles{idx} = [];
            app.plotMarkerHandles{idx} = [];

            message = "Plot " + idx + " removed.";
            app.displayMessage(message,0)
            
        end
        
        function highlightPlotCallback(app,src,event,idx)
            try
                % If its a right click, dont highlight, but delete instead
                if( src.Parent.Parent.Parent.Parent.Parent.SelectionType == "alt")
                    deletePlotCallback(app,src,event,idx)
                    return
                end
                
                % If a highlight already exists, delete it
                if(~isempty(app.highlightHandles))
                    app.deleteHighlightsCallback(src,event)
                end
    
                % Create plot highlights
                app.highlightHandles{1} = copyobj( app.plotHandlesPP{idx}, app.UIAxes_pp);
                set( app.highlightHandles{1},"linewidth",app.lw*2.5,"color",app.highlightColor,"LineStyle","-")
                app.highlightHandles{2} = copyobj( app.plotHandlesPP{idx}, app.UIAxes_pp);
                set(app.highlightHandles{2},"linewidth",app.lw*0.8)             
                app.highlightHandles{3} = copyobj( app.plotHandlesX{idx}, app.UIAxes_x);
                set( app.highlightHandles{3},"linewidth",app.lw*2.5,"color",app.highlightColor)
                app.highlightHandles{4} = copyobj( app.plotHandlesX{idx}, app.UIAxes_x);
                set(app.highlightHandles{4},"linewidth",app.lw*0.8)
                app.highlightHandles{5} = copyobj( app.plotHandlesY{idx}, app.UIAxes_y);
                set( app.highlightHandles{5},"linewidth",app.lw*2.5,"color",app.highlightColor)
                app.highlightHandles{6} = copyobj( app.plotHandlesY{idx}, app.UIAxes_y);
                set(app.highlightHandles{6},"linewidth",app.lw*0.8)
                app.highlightHandles{7} = copyobj(app.plotMarkerHandles{idx}, app.UIAxes_pp);
                set( app.highlightHandles{7},"linewidth",app.lw*2.5,"color",app.highlightColor)
                app.highlightHandles{8} = copyobj(app.plotMarkerHandles{idx}, app.UIAxes_pp);
                set(app.highlightHandles{8},"linewidth",app.lw*0.8)
                
                        
                %Hide labels
                for k = 1:numel(app.plotTextHandles)
                    if(k ~= idx)
                        app.plotTextHandles{k}.Visible = "off";
                    end
                end
                %Hide markers
                for k = 1:numel(app.plotMarkerHandles)
                    app.plotMarkerHandles{k}.Visible = "off";
                end
                %Dim lines
                for j = linspace(0,1,10)
                    for k = 1:numel(app.plotHandlesX)
                        if(~isempty(app.plotHandlesX{k}))
                            app.plotHandlesX{k}.Color(4) = 1+(-1+app.highlightEdgeAlpha)*j;
                            app.plotHandlesY{k}.Color(4) = 1+(-1+app.highlightEdgeAlpha)*j;
                            app.plotHandlesPP{k}.Color(4) = 1+(-1+app.highlightEdgeAlpha)*j;
                        end
                    end
                    pause(0.001)
                end
                
                % Set callbacks that will delete the highlight
                for k = 1:numel(app.highlightHandles)
                    set(app.highlightHandles{k},"ButtonDownFcn",@app.deleteHighlightsCallback)
                end
            catch ME
                app.displayMessage("Error in highlightPlotCallback due to: "...
                    + ME.message,3)
            end
        end
  
        function deleteHighlightsCallback(app,src,event) 
            % Deletes the plot highlights
            for k = 1:numel(app.highlightHandles)
                delete(app.highlightHandles{k})
            end
            app.highlightHandles = {};       
            
            
            for k = 1:numel(app.plotHandlesX)
                if(~isempty(app.plotHandlesX{k}))
                    app.plotHandlesX{k}.Color(4) = 1;
                    app.plotHandlesY{k}.Color(4) = 1;
                end
            end
            
            % Show other plots again
            for k = 1:numel(app.plotHandlesPP)
                if(~isempty(app.plotHandlesPP{k}))
                    app.plotHandlesPP{k}.Color(4) = 1;
                end
            end
            %Show markers again
            for k = 1:numel(app.plotMarkerHandles)
                app.plotMarkerHandles{k}.Visible = "on";
            end
            %Show labels again if labels are on
            if(app.pointLabelsSwitch)
                for k = 1:numel(app.plotTextHandles)
                    app.plotTextHandles{k}.Visible = "on";
                end
            end
        end    
        
        function equilibriumCallback(app,src,event,idx) 
            app.displayEquilibrium(idx)
        end

        %-- END CLICK CALLBACKS AND CLICK CALLBACK SUPPORTING FUNCTIONS --%

        %------------------- USER INTERFACE FUNCTIONS  -------------------%          
        function displayMessage(app,message,errorFlag)
            % This calls the shared function:
            SharedFunctions.displayMessage(message,errorFlag,app.MessageArea,app.StartPanel)
        end
        
        function updateParams(app)
           % This updates all of the user defined parameters
           % (only used internally for the library systems)
           app.updateParam(app.p1Name.Value,app.p1Value.Value,1)
           app.updateParam(app.p2Name.Value,app.p2Value.Value,2)
           app.updateParam(app.p3Name.Value,app.p3Value.Value,3)
           app.updateParam(app.p4Name.Value,app.p4Value.Value,4)
           app.updateParam(app.p5Name.Value,app.p5Value.Value,5)
           app.updateParam(app.p6Name.Value,app.p6Value.Value,6)
        end
        
        function updateParam(app,prmName,prmValue,prmIndex)
            % Stores one of the parameters
            app.params(prmIndex).name = prmName;
            if(~isempty(prmValue))
                eval("numVal = num2str(" + prmValue + ",'%0.8g');")
            else
                numVal ="";
            end
            app.params(prmIndex).value = numVal;
        end
        
        function out = checkVariableName(app,value,previousValue)
            % This function checks for issues with the variable names
            % selected by the user
            if(isempty(value))% Okay to just remove the name
                out = value;
                return
            end            
            [dupFlag,tFlag] = app.checkDuplicateNames;
            if(~isvarname(value))
                message = "'" + value + "' is not a valid MATLAB variable name. " ...
                    + newline + "Select a different variable name.";
                app.displayMessage(message,2)
                out = previousValue;
            elseif(dupFlag)
                message = "Looks like you already used '" + value + "'" ...
                    + newline + "Select a different variable name.";
                app.displayMessage(message,2)
                out = previousValue;
            elseif(tFlag)
                message = "The variable 't' is reserved for the independent variable."...
                    + newline + "Select a different variable name.";
                app.displayMessage(message,2)
                out = previousValue;
                
            else
                out = value;
                message = "Name updated successfully to " + out;
                app.displayMessage(message,0)
            end
        end
        
        function [dupFlag,tflag] = checkDuplicateNames(app)
            % Check for duplicates
           names(1) = string(app.p1Name.Value);
           names(2) = app.p2Name.Value;
           names(3) = app.p3Name.Value;
           names(4) = app.p4Name.Value;
           names(5) = app.p5Name.Value;
           names(6) = app.p6Name.Value;
           names(7) = app.Variable1EditField.Value;
           names(8) = app.Variable2EditField.Value;           
           names(names=="") = [];
           dupFlag = length(names) ~= length(unique(names));
           % Check for "t"
           tflag = any(names == "t");
        end
        
        function toggleLabels(app)
            % Turns initial point labels on and off
            for k = 1:numel(app.plotTextHandles)
                app.plotTextHandles{k}.Visible = app.pointLabelsSwitch;
            end
            
            for k = 1:numel(app.equilibriaLabels)
                app.equilibriaLabels{k}.Visible = app.pointLabelsSwitch;
            end
        end
        
        function tAxisMaxPopup(app,event)
            % If a user sets axis limit tmax, this will query the user and
            % set the solver stop time to match if requested
            message = "You changed the axis limit tmax to " + event.Source.Value + "." + newline + ...
                "Do you want to update the solver forward stop time to match the axis limit?";
            title = "Update solver stop time?";
            out = uiconfirm(app.PhasePlaneUIFigure, message, title,...
                'Options', {'Yes','No'},'DefaultOption', 'Yes', 'CancelOption', 'No');
            if(strcmp(out,'Yes'))
                app.ForwardsolutiontmaxEditField.Value = event.Source.Value;
                message = "Updated solver forward stop time to " + event.Source.Value;
                app.displayMessage(message,0)
            end
        end        
        
        function tAxisMinPopup(app,event)
            % If a user sets axis limit tmin, this will query the user and
            % set the solver backward stop time to match if requested
            message = "You changed the axis limit tmin to " + event.Source.Value + "." + newline + ...
                "Do you want to update the solver backward stop time to match the axis limit?";
            title = "Update solver stop time?";
            out = uiconfirm(app.PhasePlaneUIFigure, message, title,...
                'Options', {'Yes','No'},'DefaultOption', 'Yes', 'CancelOption', 'No');
            if(strcmp(out,'Yes'))
                app.BackwardsolutiontminEditField.Value = event.Source.Value;
                message = "Updated solver backward stop time to " + event.Source.Value;
                app.displayMessage(message,0)
            end
        end
     
 
        %----------------- END USER INTERFACE FUNCTIONS  -----------------%    
        
        
        %-------------------- LIBRARY MENU FUNCTIONS  --------------------%    
        function odeSystemMenuSelected(app,src,event,odeSystemData)
            % Set parameters
            app.clearParameters
            app.p1Name.Value = odeSystemData.pNames(1);
            app.p1Value.Value = odeSystemData.pValues(1);
            app.p2Name.Value = odeSystemData.pNames(2);
            app.p2Value.Value = odeSystemData.pValues(2);
            app.p3Name.Value = odeSystemData.pNames(3);
            app.p3Value.Value = odeSystemData.pValues(3);
            app.p4Name.Value = odeSystemData.pNames(4);
            app.p4Value.Value = odeSystemData.pValues(4);
            app.p5Name.Value = odeSystemData.pNames(5);
            app.p5Value.Value = odeSystemData.pValues(5);
            app.p6Name.Value = odeSystemData.pNames(6);
            app.p6Value.Value = odeSystemData.pValues(6);
     
            % Set ODE system
            app.Variable1EditField.Value = odeSystemData.varNames(1);
            app.Variable2EditField.Value = odeSystemData.varNames(2);
            app.EditField_dxdt.Value = odeSystemData.dxdtExpression;
            app.EditField_dydt.Value = odeSystemData.dydtExpression;

            % Set axis limits
            app.setAxisLimits(odeSystemData.xylims, odeSystemData.tlims)

            % Update
            app.updateParams
            app.updateEquations
            
            % Message
            message = "ODE system set to '"+odeSystemData.name + "'.";
            app.displayMessage(message,-1)
        end

        function saveLibrary(app)
            % Saves the user library to a MAT file
            SharedFunctions.saveLibrary(app.defaultLibraryName,app.userODESystems,app.MessageArea)
        end
   
        function loadLibrary(app)  
            % Clear current library
            app.clearCustomLibrary

            % loads a user library from a MAT file
            app.userODESystems = SharedFunctions.loadLibrary(app.defaultLibraryName,app.MessageArea);
            
            % Add them to the menu         
            for k = 1:numel(app.userODESystems)
                app.addUserODEMenu(k,app.userODESystems{k})
            end
        end
             
        function clearCustomLibraryRequest(app)
            title = "Clear library";
            message = "Are you sure you want to clear the custom library?";
            selection = uiconfirm(app.PhasePlaneUIFigure,message,title);
            if(selection)
               app.clearCustomLibrary
               message = "Custom library cleared.";
               app.displayMessage(message,0)
            end
        end
        
        function addUserODESystem(app,src,event)
            prompt = "Name your system";
            title = "Add system";
            name = inputdlg(prompt,title,[1 40],"");
            if( isempty(name) )
                name = "";
            end
            if(name == "")
                message = "ODE system was not saved (you must give the system a name).";
                app.displayMessage(message,1)
                return
            end
            odeSystemData.name = name{1};
 
            % Retrieve the current settings
            odeSystemData.pNames = [string(app.p1Name.Value), string(app.p2Name.Value), ...
                string(app.p3Name.Value), string(app.p4Name.Value), string(app.p5Name.Value), string(app.p6Name.Value)];
            odeSystemData.pValues = [string(app.p1Value.Value), string(app.p2Value.Value),...
                string(app.p3Value.Value), string(app.p4Value.Value), string(app.p5Value.Value), string(app.p6Value.Value)];

            % Set ODE system
            odeSystemData.varNames = [string(app.Variable1EditField.Value), string(app.Variable2EditField.Value)];
            odeSystemData.dxdtExpression = app.EditField_dxdt.Value;
            odeSystemData.dydtExpression = app.EditField_dydt.Value;

            % Set axis limits
            odeSystemData.xylims = [app.xminEditField.Value,app.xmaxEditField.Value,...
                                    app.yminEditField.Value,app.ymaxEditField.Value];
            odeSystemData.tlims = [app.tminEditField.Value,app.tmaxEditField.Value];
       
            % Store ODE system data
            idx = numel(app.userODESystems)+1;
            app.userODESystems{idx} = odeSystemData;
            
            % Add the menu
            app.addUserODEMenu(idx,odeSystemData)
        
            % Display a message
            message = "User ODE system '"+odeSystemData.name+"' saved with: " + newline ...
                + "dxdt = " + odeSystemData.dxdtExpression + newline  ...
                + "dydt = " + odeSystemData.dydtExpression; 
            app.displayMessage(message,0)
        end
        
        function addUserODEMenu(app,idx,odeSystemData)
            % Add the system to the menu
            app.userOdeMenuHandles{idx} = uimenu(app.CustomlibraryMenu); 
            app.userOdeMenuHandles{idx}.MenuSelectedFcn = {@app.odeSystemMenuSelected,odeSystemData};
            app.userOdeMenuHandles{idx}.Text = odeSystemData.name;
            if(idx == 1)
                app.userOdeMenuHandles{idx}.Separator = true;
            end
        end
        
        function addDefaultODESystem(app,odeSystemData)
            % Add the system to the menu
            idx = numel(app.defaultOdeMenuHandles)+1;
            app.defaultOdeMenuHandles{idx} = uimenu(app.LibraryMenu); 
            app.defaultOdeMenuHandles{idx}.MenuSelectedFcn = {@app.odeSystemMenuSelected,odeSystemData};
            app.defaultOdeMenuHandles{idx}.Text = odeSystemData.name;
            
        end
      
        function createDefaultODEMenus(app)
            % 0. default
            odeSystemData.name = "default";
            odeSystemData.pNames = ["","","","","",""];
            odeSystemData.pValues = ["","","","","",""];
            odeSystemData.varNames = ["x","y"];
            odeSystemData.dxdtExpression = " 2*x - y + 3*(x^2-y^2) + 2*x*y";
            odeSystemData.dydtExpression = " x - 3*y - 3*(x^2-y^2) + 3*x*y";
            odeSystemData.xylims = [-2 4 -4 2];
            odeSystemData.tlims = [-5 25];
            addDefaultODESystem(app,odeSystemData)
            
            % 1. van der pol
            odeSystemData.name = "van der Pol";
            odeSystemData.pNames = ["mu","","","","",""];
            odeSystemData.pValues = ["1","","","","",""];
            odeSystemData.varNames = ["x","y"];
            odeSystemData.dxdtExpression = "mu*x - y - x^3";
            odeSystemData.dydtExpression = "x";
            odeSystemData.xylims = [-2 2 -2 2];
            odeSystemData.tlims = [0 10];
            addDefaultODESystem(app,odeSystemData)
            
            % 2. pendulum
            odeSystemData.name = "pendulum";
            odeSystemData.pNames = ["D","","","","",""];
            odeSystemData.pValues = ["0","","","","",""];
            odeSystemData.varNames = ["theta","omega"];
            odeSystemData.dxdtExpression = "omega";
            odeSystemData.dydtExpression = "-sin(theta) - D*omega";
            odeSystemData.xylims = [-3.2 9.5 -5 5];
            odeSystemData.tlims = [0 10];
            addDefaultODESystem(app,odeSystemData)
            
            % 3. linear system
            odeSystemData.name = "linear system";
            odeSystemData.pNames = ["a","c","","b","d",""];
            odeSystemData.pValues = ["1.5","-1","","1","-2",""];
            odeSystemData.varNames = ["x","y"];
            odeSystemData.dxdtExpression = "a*x + b*y";
            odeSystemData.dydtExpression = "c*x + d*y";
            odeSystemData.xylims = [-2 2 -2 2];
            odeSystemData.tlims = [-5 5];
            addDefaultODESystem(app,odeSystemData)
            
             % 4. mass-spring-damper
            odeSystemData.name = "mass-spring-damper";
            odeSystemData.pNames = ["k","d","","m","",""];
            odeSystemData.pValues = ["5","0","","1","",""];
            odeSystemData.varNames = ["x","v"];
            odeSystemData.dxdtExpression = "v";
            odeSystemData.dydtExpression = "-(k*x + d*v)/m";
            odeSystemData.xylims = [-2.5 2.5 -5 5];
            odeSystemData.tlims = [0 10];
            addDefaultODESystem(app,odeSystemData)
            
            % 5. predator-prey
            odeSystemData.name = "predator-prey";
            odeSystemData.pNames = ["a","c","","b","d",""];
            odeSystemData.pValues = ["0.5","0.2","","0.5","0.3",""];
            odeSystemData.varNames = ["prey","predator"];
            odeSystemData.dxdtExpression = "prey*(a-b*predator)";
            odeSystemData.dydtExpression = "-predator*(c - d*prey)";
            odeSystemData.xylims = [0 2.5 0 2.5];
            odeSystemData.tlims = [0 30];
            addDefaultODESystem(app,odeSystemData)
            
            % 6. competing species
            odeSystemData.name = "competing species";
            odeSystemData.pNames = ["r","a","","s","b",""];
            odeSystemData.pValues = ["1.5","0.2","","2.8","0.5",""];
            odeSystemData.varNames = ["x","y"];
            odeSystemData.dxdtExpression = "r*(1-x-a*y)*x";
            odeSystemData.dydtExpression = "s*(1-y-b*x)*y";
            odeSystemData.xylims = [-0.01 2 -0.01 2];
            odeSystemData.tlims = [0 10];
            addDefaultODESystem(app,odeSystemData)
            
            % 7. cooperative species
            odeSystemData.name = "cooperative species";
            odeSystemData.pNames = ["r","a","","s","b",""];
            odeSystemData.pValues = ["1","1","","0.8","0.4",""];
            odeSystemData.varNames = ["x","y"];
            odeSystemData.dxdtExpression = "r*(1-x+a*y)*x";
            odeSystemData.dydtExpression = "s*(1-y+b*x)*y";
            odeSystemData.xylims = [-0.01 3 -0.01 3];
            odeSystemData.tlims = [0 5];
            addDefaultODESystem(app,odeSystemData)
            
            % 8. Duffing oscillator
            odeSystemData.name = "Duffing oscillator";
            odeSystemData.pNames = ["k","d","","m","beta",""];
            odeSystemData.pValues = ["3","0","","1","2",""];
            odeSystemData.varNames = ["x","y"];
            odeSystemData.dxdtExpression = "y";
            odeSystemData.dydtExpression = "-(d*y + k*x + beta*x^3)/m";
            odeSystemData.xylims = [-2.5 2.5 -5 5];
            odeSystemData.tlims = [0 5];
            addDefaultODESystem(app,odeSystemData)
            
            % 9. square limit set
            odeSystemData.name = "square limit set";
            odeSystemData.pNames = ["a","","","","",""];
            odeSystemData.pValues = ["0.2","","","","",""];
            odeSystemData.varNames = ["x","y"];
            odeSystemData.dxdtExpression = "(y + a*x)*(1-x^2)";
            odeSystemData.dydtExpression = "-x*(1-y^2)";
            odeSystemData.xylims = [-2 2 -2 2];
            odeSystemData.tlims = [-10 10];
            addDefaultODESystem(app,odeSystemData)

        end
        %------------------ END LIBRARY MENU FUNCTIONS  ------------------%    
        
        %----------------------- VISUAL FUNCTIONS -----------------------%
        function setUIColors(app,backgroundColor,foregroundColor)
        % Change the main UI colors
            % Change the axes
            app.PhaseplanePanel.BackgroundColor = backgroundColor;
            app.UIAxes_pp.XColor = foregroundColor;
            app.UIAxes_pp.YColor = foregroundColor;
            app.UIAxes_x.XColor = foregroundColor;
            app.UIAxes_x.YColor = foregroundColor;
            app.UIAxes_y.XColor = foregroundColor;
            app.UIAxes_y.YColor = foregroundColor;
            
            % Get the handles for the UI objects
            handles1 = findobj(app.PhaseplanePanel);
            handles2 = findobj(app.TimeseriesPanel);
            handles = [handles1;handles2];
            textHandles = [app.plotTextHandles,app.equilibriaLabels,app.isoclinesLabels];
            
            % Loop through handles and change the appearance
            SharedFunctions.setUIColorFromHandles(backgroundColor,foregroundColor,handles,textHandles);
            
            % Change the default initial value label color
            app.fontColorIP = foregroundColor;
            app.isoTextColor = foregroundColor;
        end
        
        function setAxisMode(app)
            % Switches between axis positions: origin/normal
            if(app.originAxisSwitch)
                app.UIAxes_pp.XAxisLocation = "origin";
                app.UIAxes_pp.YAxisLocation = "origin";
                app.UIAxes_x.XAxisLocation = "origin";
                app.UIAxes_x.YAxisLocation = "origin";
                app.UIAxes_y.XAxisLocation = "origin";
                app.UIAxes_y.YAxisLocation = "origin";
            else
                app.UIAxes_pp.XAxisLocation = "bottom";
                app.UIAxes_pp.YAxisLocation = "left";
                app.UIAxes_x.XAxisLocation = "bottom";
                app.UIAxes_x.YAxisLocation = "left";
                app.UIAxes_y.XAxisLocation = "bottom";
                app.UIAxes_y.YAxisLocation = "left";
            end
        end
        
        function setLineWidth(app)
            % Delete highlights if adjusting line width
            deleteHighlightsCallback(app,[],[]);
            
            % Set the line width
            app.lw = app.SpinnerLineWidth.Value;
            
            % Adjust existing plots
            for idx = 1:numel(app.plotHandlesPP)
                app.plotHandlesPP{idx}.LineWidth = app.lw;
                app.plotHandlesX{idx}.LineWidth = app.lw;
                app.plotHandlesY{idx}.LineWidth = app.lw;
                app.plotMarkerHandles{idx}.LineWidth = app.lw;
            end
                
        end
        
        function setAxisLimits(app,xylims,tlims)
            % Sets the axis limits by calling the callbacks
            app.xminEditField.Value = xylims(1);
            app.xmaxEditField.Value = xylims(2);
            app.yminEditField.Value = xylims(3);
            app.ymaxEditField.Value = xylims(4);
            app.tminEditField.Value = tlims(1);
            app.tmaxEditField.Value = tlims(2);
            
            app.BackwardsolutiontminEditField.Value = tlims(1);
            app.ForwardsolutiontmaxEditField.Value = tlims(2);
            
            app.xminEditFieldValueChanged
            app.xmaxEditFieldValueChanged
            app.yminEditFieldValueChanged
            app.ymaxEditFieldValueChanged
            app.setTimeAxisLimits
        end
        
        function setTimeAxisLimits(app)
            tmin = app.tminEditField.Value;
            tmax = app.tmaxEditField.Value;
            app.UIAxes_y.XLim(1) = tmin;       
            app.UIAxes_x.XLim(1) = tmin;   
            app.UIAxes_y.XLim(2) = tmax;       
            app.UIAxes_x.XLim(2) = tmax;   
        end

        function setVariableLabels(app)     
            try
                % Get variable names
                variables = [string(app.Variable1EditField.Value), string(app.Variable2EditField.Value)];
                
                % Convert to tex
                xvar = texlabel(variables(1));
                yvar = texlabel(variables(2));
                
                % Set variable names on the axis handles (latex is prettier
                % than tex)
                ylabel(app.UIAxes_x,"$" + xvar + "$","Interpreter","Latex","FontSize",app.fsLabels)
                xlabel(app.UIAxes_pp,"$" + xvar + "$","Interpreter","Latex","FontSize",app.fsLabels)
                ylabel(app.UIAxes_y,"$" + yvar +"$","Interpreter","Latex","FontSize",app.fsLabels)
                ylabel(app.UIAxes_pp,"$" + yvar + "$","Interpreter","Latex","FontSize",app.fsLabels)
                
                % These are always the same [but nice to have here]
                xlabel(app.UIAxes_x,"$t$","Interpreter","Latex","FontSize",app.fsLabels)
                xlabel(app.UIAxes_y," ","Interpreter","Latex","FontSize",app.fsLabels)
                
                % Set IV labels
                app.invalidEquation = false;
                app.x0Label.Text = variables(1) + "0 = ";
                app.y0EditFieldLabel.Text = variables(2) + "0 = ";
            catch ME
                message = "Updating axis labels failed due to: "+ newline + ME.message;
                app.displayMessage(message,2)
            end
        end
       
        %---------------------- END VISUAL FUNCTIONS ------------------------%
         
        %---------------------- EXPORT FUNCTIONS -------------------------%
        function figHandle = exportToFigure(app,d)
            vshift = 0;
            try
                % Decide on a background color
                if(app.darkModeSwitch)
                    figColor = app.bgColorDM;
                    textColor = app.textColorDM;
                else
                    figColor = app.bgColorSTD;
                    textColor = app.textColorSTD;
                end
                
                % Position based on the panel
                uiFigPos = app.PhasePlaneUIFigure.Position;
                ppanelPos = app.PhaseplanePanel.InnerPosition;
                tpanelPos = app.TimeseriesPanel.InnerPosition;
                pos = [uiFigPos(1)+50,uiFigPos(2)+50,ppanelPos(3)+tpanelPos(3),ppanelPos(4)+vshift];
      
                % Create a new figure
                figHandle = figure("Visible","off","Color",figColor,"InnerPosition",pos,...
                                 "InvertHardcopy","off");
                d.Value = 0.2; %progress update
    
                % Copy to new figure
                ppHandle = copyobj(app.UIAxes_pp,figHandle);
                ppHandle.Position(2) = ppHandle.Position(2) + vshift;
                title(ppHandle,"Phase portrait","Color",textColor,"Interpreter","latex")
                yhandle = copyobj(app.UIAxes_y,figHandle);
                title(yhandle,"Time series","Color",textColor,"Interpreter","latex")
                xhandle = copyobj(app.UIAxes_x,figHandle);
                xhandle.Position = [xhandle.Position(1)+ppanelPos(3),...
                    xhandle.Position(2)+vshift,xhandle.Position(3:4)];
                yhandle.Position = [yhandle.Position(1)+ppanelPos(3),...
                    yhandle.Position(2)+vshift,yhandle.Position(3:4)];
                
                % Add equations to new figure
                eqnAxisPos = [ppHandle.Position(1),ppHandle.Position(2)-vshift-30,ppHandle.Position(3),40];
                eqnAxis = axes(figHandle,"Units",xhandle.Units);
                eqnAxis.Position = eqnAxisPos;
                axis(eqnAxis,"off")
                axis(eqnAxis,[-1,1,-1,1])
                         
                xvar = texlabel(app.Variable1EditField.Value);
                yvar = texlabel(app.Variable2EditField.Value);
                dxdttex = texlabel(app.EditField_dxdt.Value);
                dydttex = texlabel(app.EditField_dydt.Value);
                
                eqn1String = dxdttex;
                eq1 ="\qquad Equations: \ " + "$" + xvar + "'(t) = " + eqn1String + "$";            
                eqn2String = dydttex;
                eq2 = "$" + yvar + "'(t) = " + eqn2String + "$";
                eq = eq1 + "\qquad" +  eq2;
       
                text(eqnAxis,-1,0,eq,"Interpreter","latex",...
                    "VerticalAlignment","top","HorizontalAlignment","left",...
                    "FontSize",app.figLatexFontsize,"Color",textColor)
                d.Value = 0.4; %progress update
            catch ME
                message = "Export to image file failed because: "...
                      + newline + ME.message;
                app.displayMessage(message,1)
            end
        end 
        
        function figHandle = exportPortraitToFigure(app,d)
            try
                if(app.darkModeSwitch)
                    figColor = app.bgColorDM;
                    textColor = app.textColorDM;
                else
                    figColor = app.bgColorSTD;
                    textColor = app.textColorSTD;
                end
                
                % Position based on the panel
                uiFigPos = app.PhasePlaneUIFigure.Position;
                ppPos = app.UIAxes_pp.Position;
                pos = [uiFigPos(1)+50,uiFigPos(2)+50,ppPos(3)+10,ppPos(4)+10];
      
                % Create a new figure
                figHandle = figure("Visible","off","Color",figColor,"InnerPosition",pos,...
                                 "InvertHardcopy","off");
                d.Value = 0.2; %progress update
    
                % Copy to new figure
                ppHandle = copyobj(app.UIAxes_pp,figHandle);
                currPos = ppHandle.Position;
                ppHandle.Position = [7,6,currPos(3),currPos(4)];
                d.Value = 0.4; %progress update
            catch ME
                message = "Export to image file failed because: "...
                      + newline + ME.message;
                app.displayMessage(message,1)
            end
        end 
        
        function exportToFile(app,fileExt,ppOnly)
            % Exports the plots to an image file
            
            try
               % Request filename
                filename = SharedFunctions.saveImagePopup(fileExt,app.defaultFilename);
                % If none was given, skip this
                if(filename == "")
                    return
                end
                
                % Progress dialogue
                d = uiprogressdlg(app.PhasePlaneUIFigure);
                d.Value = 0.1;

                % Generate export figure
                if(ppOnly)
                   figHandle = app.exportPortraitToFigure(d);
                else
                   figHandle = app.exportToFigure(d);
                end
                drawnow
                d.Value = 0.75; %progress update
                
                % Save figure
                if(fileExt==".pdf") 
                    if(app.darkModeSwitch)
                        figColor = app.bgColorDM;
                    else
                        figColor = app.bgColorSTD;
                    end
                    set(figHandle,'Units','Inches');
                    pos = get(figHandle,'Position');
                    set(figHandle,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])
                    saveas(figHandle,filename)
                else
                    saveas(figHandle,filename)
                end
                close(figHandle)
                
                d.Value = 1; %progress update
                
                % Display message
                message = "Phase portrait saved to " + filename + ".";
                app.displayMessage(message,0)
                
            catch ME
                % Display message
                message = "Error exporting to " + filename + " due to:" + newline + ME.message;
                app.displayMessage(message,1)
            end
        end
        %-------------------- END EXPORT FUNCTIONS -----------------------%
        

        %------------------- CLEAN UP/RESET FUNCTIONS -------------------%
        function resetField(app)
            % Clear solutions
            app.clearSolns
            
            % Clear messages
            message = '';
            app.displayMessage(message,0)
            
            % Clear modes
            app.findEqMode = false;
            
            % Clear equilibria
            app.clearEquilibria
        
            % Clear nullclines menu
            app.ShownullclinesMenu.Checked = false;
            app.nullclinesSwitch = false;
            % Clear nullclines plots
            app.clearNullclines

            % Clear isoclines
            app.clearIsoclines
            
            % Clear axes
            cla(app.UIAxes_pp);
            cla(app.UIAxes_x);
            cla(app.UIAxes_y);
        end
        

        function clearSolns(app)
            
            % Clear highlights
            deleteHighlightsCallback(app,[],[]);
            
            % Delete graphics objects
            SharedFunctions.clear1DGraphicsCellArray(app.plotHandlesPP,app.MessageArea)
            SharedFunctions.clear1DGraphicsCellArray(app.plotMarkerHandles,app.MessageArea)
            SharedFunctions.clear1DGraphicsCellArray(app.plotTextHandles,app.MessageArea)
            SharedFunctions.clear1DGraphicsCellArray(app.plotHandlesX,app.MessageArea)
            SharedFunctions.clear1DGraphicsCellArray(app.plotHandlesY,app.MessageArea)
            
            % Clear handle containers
            app.plotHandlesPP = {};
            app.plotMarkerHandles = {};
            app.plotTextHandles = {};
            app.plotHandlesX = {};
            app.plotHandlesY = {};
        end
      
        function clearEquilibria(app)
            
            % Delete graphics
            for k = 1:numel(app.equilibriaLabels)
                delete(app.equilibriaLabels{k})
            end
            for k = 1:numel(app.equilibriaPlots)
                delete(app.equilibriaPlots{k})
            end    
            for k = 1:size(app.eigenvectorPlots,1)
                for j = 1:size(app.eigenvectorPlots,2)
                    if(~isempty(app.eigenvectorPlots{k,j}))
                        delete(app.eigenvectorPlots{k,j})
                    end
                end
            end

            % Clear handles
            app.equilibriaLabels = {};
            app.equilibriaPlots = {};
            app.equilibria = {};
            app.eigenvectorPlots = {};
            
        end
        
        function clearNullclines(app)
            % Clear the nullclines
            if(~isempty(app.nullclinesPlots))
                delete(app.nullclinesPlots{1})
                delete(app.nullclinesPlots{2})
                app.nullclinesPlots = {};
            end
        end
        
        function clearIsoclines(app)
            % Clear the isocolines
            if(~isempty(app.isoclinesPlots))
                for k = 1:numel(app.isoclinesPlots)
                    delete(app.isoclinesPlots{k})
                end
                app.isoclinesPlots = {};
            end
                  
            % Clear the labels
            if(~isempty(app.isoclinesLabels))
                for k = 1:numel(app.isoclinesLabels)
                    delete(app.isoclinesLabels{k})
                end
                app.isoclinesLabels = {};
            end
        end
        
        function clearSeparatrices(app)
            try
                delete(app.separatricesPlot);
            catch
            end
            app.separatricesPlot = {};
        end
        
        function clearParameters(app)
           app.p1Name.Value = "";
           app.p1Value.Value = "";
           app.p2Name.Value = "";
           app.p2Value.Value = "";
           app.p3Name.Value = "";
           app.p3Value.Value = "";
           app.p4Name.Value = "";
           app.p4Value.Value = "";
           app.p5Name.Value = "";
           app.p5Value.Value = "";
           app.p6Name.Value = "";
           app.p6Value.Value = "";
        end
        
        function clearCustomLibrary(app)
            % Delete existing menus
            for k = 1:numel(app.userOdeMenuHandles)
                delete(app.userOdeMenuHandles{k})                   
            end
            app.userOdeMenuHandles = {};
            app.userODESystems = {};
        end
        
        function updateEquations(app)
            resetField(app);
            app.setEquation
            app.updateArrows
        end
        %----------------- END CLEAN UP/RESET FUNCTIONS -----------------% 
        
    end % end private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Always add the app to the path to avoid errors
            filePathName = split(string(mfilename('fullpath')),filesep);
            filePath = join(filePathName(1:end-1),filesep);
            addpath(filePath);
            
            % Add the tutorials folder
            tutorialsPath = filePath + filesep + app.tutorialsFolder;
            addpath(tutorialsPath);
            
            % Adjust the size of the app
            SharedFunctions.startupDisplaySize(app.PhasePlaneUIFigure,...
                app.minWidth,app.minHeight,app.verticalBuffer)
            
            % Set handle for minimum resize
            app.PhasePlaneUIFigure.SizeChangedFcn = @(src,event) SharedFunctions.resizedCallback...
                (src,event,app.PhasePlaneUIFigure,app.minWidth,app.minHeight);
                        
            % Disable default interactivity
            app.UIAxes_pp.Toolbar.Visible = 'off';
            app.UIAxes_x.Toolbar.Visible = 'off';
            app.UIAxes_y.Toolbar.Visible = 'off';
            
            disableDefaultInteractivity(app.UIAxes_pp);
            disableDefaultInteractivity(app.UIAxes_x);
            disableDefaultInteractivity(app.UIAxes_y);
                        
            % Set the motion callback
            set(app.PhasePlaneUIFigure,"WindowButtonMotionFcn",@app.windowMotion)
            set(app.PhasePlaneUIFigure,"WindowButtonDownFcn",@app.windowButtonDown)
            
            % Set switches to selected UI defaults
            app.animateSolnSwitch = app.AnimatesolutionMenu.Checked;
            app.pointLabelsSwitch = app.PointlabelsMenu.Checked;
            app.originAxisSwitch = app.AxisthroughoriginMenu.Checked;
            app.darkModeSwitch = app.DarkmodeMenu.Checked;
            app.orientationOnlySwitch = app.FieldorientationonlyMenu.Checked;           
            app.showEigenvectorsSwitch = app.ShowrealeigenvectorsMenu.Checked;
            
            % Set the equations
            app.setEquation
            
            % Set the axes to the starting values
            app.UIAxes_y.XLim = [app.tminEditField.Value app.tmaxEditField.Value];
            app.UIAxes_x.XLim = [app.tminEditField.Value app.tmaxEditField.Value];
            app.UIAxes_y.YLim = [app.yminEditField.Value app.ymaxEditField.Value];
            app.UIAxes_x.YLim = [app.xminEditField.Value app.xmaxEditField.Value];
            app.UIAxes_pp.XLim = [app.xminEditField.Value app.xmaxEditField.Value];
            app.UIAxes_pp.YLim = [app.yminEditField.Value app.ymaxEditField.Value];
 
            % Set the axis mode
            app.setAxisMode
            
            % Set line widths
            app.setLineWidth
            
            % Set solver type
            app.SolvertypeButtonGroupSelectionChanged([])
   

            % Add default ODE systems
            app.createDefaultODEMenus
            
            % Select default menu by evaluating the callback for the first
            % ode menu (no source or event)
            feval(app.defaultOdeMenuHandles{1}.MenuSelectedFcn{1},...
                [],[],app.defaultOdeMenuHandles{1}.MenuSelectedFcn{2})
            
            % ---> these should be done last --->
            % Update field arrows
            app.updateArrows
            
            % Turn on the help panel
            app.StartPanel.Visible = true;
            
            % Turn on the axes
            pause(0.1)
            drawnow
            set(app.UIAxes_pp,"Visible","on")
            set(app.UIAxes_x,"Visible","on")
            set(app.UIAxes_y,"Visible","on")
           
            
        end

        % Close request function: PhasePlaneUIFigure
        function PhasePlaneUIFigureCloseRequest(app, event)
            % Required: delete app
            delete(app)
        end

        % Callback function
        function FieldDensitySliderValueChanged(app, event)
            app.updateArrows
        end

        % Value changed function: ClearsolutionsButton
        function ClearsolutionsButtonValueChanged(app, event)
            app.clearSolns
        end

        % Value changed function: tminEditField
        function tminEditFieldValueChanged(app, event)
            value = app.tminEditField.Value;
            if(value > 0 )
                message = 'Lower t bound must be less than or equal to 0.';
                app.displayMessage(message,2)
                app.tminEditField.Value = app.UIAxes_y.XLim(1);
            else                 
                app.setTimeAxisLimits 
                app.tAxisMinPopup(event)
            end
            
        end

        % Value changed function: tmaxEditField
        function tmaxEditFieldValueChanged(app, event)
            value = app.tmaxEditField.Value;
            if(value <= 0)
                message = 'Upper t bound must be greater than 0.';
                app.displayMessage(message,2)
                app.tmaxEditField.Value = app.UIAxes_y.XLim(2);
            else                 
                app.setTimeAxisLimits 
                app.tAxisMaxPopup(event)
            end
        end

        % Value changed function: xminEditField
        function xminEditFieldValueChanged(app, event)
            value = app.xminEditField.Value;
            if(value >= app.UIAxes_pp.XLim(2))
                message = 'Lower x bound must be less than upper x bound.';
                app.displayMessage(message,2)
                app.xminEditField.Value = app.UIAxes_pp.XLim(1);
            else
                app.UIAxes_pp.XLim(1) = value;
                app.UIAxes_x.YLim(1) = value;
            end
            app.updateArrows
        end

        % Value changed function: xmaxEditField
        function xmaxEditFieldValueChanged(app, event)
            value = app.xmaxEditField.Value;
            if(value <= app.UIAxes_pp.XLim(1))
                message = 'Upper x bound must be greater than lower x bound.';
                app.displayMessage(message,2)
                app.xmaxEditField.Value = app.UIAxes_pp.XLim(2);
            else
                app.UIAxes_pp.XLim(2) = value;
                app.UIAxes_x.YLim(2) = value;
            end
            app.updateArrows
        end

        % Value changed function: yminEditField
        function yminEditFieldValueChanged(app, event)
            value = app.yminEditField.Value;
            if(value >= app.UIAxes_pp.YLim(2))
                message = 'Lower y bound must be less than upper y bound.';
                app.displayMessage(message,2)
                app.yminEditField.Value = app.UIAxes_pp.YLim(1);
            else
                app.UIAxes_pp.YLim(1) = value;
                app.UIAxes_y.YLim(1) = value;       
            end            
            app.updateArrows
        end

        % Value changed function: ymaxEditField
        function ymaxEditFieldValueChanged(app, event)
            value = app.ymaxEditField.Value;
            if(value <= app.UIAxes_pp.YLim(1))
                message = 'Upper y bound must be greater than lower y bound.';
                app.displayMessage(message,2)
                app.ymaxEditField.Value = app.UIAxes_pp.YLim(2);
            else
                app.UIAxes_pp.YLim(2) = value;
                app.UIAxes_y.YLim(2) = value;
            end            
            app.updateArrows
        end

        % Value changed function: UpdateButton
        function UpdateButtonValueChanged(app, event)
            app.updateEquations
        end

        % Value changed function: FieldDensityUI
        function FieldDensityUIValueChanged(app, event)
            app.updateArrows
        end

        % Value changed function: FieldScaleUI
        function FieldScaleUIValueChanged(app, event)
            app.updateArrows
        end

        % Value changed function: Variable1EditField
        function Variable1EditFieldValueChanged(app, event)
            value = app.Variable1EditField.Value;
            app.Variable1EditField.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: Variable2EditField
        function Variable2EditFieldValueChanged(app, event)
            value = app.Variable2EditField.Value;
            app.Variable2EditField.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Button pushed function: Solvefromx0y0Button
        function Solvefromx0y0ButtonPushed(app, event)
            app.solveAndPlotFromInitialValue
        end

        % Value changed function: p1Name
        function p1NameValueChanged(app, event)
            value = app.p1Name.Value;
            app.p1Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p2Name
        function p2NameValueChanged(app, event)
            value = app.p2Name.Value;
            app.p2Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p3Name
        function p3NameValueChanged(app, event)
            value = app.p3Name.Value;
            app.p3Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p4Name
        function p4NameValueChanged(app, event)
            value = app.p4Name.Value;
            app.p4Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p5Name
        function p5NameValueChanged(app, event)
            value = app.p5Name.Value;
            app.p5Name.Value = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p6Name
        function p6NameValueChanged(app, event)
            value = app.p6Name.Value;
            app.p6Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p1Value
        function p1ValueValueChanged(app, event)
            value = app.p1Value.Value;
            app.p1Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: p2Value
        function p2ValueValueChanged(app, event)
            value = app.p2Value.Value;
            app.p2Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: p3Value
        function p3ValueValueChanged(app, event)
            value = app.p3Value.Value;
            app.p3Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: p4Value
        function p4ValueValueChanged(app, event)
            value = app.p4Value.Value;
            app.p4Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: p5Value
        function p5ValueValueChanged(app, event)
            value = app.p5Value.Value;
            app.p5Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: p6Value
        function p6ValueValueChanged(app, event)
            value = app.p6Value.Value;
            app.p6Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: SpinnerLineWidth
        function SpinnerLineWidthValueChanged(app, event)
            app.setLineWidth
        end

        % Menu selected function: FindnearbyequilibriumMenu
        function FindnearbyequilibriumMenuSelected(app, event)
            app.findEqMode = true;
            message = "EQUILIBRIUM MODE:" +newline+ "Select an initial point near the suspected equilibrium.";
            app.displayMessage(message,1)
        end

        % Menu selected function: ScanforequilibriaMenu
        function ScanforequilibriaMenuSelected(app, event)
            app.scanForEquilibria
        end

        % Menu selected function: ClearequilibriaMenu
        function ClearequilibriaMenuSelected(app, event)
            clearEquilibria(app)
        end

        % Menu selected function: AnimatesolutionMenu
        function AnimatesolutionMenuSelected(app, event)
            if(app.AnimatesolutionMenu.Checked)
                app.AnimatesolutionMenu.Checked = false;
                app.animateSolnSwitch = false;
            else
                app.AnimatesolutionMenu.Checked = true;
                app.animateSolnSwitch = true;
            end
        end

        % Menu selected function: PointlabelsMenu
        function PointlabelsMenuSelected(app, event)
            if(app.PointlabelsMenu.Checked)
                app.PointlabelsMenu.Checked = false;
                app.pointLabelsSwitch = false;
            else
                app.PointlabelsMenu.Checked = true;
                app.pointLabelsSwitch = true;
            end
            app.toggleLabels
        end

        % Menu selected function: AxisthroughoriginMenu
        function AxisthroughoriginMenuSelected(app, event)
            if(app.AxisthroughoriginMenu.Checked)
                app.AxisthroughoriginMenu.Checked = false;
                app.originAxisSwitch = false;
            else
                app.AxisthroughoriginMenu.Checked = true;
                app.originAxisSwitch = true;
            end
            app.setAxisMode
        end

        % Menu selected function: SolveforsaddleseparatricesMenu
        function SolveforsaddleseparatricesMenuSelected(app, event)
            app.clearSeparatrices
            app.solveForSaddleSeparatrices
        end

        % Menu selected function: DarkmodeMenu
        function DarkmodeMenuSelected(app, event)
            if(app.DarkmodeMenu.Checked)
                app.DarkmodeMenu.Checked = false;
                app.darkModeSwitch = false;
                setUIColors(app,app.bgColorSTD,app.textColorSTD)
            else
                app.DarkmodeMenu.Checked = true;
                app.darkModeSwitch = true;
                setUIColors(app,app.bgColorDM,app.textColorDM)
            end
        end

        % Menu selected function: FieldorientationonlyMenu
        function FieldorientationonlyMenuSelected(app, event)
            if(app.FieldorientationonlyMenu.Checked)
                app.FieldorientationonlyMenu.Checked = false;
                app.orientationOnlySwitch = false;
            else
                app.FieldorientationonlyMenu.Checked = true;
                app.orientationOnlySwitch = true;
            end
            app.updateArrows
        end

        % Menu selected function: ShowrealeigenvectorsMenu
        function ShowrealeigenvectorsMenuSelected(app, event)
            if(app.ShowrealeigenvectorsMenu.Checked)
                app.ShowrealeigenvectorsMenu.Checked = false;
                app.showEigenvectorsSwitch = false;
            else
                app.ShowrealeigenvectorsMenu.Checked = true;
                app.showEigenvectorsSwitch = true;
            end
            app.toggleEigenvectors
        end

        % Menu selected function: ClearseparatricesMenu
        function ClearseparatricesMenuSelected(app, event)
            app.clearSeparatrices
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.clearParameters
            app.Variable1EditField.Value = "";
            app.Variable2EditField.Value = "";
            app.EditField_dxdt.Value = "";
            app.EditField_dydt.Value = "";
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            % Select default menu by evaluating the callback for the first
            % ode menu (no source or event)
            feval(app.defaultOdeMenuHandles{1}.MenuSelectedFcn{1},...
                [],[],app.defaultOdeMenuHandles{1}.MenuSelectedFcn{2})
        end

        % Menu selected function: ShownullclinesMenu
        function ShownullclinesMenuSelected(app, event)
            if(app.ShownullclinesMenu.Checked)
                app.ShownullclinesMenu.Checked = false;
                app.nullclinesSwitch = false;
            else
                app.ShownullclinesMenu.Checked = true;
                app.nullclinesSwitch = true;
            end
            app.updateNullclines
        end

        % Menu selected function: ExporttoPNGMenu
        function ExporttoPNGMenuSelected(app, event)
            app.exportToFile(".png",false)
        end

        % Menu selected function: ExporttoSVGMenu
        function ExporttoSVGMenuSelected(app, event)
            app.exportToFile(".svg",false)
        end

        % Menu selected function: ExporttoPDFMenu
        function ExporttoPDFMenuSelected(app, event)
            app.exportToFile(".pdf",false)
        end

        % Menu selected function: ExportportraitonlytoPNGMenu
        function ExportportraitonlytoPNGMenuSelected(app, event)
            app.exportToFile(".png",true)
        end

        % Menu selected function: ExportportraitonlytoSVGMenu
        function ExportportraitonlytoSVGMenuSelected(app, event)
            app.exportToFile(".svg",true)
        end

        % Menu selected function: ExportportraitonlytoPDFMenu
        function ExportportraitonlytoPDFMenuSelected(app, event)
            app.exportToFile(".pdf",true)
        end

        % Menu selected function: AddcurrentsystemMenu
        function AddcurrentsystemMenuSelected(app, event)
            app.addUserODESystem
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.saveLibrary
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.loadLibrary
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            app.clearCustomLibraryRequest
        end

        % Button pushed function: SkipanimationButton
        function SkipanimationButtonPushed(app, event)
            app.skipAnimationFlag = true;
        end

        % Value changed function: TimeseriesCheckBox
        function TimeseriesCheckBoxValueChanged(app, event)
            value = app.TimeseriesCheckBox.Value;
            if(value)
                app.TimeseriesPanel.Visible = true;
                app.PhaseplanePanel.Layout.Column = [1,2];
            else
                app.TimeseriesPanel.Visible = false;
                app.PhaseplanePanel.Layout.Column = [1,4];
            end

        end

        % Selection changed function: SolvertypeButtonGroup
        function SolvertypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SolvertypeButtonGroup.SelectedObject;
            if(selectedButton.Text == "Fixed step")
                app.VariablesolveroptionsPanel.Visible = false;
                app.FixedsolveroptionsPanel.Visible = true;
                app.solverType = "fixed";
            else
                app.VariablesolveroptionsPanel.Visible = true;
                app.FixedsolveroptionsPanel.Visible = false;
                app.solverType = "variable";
            end
        end

        % Menu selected function: SolvefromregionMenu
        function SolvefromregionMenuSelected(app, event)
            app.solveFromRegion
        end

        % Menu selected function: AutogenerateisoclinesMenu
        function AutogenerateisoclinesMenuSelected(app, event)
            app.autoGenerateIsoclines
        end

        % Menu selected function: DrawisoclinethroughapointMenu
        function DrawisoclinethroughapointMenuSelected(app, event)
            app.singleIsoclineMode = true;
            message = "Click on the phase plane to draw an isocline.";
            app.displayMessage(message,-1)
        end

        % Menu selected function: DrawseveralisoclinesMenu
        function DrawseveralisoclinesMenuSelected(app, event)
            app.generateIsoclinesFromVector
        end

        % Menu selected function: ClearisoclinesMenu
        function ClearisoclinesMenuSelected(app, event)
            app.clearIsoclines
        end

        % Menu selected function: DrawsolutionMenu
        function DrawsolutionMenuSelected(app, event)
            app.drawSolution(false)
        end

        % Menu selected function: DrawandcomparesolutionMenu
        function DrawandcomparesolutionMenuSelected(app, event)
            app.drawSolution(true)
        end

        % Callback function: Hyperlink3
        function Hyperlink3Clicked(app, event)
            % Click the x to remove the start panel
            app.StartPanel.Visible = false;
        end

        % Callback function: Hyperlink1
        function Hyperlink1Clicked(app, event)
            SharedFunctions.winopenFile(app.videoFileLocation,app.MessageArea,app.StartPanel)
        end

        % Callback function: Hyperlink2
        function Hyperlink2Clicked(app, event)
            SharedFunctions.winopenFile(app.quickStartLocation,app.MessageArea,app.StartPanel)
        end

        % Menu selected function: VideotutorialMenu
        function VideotutorialMenuSelected(app, event)
            SharedFunctions.winopenFile(app.videoFileLocation,app.MessageArea,app.StartPanel)
        end

        % Menu selected function: QuickstartguideMenu
        function QuickstartguideMenuSelected(app, event)
            SharedFunctions.winopenFile(app.quickStartLocation,app.MessageArea,app.StartPanel)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhasePlaneUIFigure = uifigure('Visible', 'off');
            app.PhasePlaneUIFigure.Name = 'Phase Plane';
            app.PhasePlaneUIFigure.CloseRequestFcn = createCallbackFcn(app, @PhasePlaneUIFigureCloseRequest, true);
            app.AnalysisMenu = uimenu(app.PhasePlaneUIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.FindnearbyequilibriumMenu = uimenu(app.AnalysisMenu);
            app.FindnearbyequilibriumMenu.MenuSelectedFcn = createCallbackFcn(app, @FindnearbyequilibriumMenuSelected, true);
            app.FindnearbyequilibriumMenu.Text = 'Find nearby equilibrium';
            app.ScanforequilibriaMenu = uimenu(app.AnalysisMenu);
            app.ScanforequilibriaMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanforequilibriaMenuSelected, true);
            app.ScanforequilibriaMenu.Text = 'Scan for equilibria';
            app.ClearequilibriaMenu = uimenu(app.AnalysisMenu);
            app.ClearequilibriaMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearequilibriaMenuSelected, true);
            app.ClearequilibriaMenu.Text = 'Clear equilibria';
            app.ShowrealeigenvectorsMenu = uimenu(app.AnalysisMenu);
            app.ShowrealeigenvectorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowrealeigenvectorsMenuSelected, true);
            app.ShowrealeigenvectorsMenu.Checked = 'on';
            app.ShowrealeigenvectorsMenu.Text = 'Show real eigenvectors';
            app.SolveforsaddleseparatricesMenu = uimenu(app.AnalysisMenu);
            app.SolveforsaddleseparatricesMenu.MenuSelectedFcn = createCallbackFcn(app, @SolveforsaddleseparatricesMenuSelected, true);
            app.SolveforsaddleseparatricesMenu.Separator = 'on';
            app.SolveforsaddleseparatricesMenu.Text = 'Solve for saddle separatrices';
            app.ClearseparatricesMenu = uimenu(app.AnalysisMenu);
            app.ClearseparatricesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearseparatricesMenuSelected, true);
            app.ClearseparatricesMenu.Text = 'Clear separatrices';
            app.ShownullclinesMenu = uimenu(app.AnalysisMenu);
            app.ShownullclinesMenu.MenuSelectedFcn = createCallbackFcn(app, @ShownullclinesMenuSelected, true);
            app.ShownullclinesMenu.Separator = 'on';
            app.ShownullclinesMenu.Text = 'Show nullclines';
            app.AutogenerateisoclinesMenu = uimenu(app.AnalysisMenu);
            app.AutogenerateisoclinesMenu.MenuSelectedFcn = createCallbackFcn(app, @AutogenerateisoclinesMenuSelected, true);
            app.AutogenerateisoclinesMenu.Separator = 'on';
            app.AutogenerateisoclinesMenu.Text = 'Auto-generate isoclines';
            app.DrawisoclinethroughapointMenu = uimenu(app.AnalysisMenu);
            app.DrawisoclinethroughapointMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawisoclinethroughapointMenuSelected, true);
            app.DrawisoclinethroughapointMenu.Text = 'Draw isocline through a point';
            app.DrawseveralisoclinesMenu = uimenu(app.AnalysisMenu);
            app.DrawseveralisoclinesMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawseveralisoclinesMenuSelected, true);
            app.DrawseveralisoclinesMenu.Text = 'Draw several isoclines';
            app.ClearisoclinesMenu = uimenu(app.AnalysisMenu);
            app.ClearisoclinesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearisoclinesMenuSelected, true);
            app.ClearisoclinesMenu.Text = 'Clear isoclines';
            app.SolveMenu = uimenu(app.PhasePlaneUIFigure);
            app.SolveMenu.Text = 'Solve';
            app.SolvefromregionMenu = uimenu(app.SolveMenu);
            app.SolvefromregionMenu.MenuSelectedFcn = createCallbackFcn(app, @SolvefromregionMenuSelected, true);
            app.SolvefromregionMenu.Text = 'Solve from region';
            app.DrawMenu = uimenu(app.PhasePlaneUIFigure);
            app.DrawMenu.Text = 'Draw';
            app.DrawsolutionMenu = uimenu(app.DrawMenu);
            app.DrawsolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawsolutionMenuSelected, true);
            app.DrawsolutionMenu.Text = 'Draw solution';
            app.DrawandcomparesolutionMenu = uimenu(app.DrawMenu);
            app.DrawandcomparesolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawandcomparesolutionMenuSelected, true);
            app.DrawandcomparesolutionMenu.Text = 'Draw and compare solution';
            app.AppearanceMenu = uimenu(app.PhasePlaneUIFigure);
            app.AppearanceMenu.Text = 'Appearance';
            app.AnimatesolutionMenu = uimenu(app.AppearanceMenu);
            app.AnimatesolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @AnimatesolutionMenuSelected, true);
            app.AnimatesolutionMenu.Checked = 'on';
            app.AnimatesolutionMenu.Text = 'Animate solution';
            app.PointlabelsMenu = uimenu(app.AppearanceMenu);
            app.PointlabelsMenu.MenuSelectedFcn = createCallbackFcn(app, @PointlabelsMenuSelected, true);
            app.PointlabelsMenu.Text = 'Point labels';
            app.AxisthroughoriginMenu = uimenu(app.AppearanceMenu);
            app.AxisthroughoriginMenu.MenuSelectedFcn = createCallbackFcn(app, @AxisthroughoriginMenuSelected, true);
            app.AxisthroughoriginMenu.Text = 'Axis through origin';
            app.DarkmodeMenu = uimenu(app.AppearanceMenu);
            app.DarkmodeMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkmodeMenuSelected, true);
            app.DarkmodeMenu.Text = 'Dark mode';
            app.FieldorientationonlyMenu = uimenu(app.AppearanceMenu);
            app.FieldorientationonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @FieldorientationonlyMenuSelected, true);
            app.FieldorientationonlyMenu.Text = 'Field orientation only';
            app.LibraryMenu = uimenu(app.PhasePlaneUIFigure);
            app.LibraryMenu.Text = 'Library';
            app.CustomlibraryMenu = uimenu(app.PhasePlaneUIFigure);
            app.CustomlibraryMenu.Text = 'Custom library';
            app.SaveMenu = uimenu(app.CustomlibraryMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.CustomlibraryMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ClearMenu = uimenu(app.CustomlibraryMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.AddcurrentsystemMenu = uimenu(app.CustomlibraryMenu);
            app.AddcurrentsystemMenu.MenuSelectedFcn = createCallbackFcn(app, @AddcurrentsystemMenuSelected, true);
            app.AddcurrentsystemMenu.Separator = 'on';
            app.AddcurrentsystemMenu.Text = 'Add current system';
            app.ExportgraphicsMenu = uimenu(app.PhasePlaneUIFigure);
            app.ExportgraphicsMenu.Text = 'Export graphics';
            app.ExporttoPNGMenu = uimenu(app.ExportgraphicsMenu);
            app.ExporttoPNGMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoPNGMenuSelected, true);
            app.ExporttoPNGMenu.Text = 'Export to PNG';
            app.ExporttoSVGMenu = uimenu(app.ExportgraphicsMenu);
            app.ExporttoSVGMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoSVGMenuSelected, true);
            app.ExporttoSVGMenu.Text = 'Export to SVG';
            app.ExporttoPDFMenu = uimenu(app.ExportgraphicsMenu);
            app.ExporttoPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoPDFMenuSelected, true);
            app.ExporttoPDFMenu.Text = 'Export to PDF';
            app.ExportportraitonlytoPNGMenu = uimenu(app.ExportgraphicsMenu);
            app.ExportportraitonlytoPNGMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportportraitonlytoPNGMenuSelected, true);
            app.ExportportraitonlytoPNGMenu.Text = 'Export portrait only to PNG';
            app.ExportportraitonlytoSVGMenu = uimenu(app.ExportgraphicsMenu);
            app.ExportportraitonlytoSVGMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportportraitonlytoSVGMenuSelected, true);
            app.ExportportraitonlytoSVGMenu.Text = 'Export portrait only to SVG';
            app.ExportportraitonlytoPDFMenu = uimenu(app.ExportgraphicsMenu);
            app.ExportportraitonlytoPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportportraitonlytoPDFMenuSelected, true);
            app.ExportportraitonlytoPDFMenu.Text = 'Export portrait only to PDF';
            app.HelpMenu = uimenu(app.PhasePlaneUIFigure);
            app.HelpMenu.Text = 'Help';
            app.VideotutorialMenu = uimenu(app.HelpMenu);
            app.VideotutorialMenu.MenuSelectedFcn = createCallbackFcn(app, @VideotutorialMenuSelected, true);
            app.VideotutorialMenu.Text = 'Video tutorial';
            app.QuickstartguideMenu = uimenu(app.HelpMenu);
            app.QuickstartguideMenu.MenuSelectedFcn = createCallbackFcn(app, @QuickstartguideMenuSelected, true);
            app.QuickstartguideMenu.Text = 'Quick start guide';
            app.GridLayout2 = uigridlayout(app.PhasePlaneUIFigure);
            app.PhaseplanePanel = uipanel(app.GridLayout2);
            app.PhaseplanePanel.Title = 'Phase plane';
            app.GridLayout = uigridlayout(app.PhaseplanePanel);
            app.UIAxes_pp = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_pp, 'x')
            ylabel(app.UIAxes_pp, 'y')
            app.UIAxes_pp.XLim = [0 10];
            app.UIAxes_pp.YLim = [0 10];
            app.UIAxes_pp.XTick = [0 2.5 5 7.5 10];
            app.UIAxes_pp.XTickLabel = {'0'; '2.5'; '5'; '7.5'; '10'};
            app.UIAxes_pp.YTick = [0 2.5 5 7.5 10];
            app.UIAxes_pp.YTickLabel = {'0'; '2.5'; '5'; '7.5'; '10'};
            app.UIAxes_pp.Visible = 'off';
            app.x0EditField = uieditfield(app.GridLayout, 'numeric');
            app.x0EditField.Value = 2;
            app.x0Label = uilabel(app.GridLayout);
            app.x0Label.Text = 'x0 = ';
            app.y0EditField = uieditfield(app.GridLayout, 'numeric');
            app.y0EditField.Value = -2;
            app.Solvefromx0y0Button = uibutton(app.GridLayout, 'push');
            app.Solvefromx0y0Button.ButtonPushedFcn = createCallbackFcn(app, @Solvefromx0y0ButtonPushed, true);
            app.Solvefromx0y0Button.Text = 'Solve from (x0,y0)';
            app.y0EditFieldLabel = uilabel(app.GridLayout);
            app.y0EditFieldLabel.Text = 'y0 = ';
            app.ClearsolutionsButton = uibutton(app.GridLayout, 'state');
            app.ClearsolutionsButton.ValueChangedFcn = createCallbackFcn(app, @ClearsolutionsButtonValueChanged, true);
            app.ClearsolutionsButton.Tooltip = {''};
            app.ClearsolutionsButton.Text = 'Clear solutions';
            app.SkipanimationButton = uibutton(app.GridLayout, 'push');
            app.SkipanimationButton.ButtonPushedFcn = createCallbackFcn(app, @SkipanimationButtonPushed, true);
            app.SkipanimationButton.Visible = 'off';
            app.SkipanimationButton.Text = 'Skip animation';
            app.PositionLabel = uilabel(app.GridLayout);
            app.PositionLabel.Text = '-';
            app.TimeseriesPanel = uipanel(app.GridLayout2);
            app.TimeseriesPanel.Title = 'Time series';
            app.GridLayout3 = uigridlayout(app.TimeseriesPanel);
            app.UIAxes_y = uiaxes(app.GridLayout3);
            ylabel(app.UIAxes_y, 'y')
            app.UIAxes_y.XLim = [0 1];
            app.UIAxes_y.YLim = [0 10];
            app.UIAxes_y.Visible = 'off';
            app.UIAxes_x = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_x, 't')
            ylabel(app.UIAxes_x, 'y')
            app.UIAxes_x.XLim = [0 1];
            app.UIAxes_x.YLim = [0 10];
            app.UIAxes_x.Visible = 'off';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.AppearanceTab = uitab(app.TabGroup);
            app.AppearanceTab.Title = 'Appearance';
            app.xminEditFieldLabel = uilabel(app.AppearanceTab);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @xminEditFieldValueChanged, true);
            app.xminEditField.Value = -2;
            app.xmaxEditFieldLabel = uilabel(app.AppearanceTab);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxEditFieldValueChanged, true);
            app.xmaxEditField.Value = 4;
            app.yminEditFieldLabel = uilabel(app.AppearanceTab);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @yminEditFieldValueChanged, true);
            app.yminEditField.Value = -2;
            app.ymaxEditFieldLabel = uilabel(app.AppearanceTab);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @ymaxEditFieldValueChanged, true);
            app.ymaxEditField.Value = 4;
            app.tminEditFieldLabel = uilabel(app.AppearanceTab);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tminEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @tminEditFieldValueChanged, true);
            app.tminEditField.Value = -2;
            app.tmaxEditFieldLabel = uilabel(app.AppearanceTab);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @tmaxEditFieldValueChanged, true);
            app.tmaxEditField.Value = 4;
            app.TimeseriesCheckBox = uicheckbox(app.AppearanceTab);
            app.TimeseriesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeseriesCheckBoxValueChanged, true);
            app.TimeseriesCheckBox.Text = 'Time series';
            app.TimeseriesCheckBox.Value = true;
            app.SpinnerLineWidth = uispinner(app.AppearanceTab);
            app.SpinnerLineWidth.Step = 0.25;
            app.SpinnerLineWidth.Limits = [0.5 3];
            app.SpinnerLineWidth.ValueChangedFcn = createCallbackFcn(app, @SpinnerLineWidthValueChanged, true);
            app.SpinnerLineWidth.Value = 1.5;
            app.AnimationspeedLabel = uilabel(app.AppearanceTab);
            app.AnimationspeedLabel.Text = 'Animation speed';
            app.FieldDensityUI = uispinner(app.AppearanceTab);
            app.FieldDensityUI.Step = 2;
            app.FieldDensityUI.Limits = [2 32];
            app.FieldDensityUI.ValueChangedFcn = createCallbackFcn(app, @FieldDensityUIValueChanged, true);
            app.FieldDensityUI.Value = 20;
            app.FielddensitySpinnerLabel = uilabel(app.AppearanceTab);
            app.FielddensitySpinnerLabel.Text = 'Field density';
            app.FieldscaleSpinnerLabel = uilabel(app.AppearanceTab);
            app.FieldscaleSpinnerLabel.Text = 'Field scale';
            app.FieldScaleUI = uispinner(app.AppearanceTab);
            app.FieldScaleUI.Step = 0.2;
            app.FieldScaleUI.Limits = [0.1 2];
            app.FieldScaleUI.ValueChangedFcn = createCallbackFcn(app, @FieldScaleUIValueChanged, true);
            app.FieldScaleUI.Value = 1;
            app.LinewidthLabel = uilabel(app.AppearanceTab);
            app.LinewidthLabel.Text = 'Line width';
            app.AnimationspeedSlider = uislider(app.AppearanceTab);
            app.AnimationspeedSlider.Limits = [1 40];
            app.AnimationspeedSlider.MajorTicks = [5 36];
            app.AnimationspeedSlider.MajorTickLabels = {'slower', 'faster'};
            app.AnimationspeedSlider.MinorTicks = [];
            app.AnimationspeedSlider.Value = 10;
            app.AxislimitsLabel = uilabel(app.AppearanceTab);
            app.AxislimitsLabel.Text = 'Axis limits';
            app.SolutionTab = uitab(app.TabGroup);
            app.SolutionTab.Title = 'Solution';
            app.ForwardsolutiontmaxEditFieldLabel = uilabel(app.SolutionTab);
            app.ForwardsolutiontmaxEditFieldLabel.Text = 'Forward solution tmax:';
            app.ForwardsolutiontmaxEditField = uieditfield(app.SolutionTab, 'numeric');
            app.ForwardsolutiontmaxEditField.Value = 4;
            app.BackwardsolutiontminEditFieldLabel = uilabel(app.SolutionTab);
            app.BackwardsolutiontminEditFieldLabel.Text = 'Backward solution tmin:';
            app.BackwardsolutiontminEditField = uieditfield(app.SolutionTab, 'numeric');
            app.BackwardsolutiontminEditField.Value = -2;
            app.TerminatesolutionsataxislimitsCheckBox = uicheckbox(app.SolutionTab);
            app.TerminatesolutionsataxislimitsCheckBox.Text = 'Terminate solutions at axis limits';
            app.TerminatesolutionsataxislimitsCheckBox.Value = true;
            app.SolvefromregiondensitySliderLabel = uilabel(app.SolutionTab);
            app.SolvefromregiondensitySliderLabel.Text = 'Solve from region density';
            app.SolvefromregiondensitySlider = uislider(app.SolutionTab);
            app.SolvefromregiondensitySlider.Limits = [5 50];
            app.SolvefromregiondensitySlider.MajorTicks = [10 40];
            app.SolvefromregiondensitySlider.MajorTickLabels = {'Less dense', 'more dense'};
            app.SolvefromregiondensitySlider.MinorTicks = [];
            app.SolvefromregiondensitySlider.Value = 20;
            app.SolverTab = uitab(app.TabGroup);
            app.SolverTab.Title = 'Solver';
            app.SolvertypeButtonGroup = uibuttongroup(app.SolverTab);
            app.SolvertypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolvertypeButtonGroupSelectionChanged, true);
            app.SolvertypeButtonGroup.Title = 'Solver type';
            app.VariablestepButton = uitogglebutton(app.SolvertypeButtonGroup);
            app.VariablestepButton.Text = 'Variable step';
            app.VariablestepButton.Value = true;
            app.FixedstepButton = uitogglebutton(app.SolvertypeButtonGroup);
            app.FixedstepButton.Text = 'Fixed step';
            app.FixedsolveroptionsPanel = uipanel(app.SolverTab);
            app.FixedsolveroptionsPanel.Title = 'Fixed solver options';
            app.SolverLabel = uilabel(app.FixedsolveroptionsPanel);
            app.SolverLabel.Text = 'Solver';
            app.FixedSolverDropDown = uidropdown(app.FixedsolveroptionsPanel);
            app.FixedSolverDropDown.Items = {'Forward Euler', 'RK2', 'RK4', 'Backward Euler', 'Trapezoid'};
            app.FixedSolverDropDown.Value = 'Forward Euler';
            app.StepsizeLabel = uilabel(app.FixedsolveroptionsPanel);
            app.StepsizeLabel.Text = 'Step size:';
            app.StepsizeEditField = uieditfield(app.FixedsolveroptionsPanel, 'numeric');
            app.StepsizeEditField.Value = 0.01;
            app.NoteimplicitmethodsareestimatedLabel = uilabel(app.FixedsolveroptionsPanel);
            app.NoteimplicitmethodsareestimatedLabel.Text = {'Fixed solver notes:'; '• fixed step solvers will fail without warning'; '• implicit methods are approximated using Newton''s method with a finite difference estimate for the Jacobian'};
            app.VariablesolveroptionsPanel = uipanel(app.SolverTab);
            app.VariablesolveroptionsPanel.Title = 'Variable solver options';
            app.RelativetoleranceLabel = uilabel(app.VariablesolveroptionsPanel);
            app.RelativetoleranceLabel.Text = 'Relative tolerance';
            app.RelativetoleranceEditField = uieditfield(app.VariablesolveroptionsPanel, 'numeric');
            app.RelativetoleranceEditField.Value = 1e-06;
            app.AbsolutetoleranceLabel = uilabel(app.VariablesolveroptionsPanel);
            app.AbsolutetoleranceLabel.Text = 'Absolute tolerance';
            app.AbsolutetoleranceEditField = uieditfield(app.VariablesolveroptionsPanel, 'numeric');
            app.AbsolutetoleranceEditField.Value = 1e-06;
            app.SolverDropDownLabel = uilabel(app.VariablesolveroptionsPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.VariablesolveroptionsPanel);
            app.SolverDropDown.Items = {'ode45', 'ode23', 'ode113', 'ode15s', 'ode23s', 'ode23t', 'ode23tb', 'ode15i'};
            app.SolverDropDown.Value = 'ode45';
            app.MaxsolverwallclocksEditFieldLabel = uilabel(app.SolverTab);
            app.MaxsolverwallclocksEditFieldLabel.Text = 'Max solver wall clock (s):';
            app.MaxsolverwallclocksEditField = uieditfield(app.SolverTab, 'numeric');
            app.MaxsolverwallclocksEditField.Limits = [0 100];
            app.MaxsolverwallclocksEditField.Value = 1;
            app.DifferentialequationsystemPanel = uipanel(app.GridLayout2);
            app.DifferentialequationsystemPanel.Title = 'Differential equation system';
            app.Variable1EditField = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.Variable1EditField.ValueChangedFcn = createCallbackFcn(app, @Variable1EditFieldValueChanged, true);
            app.Variable1EditField.Value = 'x';
            app.EditField_dxdt = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.EditField_dxdt.Value = ' 2*x - y + 3*(x^2-y^2) + 2*x*y';
            app.Variable2EditField = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.Variable2EditField.ValueChangedFcn = createCallbackFcn(app, @Variable2EditFieldValueChanged, true);
            app.Variable2EditField.Value = 'y';
            app.EditField_dydt = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.EditField_dydt.Value = ' x - 3*y - 3*(x^2-y^2) + 3*x*y';
            app.p1Name = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p1Name.ValueChangedFcn = createCallbackFcn(app, @p1NameValueChanged, true);
            app.p1Name.Value = 'a';
            app.p1Value = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p1Value.ValueChangedFcn = createCallbackFcn(app, @p1ValueValueChanged, true);
            app.p1Value.Value = '2';
            app.p4Name = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p4Name.ValueChangedFcn = createCallbackFcn(app, @p4NameValueChanged, true);
            app.p4Name.Value = 'd';
            app.p4Value = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p4Value.ValueChangedFcn = createCallbackFcn(app, @p4ValueValueChanged, true);
            app.p4Value.Value = '-1';
            app.p2Name = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p2Name.ValueChangedFcn = createCallbackFcn(app, @p2NameValueChanged, true);
            app.p2Name.Value = 'b';
            app.p2Value = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p2Value.ValueChangedFcn = createCallbackFcn(app, @p2ValueValueChanged, true);
            app.p2Value.Value = '1';
            app.p5Name = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p5Name.ValueChangedFcn = createCallbackFcn(app, @p5NameValueChanged, true);
            app.p5Value = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p5Value.ValueChangedFcn = createCallbackFcn(app, @p5ValueValueChanged, true);
            app.p3Name = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p3Name.ValueChangedFcn = createCallbackFcn(app, @p3NameValueChanged, true);
            app.p3Name.Value = 'c';
            app.p3Value = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p3Value.ValueChangedFcn = createCallbackFcn(app, @p3ValueValueChanged, true);
            app.p3Value.Value = '-1';
            app.p6Name = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p6Name.ValueChangedFcn = createCallbackFcn(app, @p6NameValueChanged, true);
            app.p6Value = uieditfield(app.DifferentialequationsystemPanel, 'text');
            app.p6Value.ValueChangedFcn = createCallbackFcn(app, @p6ValueValueChanged, true);
            app.DefaultButton = uibutton(app.DifferentialequationsystemPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Tooltip = {'Restore the default system'};
            app.DefaultButton.Text = 'Default';
            app.ClearButton = uibutton(app.DifferentialequationsystemPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clear the equations and parameters'};
            app.ClearButton.Text = 'Clear';
            app.UpdateButton = uibutton(app.DifferentialequationsystemPanel, 'state');
            app.UpdateButton.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.UpdateButton.Tooltip = {'Reset the phase plane'};
            app.UpdateButton.Text = 'Update';
            app.ParametersLabel = uilabel(app.DifferentialequationsystemPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.Label_5 = uilabel(app.DifferentialequationsystemPanel);
            app.Label_5.Text = '=';
            app.EditField_2Label = uilabel(app.DifferentialequationsystemPanel);
            app.EditField_2Label.Text = '=';
            app.EditField_3Label = uilabel(app.DifferentialequationsystemPanel);
            app.EditField_3Label.Text = '=';
            app.EditField_4Label = uilabel(app.DifferentialequationsystemPanel);
            app.EditField_4Label.Text = '=';
            app.EditField_5Label = uilabel(app.DifferentialequationsystemPanel);
            app.EditField_5Label.Text = '=';
            app.EditField_6Label = uilabel(app.DifferentialequationsystemPanel);
            app.EditField_6Label.Text = '=';
            app.Label_3 = uilabel(app.DifferentialequationsystemPanel);
            app.Label_3.Text = ''' = ';
            app.Label_4 = uilabel(app.DifferentialequationsystemPanel);
            app.Label_4.Text = ''' =';
            app.MessageArea = uitextarea(app.GridLayout2);
            app.MessageArea.Editable = 'off';
            app.MessageArea.Tooltip = {'Error messages will be displayed here.'};
            app.StartPanel = uipanel(app.GridLayout2);
            app.StartPanel.Visible = 'off';
            app.Hyperlink1 = uihyperlink(app.StartPanel);
            app.Hyperlink1.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink1Clicked, true);
            app.Hyperlink1.Text = 'Video tutorial';
            app.Hyperlink2 = uihyperlink(app.StartPanel);
            app.Hyperlink2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2Clicked, true);
            app.Hyperlink2.Text = 'Quick start guide';
            app.NewtothePhasePlaneappLabel = uilabel(app.StartPanel);
            app.NewtothePhasePlaneappLabel.Text = 'New to the Phase Plane app?';
            app.Hyperlink3 = uihyperlink(app.StartPanel);
            app.Hyperlink3.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink3Clicked, true);
            app.Hyperlink3.Text = '🗙';
            % Show the figure after all components are created
            app.PhasePlaneUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhasePlane

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhasePlaneUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhasePlaneUIFigure)
        end
    end
end