classdef SlopeField < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlopeFieldUIFigure             matlab.ui.Figure
        AnalysisMenu                   matlab.ui.container.Menu
        ShownullclinesMenu             matlab.ui.container.Menu
        AutogenerateisoclinesMenu      matlab.ui.container.Menu
        DrawisoclinethroughapointMenu  matlab.ui.container.Menu
        DrawseveralisoclinesMenu       matlab.ui.container.Menu
        ClearisoclinesMenu             matlab.ui.container.Menu
        SolveMenu                      matlab.ui.container.Menu
        SolvefromregionMenu            matlab.ui.container.Menu
        DrawMenu                       matlab.ui.container.Menu
        DrawsolutionMenu               matlab.ui.container.Menu
        DrawandcomparesolutionMenu     matlab.ui.container.Menu
        AppearanceMenu                 matlab.ui.container.Menu
        AnimatesolutionMenu            matlab.ui.container.Menu
        PointlabelsMenu                matlab.ui.container.Menu
        AxisthroughoriginMenu          matlab.ui.container.Menu
        DarkmodeMenu                   matlab.ui.container.Menu
        FieldorientationonlyMenu       matlab.ui.container.Menu
        LibraryMenu                    matlab.ui.container.Menu
        CustomlibraryMenu              matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        ClearMenu                      matlab.ui.container.Menu
        AddcurrentsystemMenu           matlab.ui.container.Menu
        ExportgraphicsMenu             matlab.ui.container.Menu
        ExporttoPNGMenu                matlab.ui.container.Menu
        ExporttoSVGMenu                matlab.ui.container.Menu
        ExporttoPDFMenu                matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        VideotutorialMenu              matlab.ui.container.Menu
        QuickstartguideMenu            matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        StartPanel                     matlab.ui.container.Panel
        Hyperlink3                     matlab.ui.control.Hyperlink
        NewtotheSlopeFieldappLabel     matlab.ui.control.Label
        Hyperlink2                     matlab.ui.control.Hyperlink
        Hyperlink1                     matlab.ui.control.Hyperlink
        DifferentialequationPanel      matlab.ui.container.Panel
        ParametersLabel_2              matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.StateButton
        ClearButton                    matlab.ui.control.Button
        DefaultButton                  matlab.ui.control.Button
        EditField_4Label               matlab.ui.control.Label
        EditField_2Label               matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        EditField_3Label               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        p4Value                        matlab.ui.control.EditField
        p4Name                         matlab.ui.control.EditField
        p2Value                        matlab.ui.control.EditField
        p2Name                         matlab.ui.control.EditField
        p3Value                        matlab.ui.control.EditField
        p3Name                         matlab.ui.control.EditField
        p1Value                        matlab.ui.control.EditField
        p1Name                         matlab.ui.control.EditField
        EditField_dxdt                 matlab.ui.control.EditField
        VariableEditField              matlab.ui.control.EditField
        SlopefieldPanel                matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Solvefromt0x0Button            matlab.ui.control.Button
        ClearsolutionsButton           matlab.ui.control.StateButton
        SkipanimationButton            matlab.ui.control.Button
        x0EditField                    matlab.ui.control.NumericEditField
        x0EditFieldLabel               matlab.ui.control.Label
        t0EditField                    matlab.ui.control.NumericEditField
        t0EditFieldLabel               matlab.ui.control.Label
        PositionLabel                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        MessageArea                    matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        AppearanceTab                  matlab.ui.container.Tab
        AnimationspeedSlider           matlab.ui.control.Slider
        LinewidthLabel                 matlab.ui.control.Label
        FieldscaleSpinnerLabel         matlab.ui.control.Label
        FielddensitySpinnerLabel       matlab.ui.control.Label
        AnimationspeedSliderLabel      matlab.ui.control.Label
        xmaxEditFieldLabel             matlab.ui.control.Label
        xminEditFieldLabel             matlab.ui.control.Label
        tmaxEditFieldLabel             matlab.ui.control.Label
        tminEditFieldLabel             matlab.ui.control.Label
        AxislimitsLabel                matlab.ui.control.Label
        FieldScaleUI                   matlab.ui.control.Spinner
        FieldDensityUI                 matlab.ui.control.Spinner
        SpinnerLineWidth               matlab.ui.control.Spinner
        xmaxEditField                  matlab.ui.control.NumericEditField
        xminEditField                  matlab.ui.control.NumericEditField
        tmaxEditField                  matlab.ui.control.NumericEditField
        tminEditField                  matlab.ui.control.NumericEditField
        SolutionTab                    matlab.ui.container.Tab
        SolvefromregiondensitySlider   matlab.ui.control.Slider
        SolvefromregiondensitySliderLabel  matlab.ui.control.Label
        TerminatesolutionsataxislimitsCheckBox  matlab.ui.control.CheckBox
        SolverTab                      matlab.ui.container.Tab
        VariablesolveroptionsPanel     matlab.ui.container.Panel
        SolverDropDown                 matlab.ui.control.DropDown
        SolverDropDownLabel            matlab.ui.control.Label
        AbsolutetoleranceEditField     matlab.ui.control.NumericEditField
        AbsolutetoleranceEditFieldLabel  matlab.ui.control.Label
        RelativetoleranceEditField     matlab.ui.control.NumericEditField
        RelativetoleranceEditFieldLabel  matlab.ui.control.Label
        MaxsolverwallclocksEditField   matlab.ui.control.NumericEditField
        MaxsolverwallclocksEditFieldLabel  matlab.ui.control.Label
        FixedsolveroptionsPanel        matlab.ui.container.Panel
        NoteimplicitmethodsareestimatedLabel  matlab.ui.control.Label
        StepsizeEditField              matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel         matlab.ui.control.Label
        FixedSolverDropDown            matlab.ui.control.DropDown
        SolverLabel                    matlab.ui.control.Label
        SolvertypeButtonGroup          matlab.ui.container.ButtonGroup
        FixedstepButton                matlab.ui.control.ToggleButton
        VariablestepButton             matlab.ui.control.ToggleButton
    end

    % This is app has similar functionality to the DField app developed by 
    % John C. Polking at Rice University between 1995-2003.
    % 
    % This app does not contain any of the original code.
    %
    % Copyright 2021 The MathWorks, Inc. 

    properties (Access = public)
        %--------- PARAMETERS HARDCODED HERE --------%
        % Plot settings
        Ntick = 3; % Number of ticks to use in the graph window
        quiverColor = [0.7 0.7 0.7] % Color of field arrows
        fsLabels = 13 % Axis labels font size [latex]
        colors = lines(500) % Colors to be plotted
        markerColor = [0 0 0]
        ms = 7 % Marker size
        ipMarkerColor = [1 1 1] % Initial point marker color
        fsIP = 12 % Size of font for initial points
        fontColorIP = [0 0 0] % This does get changed in dark mode
        labelBGColor = "none" % labels background color
        highlightEdgeAlpha = 0.15

        % Video tutorial local file location
        videoFileLocation = "SlopeFieldIntro.mp4"
        quickStartLocation = "SlopeFieldQuickStart.pdf"
        tutorialsFolder = "Tutorials"
                
        % Other appearance colors
        textColorDM = [1 1 1]     % dark mode
        bgColorDM = [0 0 0]       % dark mode
        textColorSTD = [0 0 0]    % standard mode
        bgColorSTD = [1 1 1]      % standard mode
        highlightColor = [0.94 0.5 0.2]
        
        % Animation steps when the speed slider is the slowest (so this is
        % the maximum number of steps that will ever be animated)
        defaultStepsPerAnimation = 600;
        Nchecks = 20; % How many times to check to stop the animation
        
        % Format spec for numbers
        formatSpec = "%.2f"
        
        % Nullclines settings
        Nnc = 400 % number of points to use when finding nullclines
        nullclineColor = [0.4 0.4 0.7]
        nclw = 1.25 % width
        
        % Isoclines settings
        Niso = 400 % number of points to use when finding nullclines
        isoclineColors = [0.6 0.4 0.6]
        isolw = 1 % width
        isoMinPoints = 50;
        isoRemovePoints = 8; % must be less than half of min points
        isoTextColor = [0 0 0]; % Will get changed by dark mode
        isoFontsize = 12;
        isoNumSpec = "%0.2f";
           
        % Default library save name            
        defaultLibraryName = "SlopeFieldLibrary"
        
        % Export
        defaultFilename = "slopefield" % Default graphic save name
        figLatexFontsize = 12 % Fontsize for equation during export            
        
        % Newton iteration settings (for the fixed step solver)
        NMaxNewton = 5 % Maximum iterations
        FNormNewton = 1e-8 % Norm of F
        xNormNewton = 1e-8 % Norm of x
        dxNewton = 1e-6 % step for finite difference approximation of derivatives
        
        % Minimum width/height after resizing
        minWidth = 720;
        minHeight = 600;
        verticalBuffer = 100; % Will try to use a buffer if the screen is small
        
        
        %--------- PARAMETERS SET BY UI --------%
        % Plot params
        lw = 1.5 % Line width (set automatically to default spinner value)
        
        % ODE Solver type ("variable" or "fixed")
        solverType = "variable";
        
        % Switches
        orientationOnlySwitch = false
        pointLabelsSwitch = true
        animateSolnSwitch = true
        originAxisSwitch
        darkModeSwitch
        nullclinesSwitch = false;

        %--------- OBJECTS --------%
        % Callback indicators
        singleIsoclineMode = false   % let the click callback know
        
        % Quiver field 
        field
        
        % Menu handle containers
        defaultOdeMenuHandles
        userOdeMenuHandles
        
        % Equation handles
        dx_cell
        dxdt
        
        % Flags
        invalidEquation % Gets flagged if setting the equation fails
        firstStep % Flags when its the first step of the ODE solution
        userDrawing = false % Identifies if a region is being drawn (initialize to false)
        skipAnimationFlag = false  % This gets triggered when user presses skip animation during an animation
        
        % Plot storage
        plotHandles
        plotMarkerHandles
        plotTextHandles
        highlightHandles 
        
        % Store object for solver issues
        solverMessage = ""
        
        % Nullcline/isocline plot handles
        nullclinesPlot
        isoclinesPlots
        isoclinesLabels

        % Library
        userODESystems
        
        % Parameter storage
        params
    end
    
    methods (Access = private)
        
        %------------------------ DERIVATIVE FIELD -----------------------%      
        function updateArrows(app)
            %This function generates the phase plane quivers
            try
                % Delete the previous field
                delete(app.field)
                % Use shared function to generate quivers
                app.field = SharedFunctions.updateArrows(app.UIAxes,app.dx_cell,...
                app.tminEditField.Value,app.tmaxEditField.Value,app.xminEditField.Value,app.xmaxEditField.Value,...
                app.FieldDensityUI.Value,app.FieldScaleUI.Value,app.quiverColor,app.Ntick,app.orientationOnlySwitch);
            catch ME
                message = "Failed to update quiver arrows with error: " + ME.message;
                app.displayMessage(message,2)
            end
        end
        
        %----------------------- ODE SOLUTION/PLOT -----------------------%      
        function evalWithParams(app,expression___)
            % This evaluates an expression with the user-defined parameters present
            if(~isempty(app.p1Name.Value) && ~isempty(app.p1Value.Value))
                eval(app.p1Name.Value + "=" + app.p1Value.Value + ";")
            end
            if(~isempty(app.p2Name.Value) && ~isempty(app.p2Value.Value))
                eval(app.p2Name.Value + "=" + app.p2Value.Value + ";")
            end
            if(~isempty(app.p3Name.Value) && ~isempty(app.p3Value.Value))
                eval(app.p3Name.Value + "=" + app.p3Value.Value + ";")
            end
            if(~isempty(app.p4Name.Value) && ~isempty(app.p4Value.Value))
                eval(app.p4Name.Value + "=" + app.p4Value.Value + ";")
            end
 
            % Evaluate expression
            eval(expression___)
        end
        
        function setEquation(app)
            % This function formats the equation and checks for errors
            % when the user tries to update it
            
            % Get variable names and user expressions
            variable = app.VariableEditField.Value;
            dxdt_str = app.EditField_dxdt.Value;
               
            % Create the equation handles and test them
            try 
                % This one is formatted for making the quivers, so the first 
                % variable is just a quiver scaling = 1
                evFieldStr = "app.dx_cell = @(t,"+variable+"){ (1);"+" ("+dxdt_str+");};";
                evFieldStrEdit = replace(evFieldStr,["*","^","/"],[".*",".^","./"]);
                evFieldStrEdit = replace(evFieldStrEdit,["..*","..^","../"],[".*",".^","./"]);               
                app.evalWithParams(evFieldStrEdit)
                % This is the ODE func
                evODEStr = "app.dxdt = @(t,"+ variable +")"+ dxdt_str + ";";
                app.evalWithParams(evODEStr)
                
                % Test out the handles
                app.dx_cell([0 0 0],[0 0 0; 0 0 0]);
                app.dxdt(0,0);
                
            catch exception
                % If it fails set "invalidEquation" to true so that the
                % user cannot generate solutions
                message = ['Invalid equation because: ', ...
                    newline, getReport(exception,'basic','hyperlinks','off'),newline,newline,...
                    'Update the equation.'];
                app.displayMessage(message,2)
                
                app.dx_cell = @(t,x){t*0;x*0};
                app.dxdt = @(t,x) x.*0;
                app.invalidEquation = true;
                return
            end
            
            % Set the axis labels/initial value labels
            app.setVariableLabels
        end
            
        function [tF,xF,tR,xR] = generateSolutionCurve(app,t0,x0) 
            tF = [];
            xF = [];
            tR = [];
            xR = [];
            if(app.invalidEquation)
                message = "Cannot generate solution because the equation is invalid."...
                        + newline + "Please define a new equation.";
                app.displayMessage(message,2)
                return
            end
            try        

                % Set the time spans to solve with the ODE solver                
                tspanF = [t0,app.tmaxEditField.Value];
                tspanR = [t0,app.tminEditField.Value];

                % Indicate to user that the ODE is being solved
                message = 'Solving...';
                app.displayMessage(message,0)
                pause(0)
                
                % Now solve the ODE
                lastwarn(''); % Clear last warning message
                app.solverMessage = "";
                
                % Forward solution
                if(tspanF(1) ~= tspanF(2))
                    [tF,xF] = solveODE(app,tspanF,x0);
                else
                    % If there's no forward time span, just include
                    % the initial point (so that it gets plotted)
                    tF = tspanF(1);
                    xF = x0;
                end

                % Backward solution
                if(tspanR(1) ~= tspanR(2))
                    [tR,xR] = solveODE(app,tspanR,x0);
                else
                    tR = [];
                    xR = [];
                end
                
                % Set warning message if needed
                [warnMsg, ~] = lastwarn;
                if( isempty(warnMsg) )
                    message = 'Solution completed successfully.';
                    app.displayMessage(message,0)
                else
                    app.displayMessage(warnMsg,1)
                    app.solverMessage = warnMsg;
                end

                app.SlopeFieldUIFigure.Pointer = "arrow"; % Regular pointer
             catch ME
                app.SlopeFieldUIFigure.Pointer = "arrow"; % Regular pointer
                message = "Solution failed with error: " + ME.message;
                app.displayMessage(message,2)
             end
        end
        
        
        function [t,x] = solveODE(app,tspan,x0)
            % Solve the ODE based on the solver selections    
            % Note: already wrapped in a try/catch statement from "generateSolnCurve" ^^
            t = [];
            x = [];
            
            % Star timer 
            tstartWallClock = tic;
            
            % This is required for the time checks       
            app.firstStep = 1; 
            
            if(app.VariablestepButton.Value)
                % Variable step solver solution
                solver = app.SolverDropDown.Value; % Pull variable solver name
                % Generate settings and call the ODE solver
                options = odeset('Events',@(t,y)app.eventFcn(t,y,tstartWallClock),...
                            "RelTol",app.RelativetoleranceEditField.Value,...
                            "AbsTol",app.AbsolutetoleranceEditField.Value); %#ok<NASGU> 
                sltnStrF = ['[t,x] = ',char(solver),'(app.dxdt,[',num2str(tspan),']'',[',num2str(x0),']'',options);'];
                eval(sltnStrF);
                
            elseif(app.FixedstepButton.Value)
                % Fixed step solver solution
                % Set forward or reverse time step
                if(tspan(2) >= tspan(1))
                    dt = app.StepsizeEditField.Value;
                else
                    dt = -app.StepsizeEditField.Value;
                end
                               
                % Set up vectors     
                Nsteps = ceil(abs(tspan(2)/dt));
                t = (tspan(1):dt:dt*Nsteps)';
                x = NaN(size(t));
                x(1) = x0;
            
                % Function handle for the fixed step
                f = @(t,x) app.dxdt(t,x);

                % Loop through time points until tspan(2) is surpassed
                for k = 2:numel(t)
                    
                    x(k) = SharedFunctions.fixedStep(t(k-1),x(k-1),f,dt,app.FixedSolverDropDown.Value,...
                        app.dxNewton,app.NMaxNewton,app.xNormNewton,app.FNormNewton);
                    [timeCheck,axisCheck] = solverChecks(app,t(k),x(k),tstartWallClock);
                    
                    if(~timeCheck)
                        msg = "Solution terminated early as simulation took longer than "+ ...
                            num2str(app.MaxsolverwallclocksEditField.Value) + "s." + newline + ....
                           "You can change this behavior in the Solver options pane →"; 
                        app.solverMessage = msg;
                        break
                    end
                    if( ~axisCheck )
                        msg = "Solution terminated early because a phase plane axis limit was reached." + newline +...
                        "You can change this behavior in the Solution options pane  →" + newline;
                        app.solverMessage = msg;
                        break
                    end
               
                end
                
                
            end
        end
        
        function [value,isterminal,direction] = eventFcn(app,t,x,tstartWallClock)
            % ODE solver event function to stop solution if needed
            isterminal = 1;
            direction = 0;
       
            % Check the axis/time limit
            [timeCheck,axisCheck] = app.solverChecks(t,x,tstartWallClock);
            value = [axisCheck,timeCheck];

            if( ~timeCheck )
                msg = "Solution terminated early as simulation took longer than "+ ...
                   num2str(app.MaxsolverwallclocksEditField.Value) + "s." + newline + ....
                   "You can change this behavior in the Solver options pane →"; 
                app.solverMessage = msg;
            end
            if( ~axisCheck )
                msg = "Solution terminated early because a phase plane axis limit was reached." + newline +...
                "You can change this behavior in the Solution options pane →" + newline;
                app.solverMessage = msg;
            end
        end  
        
        function [timeCheck,axisCheck] = solverChecks(app,t,x,tstartWallClock)
            % Check if the solution is taking forever
            timeCheck = toc(tstartWallClock) < app.MaxsolverwallclocksEditField.Value;
            
            % Check if the solution is outside the bounds
            axisCheck = true;
            if(app.TerminatesolutionsataxislimitsCheckBox.Value)
                if(t < app.tminEditField.Value || t > app.tmaxEditField.Value || ...
                   x < app.xminEditField.Value || x > app.xmaxEditField.Value )
                    axisCheck = false;
                end
            end
            % If its the first step, report true
            if( app.firstStep==1 )
                timeCheck = true;
                axisCheck = true;
            end
            % After first step, set to zero
            app.firstStep = 0;
        end
        
        
        function plotSolution(app,tF,xF,tR,xR,lineSpec)
  
            % If the equation is invalid, simply skip the plotting    
            if(app.invalidEquation)
                message = "Cannot generate solution because the equation is invalid."...
                        + newline + "Please define a new equation.";
                app.displayMessage(message,2)
                return
            end
            
            try
                app.SlopeFieldUIFigure.Pointer = "watch"; % Indicate that work is being done
                message = 'Plotting solution...';
                app.displayMessage(message,0)
                pause(0.00001)
                
                % Time range
                trange = abs(app.tmaxEditField.Value - app.tminEditField.Value);
    
                % Get the index of the new plot
                numPlots = numel(app.plotHandles);
                idxPlot = numPlots+1;            
                lineColor = app.colors(idxPlot,:);
                
                % Create a handle for the new plot
                hold(app.UIAxes,"on")
                app.plotHandles{idxPlot} = plot(app.UIAxes,0,0,lineSpec,"Linewidth",app.lw,"Color",lineColor);
                markerHandle = plot(app.UIAxes,tF(1),xF(1),'o',"Color",lineColor,...
                    "MarkerFaceColor",app.markerColor,"MarkerSize",app.ms,"LineWidth",app.lw);        
                app.plotMarkerHandles{idxPlot} = plot(app.UIAxes,tF(1),xF(1),'o',"Color",lineColor,...
                    "MarkerFaceColor",app.ipMarkerColor,"MarkerSize",app.ms,"LineWidth",app.lw);
                % text label  
                textLabel = "  (" + num2str(tF(1),app.formatSpec) + ", " + num2str(xF(1),app.formatSpec) + ")";
                app.plotTextHandles{idxPlot} = text(app.UIAxes,tF(1),xF(1),textLabel,"fontsize",app.fsIP,...
                    "Visible",app.pointLabelsSwitch,'Color',app.fontColorIP,"PickableParts","none",...
                    "BackgroundColor", app.labelBGColor);
                hold(app.UIAxes,"off")

                app.skipAnimationFlag = false;
                app.SkipanimationButton.Visible = true;
                
                % 1. Forward plot animation (if turned on)
                if(app.animateSolnSwitch)                 
                    % Compute the times where the solution will be drawn
                    forwardTime = abs(tF(end)-tF(1));
                    Npoints = forwardTime/trange * app.defaultStepsPerAnimation/app.AnimationspeedSlider.Value;
                    tvec = linspace(tF(1),tF(end),Npoints);
                    % Indices where the solution should be plotted
                    [~,tIndices] = min(abs(tvec-tF));
    
                    j = 1;
                    for k = tIndices
                        % Update plot
                        app.plotHandles{idxPlot}.XData = tF(1:k);
                        app.plotHandles{idxPlot}.YData = xF(1:k);
                        markerHandle.XData = tF(k);
                        markerHandle.YData = xF(k);
                        pause(0)
                        
                        if(mod(j,round(app.defaultStepsPerAnimation/app.Nchecks)) == 0)
                            drawnow
                            if(app.skipAnimationFlag)
                                break
                            end
                        end
                        j = j+1;
                    end
                end
                
               % 2. Reverse plot animation (if turned on)
               if(app.animateSolnSwitch && ~isempty(tR) && ~app.skipAnimationFlag)
    
                    % Compute the times where the solution will be drawn
                    reverseTime = abs(tR(end)-tR(1));
                    Npoints = reverseTime/trange * app.defaultStepsPerAnimation/app.AnimationspeedSlider.Value;
                    tvec = linspace(tR(1),tR(end),Npoints);
                    % Indices where the solution should be plotted
                    [~,tIndices] = min(abs(tvec-tR));
    
                    j = 1;
                    for k = tIndices
                        % Update plot
                        app.plotHandles{idxPlot}.XData = [tR(k:-1:1); tF];
                        app.plotHandles{idxPlot}.YData = [xR(k:-1:1); xF];
                        markerHandle.XData = tR(k);
                        markerHandle.YData = xR(k);
                        pause(0)
                          
                       if(mod(j,round(app.defaultStepsPerAnimation/app.Nchecks)) == 0)
                            drawnow
                            if(app.skipAnimationFlag)
                                break
                            end
                        end
                        j = j+1;
                    end
         
               end
               drawnow
               app.SkipanimationButton.Visible = false;
       
                if(~isempty(xR))
                   x = [xR(end:-1:1); xF]; 
                   t = [tR(end:-1:1); tF]; 
                else
                   x = xF;
                   t = tF;
                end
               
                
                % Regular plot update
                app.plotHandles{idxPlot}.XData = t;
                app.plotHandles{idxPlot}.YData = x;
                markerHandle.XData = tF(1);
                markerHandle.YData = xF(1);
                
                % Delete the animation marker
                delete(markerHandle)
                            
                % Set a click callback for highlighting the solution 
                set(app.plotHandles{idxPlot},"ButtonDownFcn",{@app.highlightPlotCallback,idxPlot});
                set(app.plotMarkerHandles{idxPlot},"ButtonDownFcn",{@app.highlightPlotCallback,idxPlot});
                
                % Display completion message
                drawnow
                if(app.solverMessage ~= "")
                    message = "Finished plotting solution." + newline ...
                        + newline + "Note:" + newline + app.solverMessage;
                    app.displayMessage(message,0)
                else
                    message = "Finished plotting solution.";
                    app.displayMessage(message,0)
                end
                app.SlopeFieldUIFigure.Pointer = "arrow"; % Regular pointer
            catch ME
               % Otherwise just error out
               message = "Plotting failed due to: "...
                    + newline + ME.message;
                
               app.displayMessage(message,2) 
            end
        end
           
              
        function solveAndPlotFromInitialValue(app)
            % Solves/plots from a single value defined in the edit fields
            [tF,yF,tR,yR] = app.generateSolutionCurve(app.t0EditField.Value,app.x0EditField.Value);
            % Plot the solution             
            app.plotSolution(tF,yF,tR,yR,'-')
        end
        
        %--------------------------- ANALYSIS --------------------------%    
        function updateNullclines(app)
            if(app.nullclinesSwitch)
                try
                    % set up grid
                    tlims = [app.tminEditField.Value, app.tmaxEditField.Value];
                    xlims = [app.xminEditField.Value, app.xmaxEditField.Value];
                    t = linspace(tlims(1),tlims(2),app.Nnc);
                    x = linspace(xlims(1),xlims(2),app.Nnc);
                    [T,X] = meshgrid(t,x);
            
                    % Pull ODE func
                    dXplane = app.dx_cell(T,X);
                    dxdtXT = dXplane{2}; % Second cell is dxdt
             
                    % Generate contours
                    hold(app.UIAxes,"on")
                    [~,app.nullclinesPlot] = contour(app.UIAxes,T,X,dxdtXT,...
                        [0,0],'Color',app.nullclineColor,'linewidth',app.nclw);
                    hold(app.UIAxes,"off")
                catch ME
                    app.displayMessage("Error displaying nullclines: " + ME.message,2)
                end
            else
                app.clearNullclines
            end
        end
               
        % Isoclines
        function autoGenerateIsoclines(app)
             [isoPlotHandles,isoLabelHandles] = SharedFunctions.autoGenerateIsoclines(app.tminEditField.Value,...
                app.tmaxEditField.Value,app.xminEditField.Value,app.xmaxEditField.Value,...
                app.Niso,app.dx_cell,app.UIAxes,app.isoclineColors,app.isolw,app.MessageArea, ...
                app.isoMinPoints,app.isoRemovePoints,app.isoTextColor,app.isoFontsize,app.isoNumSpec);
             
             app.storeIsoclinePlots(isoPlotHandles,isoLabelHandles)
        end
        
        function generateIsoclineThroughPoint(app,location)
             [isoPlotHandles,isoLabelHandles] = SharedFunctions.generateIsoclineThroughPoint(location,app.tminEditField.Value,...
                app.tmaxEditField.Value,app.xminEditField.Value,app.xmaxEditField.Value,...
                app.Niso,app.dx_cell,app.UIAxes,app.isoclineColors,app.isolw,app.MessageArea, ...
                app.isoMinPoints,app.isoRemovePoints,app.isoTextColor,app.isoFontsize,app.isoNumSpec);
            
             app.storeIsoclinePlots(isoPlotHandles,isoLabelHandles)
        end

       function generateIsoclinesFromVector(app)
             [isoPlotHandles,isoLabelHandles] = SharedFunctions.generateIsoclinesFromVector(app.tminEditField.Value,...
                app.tmaxEditField.Value,app.xminEditField.Value,app.xmaxEditField.Value,...
                app.Niso,app.dx_cell,app.UIAxes,app.isoclineColors,app.isolw,app.MessageArea, ...
                app.isoMinPoints,app.isoRemovePoints,app.isoTextColor,app.isoFontsize,app.isoNumSpec);
             
             app.storeIsoclinePlots(isoPlotHandles,isoLabelHandles)
       end
       
       function storeIsoclinePlots(app,isoPlotHandles,isoLabelHandles)
           % Saves the iscocline plot handles
           N = numel(app.isoclinesPlots);
           indices = N + (1:numel(isoPlotHandles));
           for k = 1:numel(indices)
               app.isoclinesPlots{indices(k)} = isoPlotHandles{k};
               app.isoclinesLabels{indices(k)} = isoLabelHandles{k};
           end
       end
    
        %---------------------- SOLVE MENU FUNCTIONS ---------------------%            
        function solveFromRegion(app)
            try
                % Drawing region
                app.userDrawing = true;
                % Pull solution density
                Nx = app.SolvefromregiondensitySlider.Value;
                Ny = app.SolvefromregiondensitySlider.Value;
  
                % Start the freehand
                message = "Draw a region on the field: "  ...
                    +newline+"1. Click once to start drawing. "...
                    +newline+"2. Click again to finish drawing."...
                    +newline+newline+"When complete, solutions will be computed at regular intervals starting inside the region.";
                app.displayMessage(message,-1)
                
                closed = true;
                 [tr,xr,pHandle] = SharedFunctions.freedraw(app.SlopeFieldUIFigure,app.SlopefieldPanel,...
                    app.UIAxes,app.tminEditField.Value,app.tmaxEditField.Value,...
                    app.xminEditField.Value,app.xmaxEditField.Value,closed);
                delete(pHandle)
                
                % Plot the polygon region
                pgon = polyshape(tr,xr,"Simplify",false);
                hold(app.UIAxes,"on")
                areaPlot = plot(app.UIAxes,pgon,"FaceColor","Blue","Edgealpha",0);
                hold(app.UIAxes,"off")
                
                % Regularly spaced grid of points to select from
                t = linspace(app.tminEditField.Value,app.tmaxEditField.Value,Nx);
                x = linspace(app.xminEditField.Value,app.xmaxEditField.Value,Ny);
                [T,X] = meshgrid(t,x);
                tp = T(:);
                xp = X(:);
                
                % Get points in the region
                hit = inpolygon(tp,xp,tr,xr);
                idx = find(hit == 1);
    
                % Loop through points and solve/plot
                for k = 1:numel(idx)
                    t0 = tp(idx(k));
                    x0 = xp(idx(k));
                    
                    %Solve
                    [tF,xF,tR,xR] = app.generateSolutionCurve(t0,x0);
                    %Plot the solution             
                    app.plotSolution(tF,xF,tR,xR,'-')
                end  
                
                
                % Done drawing region
                app.userDrawing = false;
                % Delete the region
                delete(areaPlot)           
            catch ME
                 app.displayMessage("Error solving from region: " + ME.message,2)
            end
                
            % reset the motion callback (in case there are any issues)
            set(app.SlopeFieldUIFigure,"WindowButtonMotionFcn",@app.windowMotion)
            set(app.SlopeFieldUIFigure,"WindowButtonDownFcn",@app.windowButtonDown)
            
        end
        
        %---------------------- DRAW MENU FUNCTIONS ---------------------%     
        function drawSolution(app,compareFlag)
            try
                % Allows user to draw a solution by hand and then compares
                % their solution to an actual solution
                message ="1. Click once to start drawing. "...
                   +newline+"2. Click again to finish drawing.";
                app.displayMessage(message,-1)
                app.userDrawing = true;
                
                closed = false;
                [t,x,pHandle] = SharedFunctions.freedraw(app.SlopeFieldUIFigure,app.SlopefieldPanel,...
                    app.UIAxes,app.tminEditField.Value,app.tmaxEditField.Value,...
                    app.xminEditField.Value,app.xmaxEditField.Value,closed);
                delete(pHandle)
                
                % Plot the drawing as a solution
                app.plotSolution(t,x,[],[],'--')

                if(compareFlag)
                     % Clear out duplicates
                    [~,idx] = unique(t);
                    t = t(idx);
                    x = x(idx); 
                    
                    % Make sure they are sorted in time
                    [tFUser,idx] = sort(t);
                    xFUser = x(idx); 
                    
                    % Range of the solution
                    trange = [tFUser(1),tFUser(end)];
                    xrange = [xFUser(1),xFUser(end)];
                    
                    
                    % Solve
                    [tF,xF] = solveODE(app,trange,xrange(1));
                                  
                    % Plot the solution             
                    app.plotSolution(tF,xF,[],[],'-')
                    
                    % Grade solution
                    ts = max([tF(1),tFUser(1)]);
                    tf = min([tF(end),tFUser(end)]);
                    t = linspace(ts,tf,200);
                    
                    xFinterp = interp1(tF,xF,t,"pchip");
                    xFUserInterp = interp1(tFUser,xFUser,t,"pchip");
    
                    % Compute the percent match. This is slightly exaggerated
                    % (multiplied by 2) because the "matches" tend to be too good
                    meanDiff = mean(abs(xFinterp - xFUserInterp));
                    maxDiff = 0.5*abs(app.xmaxEditField.Value - app.xminEditField.Value);
                    percentDiff = abs(meanDiff)/maxDiff*100;
                    percentMatch = 100 - percentDiff;
    
                    message = "Relative to half the size of the current axes, your solution matches "...
                        + num2str(percentMatch,"%0.2f") + "%.";
                    app.displayMessage(message,-1)
                else
                    
                    message = "Done drawing." ;
                    app.displayMessage(message,0)
                end
                
            
            catch ME
                 app.displayMessage("Error drawing solution: " + ME.message,2)
            end
            app.userDrawing = false;
            % reset the motion callback (in case there are any issues)
            set(app.SlopeFieldUIFigure,"WindowButtonMotionFcn",@app.windowMotion)
            set(app.SlopeFieldUIFigure,"WindowButtonDownFcn",@app.windowButtonDown)
            
        end
        
        
        %--------- CLICK CALLBACKS AND CLICK CALLBACK SUPPORT ---------%
        function windowMotion(app,obj,event)
            try
                % Write the current point if in the PP axis
                textLabel = "-";
                location = SharedFunctions.checkIfInFieldAxis(event,app.SlopefieldPanel,...
                    app.UIAxes,app.tminEditField.Value,app.tmaxEditField.Value,...
                    app.xminEditField.Value,app.xmaxEditField.Value);
                if(~isnan(location))
                    textLabel = "(" + num2str(location(1),app.formatSpec) + ", " + num2str(location(2),app.formatSpec) + ")"; 
                    if( app.singleIsoclineMode)
                            obj.Pointer = "crosshair";
                    elseif (app.userDrawing)
                        % If drawing an ROI, don't do anything here
                    else
                        obj.Pointer = "hand";
                    end
                else
                    obj.Pointer = "arrow";
                end
                app.PositionLabel.Text = textLabel;
                pause(0)
            catch ME
                 app.displayMessage("Error in windowMotion function: "...
                    + ME.message + newline + "If this persists try restarting the app.",3)
            end
        end
        
        function windowButtonDown(app,obj,event)   
 
            % Check if the current point is on the axes
            location = SharedFunctions.checkIfInFieldAxis(event,app.SlopefieldPanel,...
                app.UIAxes,app.tminEditField.Value,app.tmaxEditField.Value,...
                app.xminEditField.Value,app.xmaxEditField.Value);
            
            % If you're creating an ROI, skip this
            if(app.userDrawing)
                return
            end
            
            if(app.singleIsoclineMode)
                  app.generateIsoclineThroughPoint(location)
                  app.singleIsoclineMode = false;
                  obj.Pointer = "hand";
                return
            end      
               
            % If you don't click on a line, try to find a point
            % Click must be normal
            if( class(event.HitObject) ~= "matlab.graphics.chart.primitive.Line" ...
                && all(~isnan(location))...
                && event.Source.SelectionType == "normal")
                
                % Solve for the solution
                [tF,xF,tR,xR] = app.generateSolutionCurve(location(1),location(2));
                % Plot the solution             
                app.plotSolution(tF,xF,tR,xR,'-')
            end         
      
        end
        
          
        function deleteHighlightsCallback(app,src,event) 
             app.deleteHighlights;
        end    
        
         
        function deletePlotCallback(app,src,event,idx)
            % Delete the objects
            delete(app.plotHandles{idx})
            delete(app.plotTextHandles{idx})
            delete(app.plotMarkerHandles{idx})
            
            % Just store an empty value in the array
            app.plotHandles{idx} = [];
            app.plotTextHandles{idx} = [];
            app.plotMarkerHandles{idx} = [];

            message = "Plot " + idx + " removed.";
            app.displayMessage(message,0)
        end
        
         %-------------------- LIBRARY MENU FUNCTIONS  --------------------%    
        function odeSystemMenuSelected(app,src,event,odeSystemData)
            % Set parameters
            app.clearParameters
            app.p1Name.Value = odeSystemData.pNames(1);
            app.p1Value.Value = odeSystemData.pValues(1);
            app.p2Name.Value = odeSystemData.pNames(2);
            app.p2Value.Value = odeSystemData.pValues(2);
            app.p3Name.Value = odeSystemData.pNames(3);
            app.p3Value.Value = odeSystemData.pValues(3);
            app.p4Name.Value = odeSystemData.pNames(4);
            app.p4Value.Value = odeSystemData.pValues(4);

            % Set ODE system
            app.VariableEditField.Value = odeSystemData.varName;
            app.EditField_dxdt.Value = odeSystemData.dxdtExpression;

            % Set axis limits
            app.setAxisLimits(odeSystemData.txlims)

            % Update
            app.updateParams
            app.updateEquations
            
            % Message
            message = "ODE system set to '"+odeSystemData.name + "'.";
            app.displayMessage(message,-1)
        end

        function saveLibrary(app)
            % Saves the user library to a MAT file
            SharedFunctions.saveLibrary(app.defaultLibraryName,app.userODESystems,app.MessageArea)
        end
   
        function loadLibrary(app)  
            % Clear current library
            app.clearCustomLibrary

            % loads a user library from a MAT file
            app.userODESystems = SharedFunctions.loadLibrary(app.defaultLibraryName,app.MessageArea);
            
            % Add them to the menu         
            for k = 1:numel(app.userODESystems)
                app.addUserODEMenu(k,app.userODESystems{k})
            end
        end
             
        function clearCustomLibraryRequest(app)
            title = "Clear library";
            message = "Are you sure you want to clear the custom library?";
            selection = uiconfirm(app.SlopeFieldUIFigure,message,title);
            if(selection)
               app.clearCustomLibrary
               message = "Custom library cleared.";
               app.displayMessage(message,0)
            end
        end
        
        function addUserODESystem(app,src,event)
            prompt = "Name your system";
            title = "Add system";
            name = inputdlg(prompt,title,[1 40],"");
            if( isempty(name) )
                name = "";
            end
            if(name == "")
                message = "ODE system was not saved (you must give the system a name).";
                app.displayMessage(message,1)
                return
            end
            odeSystemData.name = name{1};
 
            % Retrieve the current settings
            odeSystemData.pNames = [string(app.p1Name.Value), string(app.p2Name.Value), ...
                string(app.p3Name.Value), string(app.p4Name.Value)];
            odeSystemData.pValues = [string(app.p1Value.Value), string(app.p2Value.Value),...
                string(app.p3Value.Value), string(app.p4Value.Value)];

            % Set ODE system
            odeSystemData.varName = app.VariableEditField.Value;
            odeSystemData.dxdtExpression = app.EditField_dxdt.Value;

            % Set axis limits
            odeSystemData.txlims = [app.tminEditField.Value,app.tmaxEditField.Value,...
                                    app.xminEditField.Value,app.xmaxEditField.Value];
       
            % Store ODE system data
            idx = numel(app.userODESystems)+1;
            app.userODESystems{idx} = odeSystemData;
            
            % Add the menu
            app.addUserODEMenu(idx,odeSystemData)
        
            % Display a message
            message = "User ODE system '"+odeSystemData.name+"' saved with: " + newline ...
                + "dxdt = " + odeSystemData.dxdtExpression;
            app.displayMessage(message,0)
        end
        
        function addUserODEMenu(app,idx,odeSystemData)
            % Add the system to the menu
            app.userOdeMenuHandles{idx} = uimenu(app.CustomlibraryMenu); 
            app.userOdeMenuHandles{idx}.MenuSelectedFcn = {@app.odeSystemMenuSelected,odeSystemData};
            app.userOdeMenuHandles{idx}.Text = odeSystemData.name;
            if(idx == 1)
                app.userOdeMenuHandles{idx}.Separator = true;
            end
        end
        
        function addDefaultODESystem(app,odeSystemData)
            % Add the system to the menu
            idx = numel(app.defaultOdeMenuHandles)+1;
            app.defaultOdeMenuHandles{idx} = uimenu(app.LibraryMenu); 
            app.defaultOdeMenuHandles{idx}.MenuSelectedFcn = {@app.odeSystemMenuSelected,odeSystemData};
            app.defaultOdeMenuHandles{idx}.Text = odeSystemData.name;
            
        end
      
        function createDefaultODEMenus(app)
            % 1. default
            odeSystemData.name = "Default";
            odeSystemData.pNames = ["a","","",""];
            odeSystemData.pValues = ["1","","",""];
            odeSystemData.varName = "x";
            odeSystemData.dxdtExpression = "a*x^2 - t";
            odeSystemData.txlims = [-2 4 -2 4];
            addDefaultODESystem(app,odeSystemData)

            % 2. logistic
            odeSystemData.name = "Logistic";
            odeSystemData.pNames = ["r","","K",""];
            odeSystemData.pValues = ["0.75","","10",""];
            odeSystemData.varName = "P";
            odeSystemData.dxdtExpression = "r*P*(1 - P/K)";
            odeSystemData.txlims = [0 20 0 15];
            addDefaultODESystem(app,odeSystemData)
            
            % 3. RC circuit
            odeSystemData.name = "RC circuit";
            odeSystemData.pNames = ["A","R","omega","C"];
            odeSystemData.pValues = ["5","0.5","3","2"];
            odeSystemData.varName = "V_c";
            odeSystemData.dxdtExpression = "(A*cos(omega*t) - V_c)/(R*C)";
            odeSystemData.txlims = [0 10 -5 5];
            addDefaultODESystem(app,odeSystemData)
            
            % 4. RL circuit
            odeSystemData.name = "RL circuit";
            odeSystemData.pNames = ["A","R","omega","L"];
            odeSystemData.pValues = ["5","3","3","2"];
            odeSystemData.varName = "I";
            odeSystemData.dxdtExpression = "(A*cos(omega*t) - R*I)/L";
            odeSystemData.txlims = [0 10 -5 5];
            addDefaultODESystem(app,odeSystemData)
        end
        %------------------ END LIBRARY MENU FUNCTIONS  ------------------%    
        
        %------------------------ USER INTERFACE -------------------------%          
        function displayMessage(app,message,errorFlag)
            % This stub calls the shared function:
            SharedFunctions.displayMessage(message,errorFlag,app.MessageArea,app.StartPanel)
        end
          function out = checkVariableName(app,value,previousValue)
            % This function checks for issues with the variable names
            % selected by the user
            if(isempty(value))% Okay to just remove the name
                out = value;
                return
            end            
            [dupFlag,tFlag] = app.checkDuplicateNames;
            if(~isvarname(value))
                message = "'" + value + "' is not a valid MATLAB variable name. " ...
                    + newline + "Select a different variable name.";
                app.displayMessage(message,2)
                out = previousValue;
            elseif(dupFlag)
                message = "Looks like you already used '" + value + "'" ...
                    + newline + "Select a different variable name.";
                app.displayMessage(message,2)
                out = previousValue;
            elseif(tFlag)
                message = "The variable 't' is reserved for the independent variable."...
                    + newline + "Select a different variable name.";
                app.displayMessage(message,2)
                out = previousValue;
                
            else
                out = value;
                message = "Name updated successfully to " + out;
                app.displayMessage(message,0)
            end
        end
           
   
        function [dupFlag,tflag] = checkDuplicateNames(app)
            % Check for duplicates
           names(1) = string(app.p1Name.Value);
           names(2) = app.p2Name.Value;
           names(3) = app.p3Name.Value;
           names(4) = app.p4Name.Value;
           names(5) = app.VariableEditField.Value;
           names(names=="") = [];
           dupFlag = length(names) ~= length(unique(names));
           % Check for "t"
           tflag = any(names == "t");
        end
        
        
        %---------------------------- VISUALS ----------------------------%
        function setUIColors(app,backgroundColor,foregroundColor)
            % Change the main UI colors
            % Change the axes
            app.SlopefieldPanel.BackgroundColor = backgroundColor;
            app.UIAxes.XColor = foregroundColor;
            app.UIAxes.YColor = foregroundColor;
            
            % Get the handles for the UI objects
            handles = findobj(app.SlopefieldPanel);
            textHandles = [app.plotTextHandles,app.isoclinesLabels];
            
            % Loop through handles and change the appearance
            SharedFunctions.setUIColorFromHandles(backgroundColor,foregroundColor,handles,textHandles);
            
            % Change the default initial value label color
            app.fontColorIP = foregroundColor;
            app.isoTextColor = foregroundColor;
        end
        
        
        function setVariableLabels(app)     
            try
                % Convert to tex
                variable = app.VariableEditField.Value;
                xvar = texlabel(variable);
                
                % Set variable names on the axis handles (latex is prettier than tex)
                xlabel(app.UIAxes,"$t$","Interpreter","Latex","FontSize",app.fsLabels)
                ylabel(app.UIAxes,"$" + xvar + "$","Interpreter","Latex","FontSize",app.fsLabels)
                
                % Set initial value label
                app.invalidEquation = false;
                app.x0EditFieldLabel.Text = variable + "0 = ";
            catch ME
                message = "Updating axis labels failed due to: "+ newline + ME.message;
                app.displayMessage(message,2)
            end
        end
          
        function setLineWidth(app)
            % Delete highlights if adjusting line width
            app.deleteHighlights
            % Set the line width
            app.lw = app.SpinnerLineWidth.Value;
            % Adjust existing plots
            for idx = 1:numel(app.plotHandles)
                app.plotHandles{idx}.LineWidth = app.lw;
                app.plotMarkerHandles{idx}.LineWidth = app.lw;
            end   
        end
        
          
        function highlightPlotCallback(app,src,event,idx)
            try
                % If its a right click, dont highlight, but delete instead
                if( src.Parent.Parent.Parent.Parent.Parent.SelectionType == "alt")
                    deletePlotCallback(app,src,event,idx)
                    return
                end
                
                % If a highlight already exists, delete it
                if(~isempty(app.highlightHandles))
                    app.deleteHighlightsCallback(src,event)
                end
    
                % Create plot highlights
                app.highlightHandles{1} = copyobj( app.plotHandles{idx}, app.UIAxes);
                set( app.highlightHandles{1},"linewidth",app.lw*2.5,"color",app.highlightColor,"LineStyle","-")
                app.highlightHandles{2} = copyobj( app.plotHandles{idx}, app.UIAxes);
                set(app.highlightHandles{2},"linewidth",app.lw*0.8)             
                app.highlightHandles{3} = copyobj(app.plotMarkerHandles{idx}, app.UIAxes);
                set( app.highlightHandles{3},"linewidth",app.lw*2.5,"color",app.highlightColor)
                app.highlightHandles{4} = copyobj(app.plotMarkerHandles{idx}, app.UIAxes);
                set(app.highlightHandles{4},"linewidth",app.lw*0.8)
                
                %Hide labels
                for k = 1:numel(app.plotTextHandles)
                    if(k ~= idx)
                        app.plotTextHandles{k}.Visible = "off";
                    end
                end
                %Hide markers
                for k = 1:numel(app.plotMarkerHandles)
                    app.plotMarkerHandles{k}.Visible = "off";
                end
                %Dim lines
                for j = linspace(0,1,10)
                    for k = 1:numel(app.plotHandles)
                        if(~isempty(app.plotHandles{k}))
                            app.plotHandles{k}.Color(4) = 1+(-1+app.highlightEdgeAlpha)*j;
                        end
                    end
                    pause(0.001)
                end
                
                % Set callbacks that will delete the highlight
                for k = 1:numel(app.highlightHandles)
                    set(app.highlightHandles{k},"ButtonDownFcn",@app.deleteHighlightsCallback)
                end
            catch ME
                app.displayMessage("Error in highlightPlotCallback due to: "...
                    + ME.message,3)
            end
        end
        
             
        function deleteHighlights(app)
            % Deletes the plot highlights
            for k = 1:numel(app.highlightHandles)
                delete(app.highlightHandles{k})
            end
            app.highlightHandles = {};   
            % Show other plots again
            for k = 1:numel(app.plotHandles)
                if(~isempty(app.plotHandles{k}))
                    app.plotHandles{k}.Color(4) = 1;
                end
            end
            %Show markers again
            for k = 1:numel(app.plotMarkerHandles)
                app.plotMarkerHandles{k}.Visible = "on";
            end
            %Show labels again if labels are on
            if(app.pointLabelsSwitch)
                for k = 1:numel(app.plotTextHandles)
                    app.plotTextHandles{k}.Visible = "on";
                end
            end
        end
      
  
        function toggleLabels(app)
            % Turns initial point labels on and off
            for k = 1:numel(app.plotTextHandles)
                app.plotTextHandles{k}.Visible = app.pointLabelsSwitch;
            end
        end
        
       function setAxisMode(app)
            % Switches between axis positions: origin/normal
            if(app.originAxisSwitch)
                app.UIAxes.XAxisLocation = "origin";
                app.UIAxes.YAxisLocation = "origin";
            else
                app.UIAxes.XAxisLocation = "bottom";
                app.UIAxes.YAxisLocation = "left";
            end
        end

        function setAxisLimits(app,txlims)
            % Sets the axis limits by calling the callbacks
            app.tminEditField.Value = txlims(1);
            app.tmaxEditField.Value = txlims(2);
            app.xminEditField.Value = txlims(3);
            app.xmaxEditField.Value = txlims(4);
            
            app.tminEditFieldValueChanged
            app.tmaxEditFieldValueChanged
            app.xminEditFieldValueChanged
            app.xmaxEditFieldValueChanged
        end
        
        %-------------------------EXPORT GRAPHICS-------------------------%
        function figHandle = exportToFigure(app,d)
            vshift = 0;
            hbuff = 15;
            try
                % Decide on a background color
                if(app.darkModeSwitch)
                    figColor = app.bgColorDM;
                    textColor = app.textColorDM;
                else
                    figColor = app.bgColorSTD;
                    textColor = app.textColorSTD;
                end
                
                % Position based on the panel
                uiFigPos = app.SlopeFieldUIFigure.Position;
                dpanelPos = app.SlopefieldPanel.InnerPosition;
                pos = [uiFigPos(1)+50,uiFigPos(2)+50,dpanelPos(3)+hbuff,dpanelPos(4)+vshift];
      
                % Create a new figure
                figHandle = figure("Visible","off","Color",figColor,"InnerPosition",pos,...
                                 "InvertHardcopy","off");
                d.Value = 0.2; %progress update
    
                % Copy to new figure
                dHandle = copyobj(app.UIAxes,figHandle);
                dHandle.Position(2) = dHandle.Position(2) + vshift;
                title(dHandle,"Slope field","Color",textColor,"Interpreter","latex")
               
                % Add equations to new figure
                eqnAxisPos = [dHandle.Position(1),dHandle.Position(2)-vshift-30,dHandle.Position(3),40];
                eqnAxis = axes(figHandle,"Units",dHandle.Units);
                eqnAxis.Position = eqnAxisPos;
                axis(eqnAxis,"off")
                axis(eqnAxis,[-1,1,-1,1])
                         
                xvar = texlabel(app.VariableEditField.Value);
                dxdttex = texlabel(app.EditField_dxdt.Value);
                  
                eqn1String = dxdttex;
                eq ="\qquad ODE: \ " + "$" + xvar + "'(t) = " + eqn1String + "$";            
       
                text(eqnAxis,-1,0,eq,"Interpreter","latex",...
                    "VerticalAlignment","top","HorizontalAlignment","left",...
                    "FontSize",app.figLatexFontsize,"Color",textColor)
                d.Value = 0.4; %progress update
            catch ME
                message = "Export to image file failed because: "...
                      + newline + ME.message;
                app.displayMessage(message,1)
            end
        end 
    
        function exportToFile(app,fileExt)
            % Exports the plots to an image file
            
            try
                % Request filename
                filename = SharedFunctions.saveImagePopup(fileExt,app.defaultFilename);
                
                % If none was given, skip this
                if(filename == "")
                    return
                end
                
                % Progress dialogue
                d = uiprogressdlg(app.SlopeFieldUIFigure);
                d.Value = 0.1;

                % Generate export figure
                figHandle = app.exportToFigure(d);

                drawnow
                d.Value = 0.75; %progress update
                
                % Save figure
                if(fileExt==".pdf") 
                    if(app.darkModeSwitch)
                        figColor = app.bgColorDM;
                    else
                        figColor = app.bgColorSTD;
                    end
                    set(figHandle,'Units','Inches');
                    pos = get(figHandle,'Position');
                    set(figHandle,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])
                    saveas(figHandle,filename)
                else
                    saveas(figHandle,filename)
                end
                close(figHandle)
                
                d.Value = 1; %progress update
                
                % Display message
                message = "Phase portrait saved to " + filename + ".";
                app.displayMessage(message,0)
                
            catch ME
                % Display message
                message = "Error exporting to " + filename + " due to:" + newline + ME.message;
                app.displayMessage(message,1)
            end
        end

        
        %---------------------- UPDATE/RESET/CLEAN UP ---------------------% 
        function resetField(app)
            % Clear solutions
            app.clearSolns
            
            % Clear messages
            message = '';
            app.displayMessage(message,0)


            % Clear nullclines menu
            app.ShownullclinesMenu.Checked = false;
            app.nullclinesSwitch = false;
            % Clear nullclines plots
            app.clearNullclines

            % Clear isoclines
            app.clearIsoclines
            
            % Clear axes
            cla(app.UIAxes);
        end

        function clearSolns(app)
            
            % Clear highlights
            deleteHighlightsCallback(app);
            
            % Delete graphics objects
            SharedFunctions.clear1DGraphicsCellArray(app.plotHandles,app.MessageArea)
            SharedFunctions.clear1DGraphicsCellArray(app.plotMarkerHandles,app.MessageArea)
            SharedFunctions.clear1DGraphicsCellArray(app.plotTextHandles,app.MessageArea)

            % Clear handle containers
            app.plotHandles = {};
            app.plotMarkerHandles = {};
            app.plotTextHandles = {};

        end
                
        function updateEquations(app)
            resetField(app);
            app.setEquation
            app.updateArrows
        end
   
         function clearNullclines(app)
            % Clear the nullclines
            if(~isempty(app.nullclinesPlot))
                delete(app.nullclinesPlot)
                app.nullclinesPlot = {};
            end
        end
               
        function clearIsoclines(app)
            % Clear the isocolines
            if(~isempty(app.isoclinesPlots))
                for k = 1:numel(app.isoclinesPlots)
                    delete(app.isoclinesPlots{k})
                end
                app.isoclinesPlots = {};
            end
                  
            % Clear the labels
            if(~isempty(app.isoclinesLabels))
                for k = 1:numel(app.isoclinesLabels)
                    delete(app.isoclinesLabels{k})
                end
                app.isoclinesLabels = {};
            end
        end

        function clearParameters(app)
           app.p1Name.Value = "";
           app.p1Value.Value = "";
           app.p2Name.Value = "";
           app.p2Value.Value = "";
           app.p3Name.Value = "";
           app.p3Value.Value = "";
           app.p4Name.Value = "";
           app.p4Value.Value = "";
        end
        
        function updateParams(app)
           % This updates all of the user defined parameters
           % (only used internally for the library systems)
           app.updateParam(app.p1Name.Value,app.p1Value.Value,1)
           app.updateParam(app.p2Name.Value,app.p2Value.Value,2)
           app.updateParam(app.p3Name.Value,app.p3Value.Value,3)
           app.updateParam(app.p4Name.Value,app.p4Value.Value,4)
        end
        
        function updateParam(app,prmName,prmValue,prmIndex)
            % Stores one of the parameters
            app.params(prmIndex).name = prmName;
            if(~isempty(prmValue))
                eval("numVal = num2str(" + prmValue + ",'%0.8g');")
            else
                numVal ="";
            end
            app.params(prmIndex).value = numVal;
        end
        
        function clearCustomLibrary(app)
            % Delete existing menus
            for k = 1:numel(app.userOdeMenuHandles)
                delete(app.userOdeMenuHandles{k})                   
            end
            app.userOdeMenuHandles = {};
            app.userODESystems = {};
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Always add the app to the path to avoid errors
            filePathName = split(string(mfilename('fullpath')),filesep);
            filePath = join(filePathName(1:end-1),filesep);
            addpath(filePath);

            % Add the tutorials folder
            tutorialsPath = filePath + filesep + app.tutorialsFolder;
            addpath(tutorialsPath);
    
            % Adjust the size of the app
            SharedFunctions.startupDisplaySize(app.SlopeFieldUIFigure,...
                app.minWidth,app.minHeight,app.verticalBuffer)
            
            % Disable default interactivity
            app.UIAxes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UIAxes);
            
            % Set handle for minimum resize
            app.SlopeFieldUIFigure.SizeChangedFcn = @(src,event) SharedFunctions.resizedCallback...
                (src,event,app.SlopeFieldUIFigure,app.minWidth,app.minHeight);
   
            % Add default ODE menus
            app.createDefaultODEMenus
  
            % Set the equation
            app.setEquation
            
            % Set the axes to the starting values
            app.UIAxes.XLim = [app.tminEditField.Value app.tmaxEditField.Value];
            app.UIAxes.YLim = [app.xminEditField.Value app.xmaxEditField.Value];
        
            % Set switches to selected UI defaults
            app.animateSolnSwitch = app.AnimatesolutionMenu.Checked;
            app.pointLabelsSwitch = app.PointlabelsMenu.Checked;
            app.originAxisSwitch = app.AxisthroughoriginMenu.Checked;
            app.darkModeSwitch = app.DarkmodeMenu.Checked;
            app.orientationOnlySwitch = app.FieldorientationonlyMenu.Checked;           

            % Set the motion callback
            set(app.SlopeFieldUIFigure,"WindowButtonMotionFcn",@app.windowMotion)
            set(app.SlopeFieldUIFigure,"WindowButtonDownFcn",@app.windowButtonDown)
   
            % Select default menu by evaluating the callback for the first
            % ode menu (no source or event)
            feval(app.defaultOdeMenuHandles{1}.MenuSelectedFcn{1},...
                [],[],app.defaultOdeMenuHandles{1}.MenuSelectedFcn{2})
            
            
            % ---> these should be done last --->
            
            % Turn on start panel
            app.StartPanel.Visible = true;
            
            % Turn on the axes
            pause(0.1)
            drawnow
        
            
        end

        % Menu selected function: FieldorientationonlyMenu
        function FieldorientationonlyMenuSelected(app, event)
            if(app.FieldorientationonlyMenu.Checked)
                app.FieldorientationonlyMenu.Checked = false;
                app.orientationOnlySwitch = false;
            else
                app.FieldorientationonlyMenu.Checked = true;
                app.orientationOnlySwitch = true;
            end
            app.updateArrows
        end

        % Value changed function: UpdateButton
        function UpdateButtonValueChanged(app, event)
            app.updateEquations
        end

        % Value changed function: tminEditField
        function tminEditFieldValueChanged(app, event)
            value = app.tminEditField.Value;
            if(value >= app.UIAxes.XLim(2))
                message = 'Lower t bound must be less than upper t bound.';
                app.displayMessage(message,2)
                app.tminEditField.Value = app.UIAxes.XLim(1);
            else
                app.UIAxes.XLim(1) = value;
            end
            app.updateArrows
        end

        % Value changed function: xminEditField
        function xminEditFieldValueChanged(app, event)
            value = app.xminEditField.Value;
            if(value >= app.UIAxes.YLim(2))
                message = 'Lower x bound must be less than upper x bound.';
                app.displayMessage(message,2)
                app.xminEditField.Value = app.UIAxes.YLim(1);
            else
                app.UIAxes.YLim(1) = value;
            end
            app.updateArrows
        end

        % Value changed function: tmaxEditField
        function tmaxEditFieldValueChanged(app, event)
            value = app.tmaxEditField.Value;
            if(value <= app.UIAxes.XLim(1))
                message = 'Upper t bound must be greater than lower t bound.';
                app.displayMessage(message,2)
                app.tmaxEditField.Value = app.UIAxes.XLim(2);
            else
                app.UIAxes.XLim(2) = value;
            end
            app.updateArrows
        end

        % Value changed function: xmaxEditField
        function xmaxEditFieldValueChanged(app, event)
            value = app.xmaxEditField.Value;
            if(value <= app.UIAxes.YLim(1))
                message = 'Upper x bound must be greater than lower x bound.';
                app.displayMessage(message,2)
                app.xmaxEditField.Value = app.UIAxes.YLim(2);
            else
                app.UIAxes.YLim(2) = value;
            end
            app.updateArrows
        end

        % Value changed function: FieldDensityUI
        function FieldDensityUIValueChanged(app, event)
            app.updateArrows
        end

        % Value changed function: FieldScaleUI
        function FieldScaleUIValueChanged(app, event)
            app.updateArrows
        end

        % Value changed function: SpinnerLineWidth
        function SpinnerLineWidthValueChanged(app, event)
            app.setLineWidth
        end

        % Value changed function: ClearsolutionsButton
        function ClearsolutionsButtonValueChanged(app, event)
            app.clearSolns
        end

        % Button pushed function: Solvefromt0x0Button
        function Solvefromt0x0ButtonPushed(app, event)
            app.solveAndPlotFromInitialValue
        end

        % Menu selected function: AnimatesolutionMenu
        function AnimatesolutionMenuSelected(app, event)
            if(app.AnimatesolutionMenu.Checked)
                app.AnimatesolutionMenu.Checked = false;
                app.animateSolnSwitch = false;
            else
                app.AnimatesolutionMenu.Checked = true;
                app.animateSolnSwitch = true;
            end
        end

        % Menu selected function: PointlabelsMenu
        function PointlabelsMenuSelected(app, event)
            if(app.PointlabelsMenu.Checked)
                app.PointlabelsMenu.Checked = false;
                app.pointLabelsSwitch = false;
            else
                app.PointlabelsMenu.Checked = true;
                app.pointLabelsSwitch = true;
            end
            app.toggleLabels
        end

        % Menu selected function: AxisthroughoriginMenu
        function AxisthroughoriginMenuSelected(app, event)
            if(app.AxisthroughoriginMenu.Checked)
                app.AxisthroughoriginMenu.Checked = false;
                app.originAxisSwitch = false;
            else
                app.AxisthroughoriginMenu.Checked = true;
                app.originAxisSwitch = true;
            end
            app.setAxisMode
        end

        % Menu selected function: DarkmodeMenu
        function DarkmodeMenuSelected(app, event)
            if(app.DarkmodeMenu.Checked)
                app.DarkmodeMenu.Checked = false;
                app.darkModeSwitch = false;
                setUIColors(app,app.bgColorSTD,app.textColorSTD)
            else
                app.DarkmodeMenu.Checked = true;
                app.darkModeSwitch = true;
                setUIColors(app,app.bgColorDM,app.textColorDM)
            end
        end

        % Menu selected function: ShownullclinesMenu
        function ShownullclinesMenuSelected(app, event)
            if(app.ShownullclinesMenu.Checked)
                app.ShownullclinesMenu.Checked = false;
                app.nullclinesSwitch = false;
            else
                app.ShownullclinesMenu.Checked = true;
                app.nullclinesSwitch = true;
            end
            app.updateNullclines
        end

        % Menu selected function: AutogenerateisoclinesMenu
        function AutogenerateisoclinesMenuSelected(app, event)
             app.autoGenerateIsoclines
        end

        % Menu selected function: ClearisoclinesMenu
        function ClearisoclinesMenuSelected(app, event)
            app.clearIsoclines
        end

        % Menu selected function: DrawisoclinethroughapointMenu
        function DrawisoclinethroughapointMenuSelected(app, event)
            app.singleIsoclineMode = true;
            message = "Click on the phase plane to draw an isocline.";
            app.displayMessage(message,-1)
        end

        % Menu selected function: DrawseveralisoclinesMenu
        function DrawseveralisoclinesMenuSelected(app, event)
            app.generateIsoclinesFromVector
        end

        % Menu selected function: SolvefromregionMenu
        function SolvefromregionMenuSelected(app, event)
            app.solveFromRegion
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.saveLibrary
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.loadLibrary
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            app.clearCustomLibraryRequest
        end

        % Menu selected function: AddcurrentsystemMenu
        function AddcurrentsystemMenuSelected(app, event)
            app.addUserODESystem
        end

        % Menu selected function: ExporttoPNGMenu
        function ExporttoPNGMenuSelected(app, event)
            app.exportToFile(".png")
        end

        % Menu selected function: ExporttoSVGMenu
        function ExporttoSVGMenuSelected(app, event)
            app.exportToFile(".svg")
        end

        % Menu selected function: ExporttoPDFMenu
        function ExporttoPDFMenuSelected(app, event)
            app.exportToFile(".pdf")
        end

        % Selection changed function: SolvertypeButtonGroup
        function SolvertypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SolvertypeButtonGroup.SelectedObject;
            if(selectedButton.Text == "Fixed step")
                app.VariablesolveroptionsPanel.Visible = false;
                app.FixedsolveroptionsPanel.Visible = true;
                app.solverType = "fixed";
            else
                app.VariablesolveroptionsPanel.Visible = true;
                app.FixedsolveroptionsPanel.Visible = false;
                app.solverType = "variable";
            end
        end

        % Value changed function: VariableEditField
        function VariableEditFieldValueChanged(app, event)
            value = app.VariableEditField.Value;
            app.VariableEditField.Value = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p1Name
        function p1NameValueChanged(app, event)
            value = app.p1Name.Value;
            app.p1Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p2Name
        function p2NameValueChanged(app, event)
            value = app.p2Name.Value;
            app.p2Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p3Name
        function p3NameValueChanged(app, event)
            value = app.p3Name.Value;
            app.p3Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p4Name
        function p4NameValueChanged(app, event)
            value = app.p4Name.Value;
            app.p4Name.Value  = app.checkVariableName(value,event.PreviousValue);
        end

        % Value changed function: p1Value
        function p1ValueValueChanged(app, event)
            value = app.p1Value.Value;
            app.p1Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: p2Value
        function p2ValueValueChanged(app, event)
            value = app.p2Value.Value;
            app.p2Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: p3Value
        function p3ValueValueChanged(app, event)
            value = app.p3Value.Value;
            app.p3Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Value changed function: p4Value
        function p4ValueValueChanged(app, event)
            value = app.p4Value.Value;
            app.p4Value.Value = SharedFunctions.checkParamValue(value,event.PreviousValue,app.MessageArea);
        end

        % Button pushed function: SkipanimationButton
        function SkipanimationButtonPushed(app, event)
            app.skipAnimationFlag = true;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.clearParameters
            app.VariableEditField.Value = "";
            app.EditField_dxdt.Value = "";
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            % Select default menu by evaluating the callback for the first
            % ode menu (no source or event)
            feval(app.defaultOdeMenuHandles{1}.MenuSelectedFcn{1},...
                [],[],app.defaultOdeMenuHandles{1}.MenuSelectedFcn{2})
        end

        % Menu selected function: DrawsolutionMenu
        function DrawsolutionMenuSelected(app, event)
            app.drawSolution(false)
        end

        % Menu selected function: DrawandcomparesolutionMenu
        function DrawandcomparesolutionMenuSelected(app, event)
            app.drawSolution(true)
        end

        % Callback function: Hyperlink3
        function Hyperlink3Clicked(app, event)
            % Click the x to remove the start panel
            app.StartPanel.Visible = false;
        end

        % Callback function: Hyperlink1
        function Hyperlink1Clicked(app, event)
            SharedFunctions.winopenFile(app.videoFileLocation,app.MessageArea,app.StartPanel)
        end

        % Callback function: Hyperlink2
        function Hyperlink2Clicked(app, event)
             SharedFunctions.winopenFile(app.quickStartLocation,app.MessageArea,app.StartPanel)
        end

        % Menu selected function: VideotutorialMenu
        function VideotutorialMenuSelected(app, event)
             SharedFunctions.winopenFile(app.videoFileLocation,app.MessageArea,app.StartPanel)
        end

        % Menu selected function: QuickstartguideMenu
        function QuickstartguideMenuSelected(app, event)
             SharedFunctions.winopenFile(app.quickStartLocation,app.MessageArea,app.StartPanel)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlopeFieldUIFigure = uifigure('Visible', 'off');
            app.SlopeFieldUIFigure.Name = 'Slope Field';
            app.AnalysisMenu = uimenu(app.SlopeFieldUIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.ShownullclinesMenu = uimenu(app.AnalysisMenu);
            app.ShownullclinesMenu.MenuSelectedFcn = createCallbackFcn(app, @ShownullclinesMenuSelected, true);
            app.ShownullclinesMenu.Text = 'Show nullclines';
            app.AutogenerateisoclinesMenu = uimenu(app.AnalysisMenu);
            app.AutogenerateisoclinesMenu.MenuSelectedFcn = createCallbackFcn(app, @AutogenerateisoclinesMenuSelected, true);
            app.AutogenerateisoclinesMenu.Separator = 'on';
            app.AutogenerateisoclinesMenu.Text = 'Auto-generate isoclines';
            app.DrawisoclinethroughapointMenu = uimenu(app.AnalysisMenu);
            app.DrawisoclinethroughapointMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawisoclinethroughapointMenuSelected, true);
            app.DrawisoclinethroughapointMenu.Text = 'Draw isocline through a point';
            app.DrawseveralisoclinesMenu = uimenu(app.AnalysisMenu);
            app.DrawseveralisoclinesMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawseveralisoclinesMenuSelected, true);
            app.DrawseveralisoclinesMenu.Text = 'Draw several isoclines';
            app.ClearisoclinesMenu = uimenu(app.AnalysisMenu);
            app.ClearisoclinesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearisoclinesMenuSelected, true);
            app.ClearisoclinesMenu.Text = 'Clear isoclines';
            app.SolveMenu = uimenu(app.SlopeFieldUIFigure);
            app.SolveMenu.Text = 'Solve';
            app.SolvefromregionMenu = uimenu(app.SolveMenu);
            app.SolvefromregionMenu.MenuSelectedFcn = createCallbackFcn(app, @SolvefromregionMenuSelected, true);
            app.SolvefromregionMenu.Text = 'Solve from region';
            app.DrawMenu = uimenu(app.SlopeFieldUIFigure);
            app.DrawMenu.Text = 'Draw';
            app.DrawsolutionMenu = uimenu(app.DrawMenu);
            app.DrawsolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawsolutionMenuSelected, true);
            app.DrawsolutionMenu.Text = 'Draw solution';
            app.DrawandcomparesolutionMenu = uimenu(app.DrawMenu);
            app.DrawandcomparesolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawandcomparesolutionMenuSelected, true);
            app.DrawandcomparesolutionMenu.Text = 'Draw and compare solution';
            app.AppearanceMenu = uimenu(app.SlopeFieldUIFigure);
            app.AppearanceMenu.Text = 'Appearance';
            app.AnimatesolutionMenu = uimenu(app.AppearanceMenu);
            app.AnimatesolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @AnimatesolutionMenuSelected, true);
            app.AnimatesolutionMenu.Checked = 'on';
            app.AnimatesolutionMenu.Text = 'Animate solution';
            app.PointlabelsMenu = uimenu(app.AppearanceMenu);
            app.PointlabelsMenu.MenuSelectedFcn = createCallbackFcn(app, @PointlabelsMenuSelected, true);
            app.PointlabelsMenu.Text = 'Point labels';
            app.AxisthroughoriginMenu = uimenu(app.AppearanceMenu);
            app.AxisthroughoriginMenu.MenuSelectedFcn = createCallbackFcn(app, @AxisthroughoriginMenuSelected, true);
            app.AxisthroughoriginMenu.Text = 'Axis through origin';
            app.DarkmodeMenu = uimenu(app.AppearanceMenu);
            app.DarkmodeMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkmodeMenuSelected, true);
            app.DarkmodeMenu.Text = 'Dark mode';
            app.FieldorientationonlyMenu = uimenu(app.AppearanceMenu);
            app.FieldorientationonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @FieldorientationonlyMenuSelected, true);
            app.FieldorientationonlyMenu.Text = 'Field orientation only';
            app.LibraryMenu = uimenu(app.SlopeFieldUIFigure);
            app.LibraryMenu.Text = 'Library';
            app.CustomlibraryMenu = uimenu(app.SlopeFieldUIFigure);
            app.CustomlibraryMenu.Text = 'Custom library';
            app.SaveMenu = uimenu(app.CustomlibraryMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.CustomlibraryMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ClearMenu = uimenu(app.CustomlibraryMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.AddcurrentsystemMenu = uimenu(app.CustomlibraryMenu);
            app.AddcurrentsystemMenu.MenuSelectedFcn = createCallbackFcn(app, @AddcurrentsystemMenuSelected, true);
            app.AddcurrentsystemMenu.Separator = 'on';
            app.AddcurrentsystemMenu.Text = 'Add current system';
            app.ExportgraphicsMenu = uimenu(app.SlopeFieldUIFigure);
            app.ExportgraphicsMenu.Text = 'Export graphics';
            app.ExporttoPNGMenu = uimenu(app.ExportgraphicsMenu);
            app.ExporttoPNGMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoPNGMenuSelected, true);
            app.ExporttoPNGMenu.Text = 'Export to PNG';
            app.ExporttoSVGMenu = uimenu(app.ExportgraphicsMenu);
            app.ExporttoSVGMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoSVGMenuSelected, true);
            app.ExporttoSVGMenu.Text = 'Export to SVG';
            app.ExporttoPDFMenu = uimenu(app.ExportgraphicsMenu);
            app.ExporttoPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoPDFMenuSelected, true);
            app.ExporttoPDFMenu.Text = 'Export to PDF';
            app.HelpMenu = uimenu(app.SlopeFieldUIFigure);
            app.HelpMenu.Text = 'Help';
            app.VideotutorialMenu = uimenu(app.HelpMenu);
            app.VideotutorialMenu.MenuSelectedFcn = createCallbackFcn(app, @VideotutorialMenuSelected, true);
            app.VideotutorialMenu.Text = 'Video tutorial';
            app.QuickstartguideMenu = uimenu(app.HelpMenu);
            app.QuickstartguideMenu.MenuSelectedFcn = createCallbackFcn(app, @QuickstartguideMenuSelected, true);
            app.QuickstartguideMenu.Text = 'Quick start guide';
            app.GridLayout = uigridlayout(app.SlopeFieldUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AppearanceTab = uitab(app.TabGroup);
            app.AppearanceTab.Title = 'Appearance';
            app.tminEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @tminEditFieldValueChanged, true);
            app.tminEditField.Value = -2;
            app.tmaxEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @tmaxEditFieldValueChanged, true);
            app.tmaxEditField.Value = 4;
            app.xminEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @xminEditFieldValueChanged, true);
            app.xminEditField.Value = -2;
            app.xmaxEditField = uieditfield(app.AppearanceTab, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxEditFieldValueChanged, true);
            app.xmaxEditField.Value = 4;
            app.SpinnerLineWidth = uispinner(app.AppearanceTab);
            app.SpinnerLineWidth.Step = 0.25;
            app.SpinnerLineWidth.Limits = [0.5 3];
            app.SpinnerLineWidth.ValueChangedFcn = createCallbackFcn(app, @SpinnerLineWidthValueChanged, true);
            app.SpinnerLineWidth.Value = 1.5;
            app.FieldDensityUI = uispinner(app.AppearanceTab);
            app.FieldDensityUI.Step = 2;
            app.FieldDensityUI.Limits = [2 32];
            app.FieldDensityUI.ValueChangedFcn = createCallbackFcn(app, @FieldDensityUIValueChanged, true);
            app.FieldDensityUI.Value = 20;
            app.FieldScaleUI = uispinner(app.AppearanceTab);
            app.FieldScaleUI.Step = 0.2;
            app.FieldScaleUI.Limits = [0.1 2];
            app.FieldScaleUI.ValueChangedFcn = createCallbackFcn(app, @FieldScaleUIValueChanged, true);
            app.FieldScaleUI.Value = 1;
            app.AxislimitsLabel = uilabel(app.AppearanceTab);
            app.AxislimitsLabel.Text = 'Axis limits';
            app.tminEditFieldLabel = uilabel(app.AppearanceTab);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tmaxEditFieldLabel = uilabel(app.AppearanceTab);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.xminEditFieldLabel = uilabel(app.AppearanceTab);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xmaxEditFieldLabel = uilabel(app.AppearanceTab);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.AnimationspeedSliderLabel = uilabel(app.AppearanceTab);
            app.AnimationspeedSliderLabel.Text = 'Animation speed';
            app.FielddensitySpinnerLabel = uilabel(app.AppearanceTab);
            app.FielddensitySpinnerLabel.Text = 'Field density';
            app.FieldscaleSpinnerLabel = uilabel(app.AppearanceTab);
            app.FieldscaleSpinnerLabel.Text = 'Field scale';
            app.LinewidthLabel = uilabel(app.AppearanceTab);
            app.LinewidthLabel.Text = 'Line width';
            app.AnimationspeedSlider = uislider(app.AppearanceTab);
            app.AnimationspeedSlider.Limits = [1 50];
            app.AnimationspeedSlider.MajorTicks = [5 46];
            app.AnimationspeedSlider.MajorTickLabels = {'slower', 'faster'};
            app.AnimationspeedSlider.MinorTicks = [];
            app.AnimationspeedSlider.Value = 20;
            app.SolutionTab = uitab(app.TabGroup);
            app.SolutionTab.Title = 'Solution';
            app.TerminatesolutionsataxislimitsCheckBox = uicheckbox(app.SolutionTab);
            app.TerminatesolutionsataxislimitsCheckBox.Text = 'Terminate solutions at axis limits';
            app.TerminatesolutionsataxislimitsCheckBox.Value = true;
            app.SolvefromregiondensitySliderLabel = uilabel(app.SolutionTab);
            app.SolvefromregiondensitySliderLabel.Text = 'Solve from region density';
            app.SolvefromregiondensitySlider = uislider(app.SolutionTab);
            app.SolvefromregiondensitySlider.Limits = [5 50];
            app.SolvefromregiondensitySlider.MajorTicks = [10 40];
            app.SolvefromregiondensitySlider.MajorTickLabels = {'Less dense', 'more dense'};
            app.SolvefromregiondensitySlider.MinorTicks = [];
            app.SolvefromregiondensitySlider.Value = 20;
            app.SolverTab = uitab(app.TabGroup);
            app.SolverTab.Title = 'Solver';
            app.SolvertypeButtonGroup = uibuttongroup(app.SolverTab);
            app.SolvertypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolvertypeButtonGroupSelectionChanged, true);
            app.SolvertypeButtonGroup.Title = 'Solver type';
            app.VariablestepButton = uitogglebutton(app.SolvertypeButtonGroup);
            app.VariablestepButton.Text = 'Variable step';
            app.VariablestepButton.Value = true;
            app.FixedstepButton = uitogglebutton(app.SolvertypeButtonGroup);
            app.FixedstepButton.Text = 'Fixed step';
            app.FixedsolveroptionsPanel = uipanel(app.SolverTab);
            app.FixedsolveroptionsPanel.Title = 'Fixed solver options';
            app.SolverLabel = uilabel(app.FixedsolveroptionsPanel);
            app.SolverLabel.Text = 'Solver';
            app.FixedSolverDropDown = uidropdown(app.FixedsolveroptionsPanel);
            app.FixedSolverDropDown.Items = {'Forward Euler', 'RK2', 'RK4', 'Backward Euler', 'Trapezoid'};
            app.FixedSolverDropDown.Value = 'Forward Euler';
            app.StepsizeEditFieldLabel = uilabel(app.FixedsolveroptionsPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size:';
            app.StepsizeEditField = uieditfield(app.FixedsolveroptionsPanel, 'numeric');
            app.StepsizeEditField.Value = 0.01;
            app.NoteimplicitmethodsareestimatedLabel = uilabel(app.FixedsolveroptionsPanel);
            app.NoteimplicitmethodsareestimatedLabel.Text = {'• fixed step solvers will fail without warning'; ''};
            app.MaxsolverwallclocksEditFieldLabel = uilabel(app.SolverTab);
            app.MaxsolverwallclocksEditFieldLabel.Text = 'Max solver wall clock (s):';
            app.MaxsolverwallclocksEditField = uieditfield(app.SolverTab, 'numeric');
            app.MaxsolverwallclocksEditField.Limits = [0 100];
            app.MaxsolverwallclocksEditField.Value = 1;
            app.VariablesolveroptionsPanel = uipanel(app.SolverTab);
            app.VariablesolveroptionsPanel.Title = 'Variable solver options';
            app.RelativetoleranceEditFieldLabel = uilabel(app.VariablesolveroptionsPanel);
            app.RelativetoleranceEditFieldLabel.Text = 'Relative tolerance';
            app.RelativetoleranceEditField = uieditfield(app.VariablesolveroptionsPanel, 'numeric');
            app.RelativetoleranceEditField.Value = 1e-06;
            app.AbsolutetoleranceEditFieldLabel = uilabel(app.VariablesolveroptionsPanel);
            app.AbsolutetoleranceEditFieldLabel.Text = 'Absolute tolerance';
            app.AbsolutetoleranceEditField = uieditfield(app.VariablesolveroptionsPanel, 'numeric');
            app.AbsolutetoleranceEditField.Value = 1e-06;
            app.SolverDropDownLabel = uilabel(app.VariablesolveroptionsPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.VariablesolveroptionsPanel);
            app.SolverDropDown.Items = {'ode45', 'ode23', 'ode113', 'ode15s', 'ode23s', 'ode23t', 'ode23tb', 'ode15i'};
            app.SolverDropDown.Value = 'ode45';
            app.MessageArea = uitextarea(app.GridLayout);
            app.MessageArea.Editable = 'off';
            app.SlopefieldPanel = uipanel(app.GridLayout);
            app.SlopefieldPanel.Title = 'Slope field';
            app.GridLayout2 = uigridlayout(app.SlopefieldPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'x')
            app.PositionLabel = uilabel(app.GridLayout2);
            app.PositionLabel.Text = '-';
            app.t0EditFieldLabel = uilabel(app.GridLayout2);
            app.t0EditFieldLabel.Text = 't0 = ';
            app.t0EditField = uieditfield(app.GridLayout2, 'numeric');
            app.x0EditFieldLabel = uilabel(app.GridLayout2);
            app.x0EditFieldLabel.Text = 'x0 = ';
            app.x0EditField = uieditfield(app.GridLayout2, 'numeric');
            app.x0EditField.Value = -0.5;
            app.SkipanimationButton = uibutton(app.GridLayout2, 'push');
            app.SkipanimationButton.ButtonPushedFcn = createCallbackFcn(app, @SkipanimationButtonPushed, true);
            app.SkipanimationButton.Visible = 'off';
            app.SkipanimationButton.Text = 'Skip animation';
            app.ClearsolutionsButton = uibutton(app.GridLayout2, 'state');
            app.ClearsolutionsButton.ValueChangedFcn = createCallbackFcn(app, @ClearsolutionsButtonValueChanged, true);
            app.ClearsolutionsButton.Tooltip = {''};
            app.ClearsolutionsButton.Text = 'Clear solutions';
            app.Solvefromt0x0Button = uibutton(app.GridLayout2, 'push');
            app.Solvefromt0x0Button.ButtonPushedFcn = createCallbackFcn(app, @Solvefromt0x0ButtonPushed, true);
            app.Solvefromt0x0Button.Text = 'Solve from (t0,x0)';
            app.DifferentialequationPanel = uipanel(app.GridLayout);
            app.DifferentialequationPanel.Title = 'Differential equation';
            app.VariableEditField = uieditfield(app.DifferentialequationPanel, 'text');
            app.VariableEditField.ValueChangedFcn = createCallbackFcn(app, @VariableEditFieldValueChanged, true);
            app.VariableEditField.Value = 'x';
            app.EditField_dxdt = uieditfield(app.DifferentialequationPanel, 'text');
            app.EditField_dxdt.Value = 'a*x^2 + t';
            app.p1Name = uieditfield(app.DifferentialequationPanel, 'text');
            app.p1Name.ValueChangedFcn = createCallbackFcn(app, @p1NameValueChanged, true);
            app.p1Name.Value = 'a';
            app.p1Value = uieditfield(app.DifferentialequationPanel, 'text');
            app.p1Value.ValueChangedFcn = createCallbackFcn(app, @p1ValueValueChanged, true);
            app.p1Value.Value = '1';
            app.p3Name = uieditfield(app.DifferentialequationPanel, 'text');
            app.p3Name.ValueChangedFcn = createCallbackFcn(app, @p3NameValueChanged, true);
            app.p3Value = uieditfield(app.DifferentialequationPanel, 'text');
            app.p3Value.ValueChangedFcn = createCallbackFcn(app, @p3ValueValueChanged, true);
            app.p2Name = uieditfield(app.DifferentialequationPanel, 'text');
            app.p2Name.ValueChangedFcn = createCallbackFcn(app, @p2NameValueChanged, true);
            app.p2Value = uieditfield(app.DifferentialequationPanel, 'text');
            app.p2Value.ValueChangedFcn = createCallbackFcn(app, @p2ValueValueChanged, true);
            app.p4Name = uieditfield(app.DifferentialequationPanel, 'text');
            app.p4Name.ValueChangedFcn = createCallbackFcn(app, @p4NameValueChanged, true);
            app.p4Value = uieditfield(app.DifferentialequationPanel, 'text');
            app.p4Value.ValueChangedFcn = createCallbackFcn(app, @p4ValueValueChanged, true);
            app.Label = uilabel(app.DifferentialequationPanel);
            app.Label.Text = ''' =';
            app.EditField_3Label = uilabel(app.DifferentialequationPanel);
            app.EditField_3Label.Text = '=';
            app.Label_2 = uilabel(app.DifferentialequationPanel);
            app.Label_2.Text = '=';
            app.EditField_2Label = uilabel(app.DifferentialequationPanel);
            app.EditField_2Label.Text = '=';
            app.EditField_4Label = uilabel(app.DifferentialequationPanel);
            app.EditField_4Label.Text = '=';
            app.DefaultButton = uibutton(app.DifferentialequationPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Tooltip = {'Restore the default system'};
            app.DefaultButton.Text = 'Default';
            app.ClearButton = uibutton(app.DifferentialequationPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clear the equations and parameters'};
            app.ClearButton.Text = 'Clear';
            app.UpdateButton = uibutton(app.DifferentialequationPanel, 'state');
            app.UpdateButton.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.UpdateButton.Tooltip = {'Reset the phase plane'};
            app.UpdateButton.Text = 'Update';
            app.ParametersLabel_2 = uilabel(app.DifferentialequationPanel);
            app.ParametersLabel_2.Text = 'Parameters';
            app.StartPanel = uipanel(app.GridLayout);
            app.StartPanel.Visible = 'off';
            app.Hyperlink1 = uihyperlink(app.StartPanel);
            app.Hyperlink1.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink1Clicked, true);
            app.Hyperlink1.Text = {'Video tutorial'; ''};
            app.Hyperlink2 = uihyperlink(app.StartPanel);
            app.Hyperlink2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2Clicked, true);
            app.Hyperlink2.Text = 'Quick start guide';
            app.NewtotheSlopeFieldappLabel = uilabel(app.StartPanel);
            app.NewtotheSlopeFieldappLabel.Text = 'New to the Slope Field app?';
            app.Hyperlink3 = uihyperlink(app.StartPanel);
            app.Hyperlink3.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink3Clicked, true);
            app.Hyperlink3.Text = '🗙';
            % Show the figure after all components are created
            app.SlopeFieldUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlopeField

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlopeFieldUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlopeFieldUIFigure)
        end
    end
end